classdef EditingTool < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        Toolbar                matlab.ui.container.Toolbar
        ManualContrast         matlab.ui.container.toolbar.PushTool
        Up                     matlab.ui.container.toolbar.PushTool
        Down                   matlab.ui.container.toolbar.PushTool
        Left                   matlab.ui.container.toolbar.PushTool
        Right                  matlab.ui.container.toolbar.PushTool
        ResetView              matlab.ui.container.toolbar.PushTool
        Pan                    matlab.ui.container.toolbar.ToggleTool
        ZoomIn                 matlab.ui.container.toolbar.ToggleTool
        ZoomOut                matlab.ui.container.toolbar.ToggleTool
        Reset                  matlab.ui.container.toolbar.PushTool
        Colormap               matlab.ui.container.toolbar.PushTool
        MaskColor              matlab.ui.container.toolbar.PushTool
        Help                   matlab.ui.container.toolbar.PushTool
        Finished               matlab.ui.container.toolbar.PushTool
        ToolbarSpacer          matlab.ui.container.toolbar.PushTool
        Cancel                 matlab.ui.container.toolbar.PushTool
        Panel_2                matlab.ui.container.Panel
        Unmask                 matlab.ui.control.Button
        MaskAllButton          matlab.ui.control.Button
        InvertMaskButton       matlab.ui.control.Button
        CloseInterior          matlab.ui.control.Button
        AutoCMethods           matlab.ui.control.DropDown
        CloseAllButton         matlab.ui.control.Button
        OpaquenessSlider       matlab.ui.control.Slider
        OpaquenessSliderLabel  matlab.ui.control.Label
        RadiusEditField        matlab.ui.control.NumericEditField
        RadiusEditFieldLabel   matlab.ui.control.Label
        ModeDropDown           matlab.ui.control.DropDown
        ModeDropDownLabel      matlab.ui.control.Label
        AutocontrastButton     matlab.ui.control.Button
        Panel                  matlab.ui.container.Panel
        UIAxes                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % Data Mode for either points or masks
        Datatype                          % Will either deal with point clouds or masks

        % Figure related
        monitorsize                       % Size of monitor to ensure the window never goes off screen
        oldguisize                       % Holds the previous window width and height after a change is made - allows a query to then trigger a line width update on the pain brush
        Parent                            % Figure / app calling the masking tool
        initialguisize                    % Initial size of the GUI - prevents downsizing too much
        Helpwindow

        % Primary Image related
        isrgb                             % Flag for whether or not the image is RGB
        rgbclims                          % Bounds for contrasting that change appearance of RGB image
        Imedges                           % Image with edges enhanced for the smart cut mode
        Image                             % Underlying image data
        imagerange                        % Upper and lower limits of the image's pixel intensity values

        % Results data related
        ax2                               % Second axes for plotting data/results
        Results                           % Original results/data
        Editedresults                     % Mask or point cloud being edited
        mrows                             % Number of rows in the image (y dim)
        ncols                             % Number of cols in the image (x dim)
        dataplotH                         % Graphics object handle for the mask or points

        % Point plot settings
        markersize = 25
        mar

        % Pointcloud adjustment
        Pointcloudplot                    % Pointcloud plot

        % Mask adjustment
        newcc                             % New connected component to be appended to the mask
        brushpolyline                     % The poly line object corresponding to the latest mask allows us to close the loop if left open prior to interior closing
        Undostack                         % Holds previous results/edits in case user makes a mistake

        % Plotting related
        clickisdown        = false        % True when mouse clicked down - flag for updating mask in brush mode
        imH                               % Image graphics object handle for the image in the axes
        
        transparency       = 0.4          % Transparency value for the mask plotted atop the image in the axes
        imcontrastH                       % Manual contrast tool handle
        autocontrastmethod = 1            % Auto contrast Method index


        % Mask Editing
        editmodes     = {'Touch Up',...
                          'Point',...
                          'Bounding Curves',...
                          'Turbo Paint Brush',...
                          'Seed Region',...
                          'Edge Based Cut'} % Modes that a user can select to use and switch to
        editmode       = 4                  % Default editing mode, determines how user edits the mask
        appendordelete                      % if 1, appending to mask, if -1, deleting region from mask
        seedtol                             % Tolerance of intensity values for the seed region mode. Relative to the pixel intensity range of an image. Expressed as a percent
        brushradius    = 3                  % Size in pixels of the masking brush - paint brush method
        brushroi                            % A circular ROI object to show region to be masked- for pain brush mode
        cursorcolor    = [1 1 1]            % Color of the circular ROI for cursor - for paint brush mode

        % Interactivity
        Mouseposition                     % Mouse cursor position in the axes
        editorH                           % Graphical object for editing data - places points along mouse path
        zoomObj                           % Zoom Mode object used to toggle legacy zoom functionality on/off
        brushlinewidth = 1                % Shrink factor for the alpha shapes computation 
        Points                            % Points computed from the alpha shapes - bounding curve method
        lassoroiH                         % roi object handle to be drawn in the axes - bounding curve method
        Brushcap                          % Graphics object drawn upon clicking when in turbo mode for accurate representation of masked region
        cmap                              % Colormap used to display image and overlayed mask
        plotcolor                         % Colormap for the mask
        CLims                             % Color limits for the image - i.e. range of intensity values for contrast adjustment
        wasoveraxes     = false           % A change in this flag will trigger changes to the pointer. Flag prevents unnecessary changes when condition is static
    end


    properties (Dependent)
        ispointsdata         % returns true if the results data is a pointcloud - determines behavior of edit methods
        appendingdata        % returns true if the appendordelete property is set to 1
        removingdata         % returns true if the appendordelete property is set to -1
        Currentpoints        % returns the set of all original and appendedpoints
    end


    methods (Access = public)
    %% Plot/Data Initialization
        function SetData(app, inputdata)
            % SETDATA will set the results data and then determine the
            % datatype which will influence the behavior of the tool.

            % Setting the data
            app.Results = inputdata;

            % Input data size
            sz = size(inputdata);
            nd = ndims(inputdata);

            % In case of empty load
            nodata = isempty(inputdata);

            % Data is 2D
            is2d = nd == 2;

            % Pointclouds and contours are arrays with 2 columns (coords)
            ispoints = sz(2) == 2;

            % Contours have same coordinates for first and last point
            if ~nodata
                closed = all(inputdata(1,:) == inputdata(end,:));
            else
                closed = false;
            end

            % Setting the tools datatype for behavior changes
            if ispoints && ~closed
                app.Datatype = 'pointcloud';

            elseif ~ispoints || nodata
                app.Datatype = 'mask';

            elseif ~is2d && nodata
                warning(['Results cannot be loaded in. Check that results' ...
                       ' are either a mask, a pointcloud, or closed contour' ...
                       ' formatted as a 2D array.'])

            end
        end


        function PlotImage(app, I)
            % PLOTIMAGE will plot the image passed to the app. It will
            % determine how the image is to be displayed and how contrast
            % will be set depending on whether the image is RGB or
            % grayscale
            if ~isnumeric(I) && ~islogical(I)
                I = app.ImageFileLoad(I);
            end
            if isempty(app.cmap)
                app.cmap   = bone();
                app.plotcolor  = [0 0 0 ; 1 1 1];
            end

            % Image size and pixel intensity range information
            [app.mrows, app.ncols] = size(I, [1 2]);
            app.Image      = I;
            [mn, mx]       = bounds(I, 'all');
            app.imagerange = double([mn mx]);

            % Plotting and determination of colormap need
            app.imH     = imagesc(app.UIAxes, I);
            if size(I, 3) == 3
                % Turns off colormap functionality for RGB images
                app.isrgb = true;
                app.Colormap.Enable = 'off';
            elseif size(I, 3) == 1
                % Grayscle image
                app.isrgb = false;
            end
            if ischar(app.cmap)
                app.cmap  = eval(app.cmap);
            end

            % Will set colormap and color limits if the image is not RGB
            if ~app.isrgb
                colormap(app.UIAxes, app.cmap)
                if ~isempty(app.CLims)
                    app.UIAxes.CLim = app.CLims;
                end
            end
            
            % Ensures axes proportions and pixel aspect ratio is accurate
            axis(app.UIAxes, 'equal')
            app.UIAxes.Visible = 'off';
            
            % Initializing settings for cursor ROI since dominant color in
            % image will determine the cursor circle color
            app.UpdateCursorColor

            % Line width for animated line depends on the ratio of image
            % pixels to screen pixels
            app.ResizeLineWidth
        end


        function PlotOriginalResults(app, originalresults)
            % PLOTORIGINALRESULTS will pass data to the appropriate
            % plotting method depending on the format of the data.

            % initialize as a mask type when results are empty
            if nargin < 2 || isempty(originalresults)
                originalresults = zeros([app.mrows app.ncols], 'logical');
            end

            % Creating the second axes object with a mouse callback
            app.ax2 = uiaxes(app.Panel);
            
            % Links the second axes with the primary ones
            app.LinkAxes

            % Determines how to plot the loaded results
            if app.ispointsdata
                
                app.InitResultsAsPoints(originalresults)
            else
                app.InitResultsAsMask(originalresults)
            end
        end


        function LinkAxes(app)
            % LINKAXES will ensure the two axes share properties with each
            % other and remain aligned
            axis(app.ax2, 'equal')
            linkprop([app.UIAxes, app.ax2], {'Position', 'XLim', 'YLim', 'XTick', 'YTick', 'XColor', 'YColor', 'YDir', 'XDir'});
            linkaxes([app.UIAxes, app.ax2], 'xy')

            % Setting zoom clip limits off to allow accurate scroll zoom
            % functionality
            set(app.ax2, 'Clipping', false)
            app.ax2.Toolbar.Visible    = 'off';
            app.ax2.Visible            = 'off';
            app.UIAxes.Toolbar.Visible = 'off';
            app.UIAxes.Visible         = 'off';

            % Deletes toolbars to prevent slowdown from useless callbacks
            app.ax2.Toolbar            = [];
            app.UIAxes.Toolbar         = [];
        end


        function InitResultsAsMask(app, originalresults)
            % Init mask as all true and its alpha data as all false
            app.Editedresults = true(app.mrows, app.ncols);
            app.Results       = originalresults;
            FirstMask         = double(app.Results).*app.transparency;
            app.dataplotH     = imagesc(app.ax2, app.Editedresults, 'AlphaData', FirstMask);
            colormap(app.ax2, app.plotcolor)

            % Callback linked to the image atop (mask overlay)
            set(app.dataplotH, 'ButtonDownFcn', @app.AdjustOn)
        end


        function InitResultsAsPoints(app, originalresults)
            % INITRESULTSASPOINTS will initialize arrays to hold original
            % results as well as the edited results which will actively be
            % edited. This will also initialize the plots that
            % differentiate the original pointcloud from appended points
            % and deleted points.

            % Points array init
            app.Editedresults = originalresults;
            app.Results       = originalresults;
            markercolor       = app.plotcolor(2,:);

            % Original data points plot
            app.dataplotH{1}  = plot(app.ax2,...
                                     app.Results(:,1), app.Results(:,2), ...
                                     "Color",markercolor, ...
                                     "LineStyle","none", ...
                                     "MarkerSize", app.markersize, ...
                                     "Marker", '.');

            % Ensures multiple plots are valid
            hold(app.ax2, "on")

            % Initializing empty cells for plots
            app.dataplotH{2} = [];
            app.dataplotH{3} = [];

            % Callback linked to primary axes
            set(app.imH, 'ButtonDownFcn', @app.AdjustOn)
        end


        function ResetResultsPlot(app)
            % RESETRESULTSPLOT will reset the plot to show the original
            % results data prior to edits but saves the most recent edits
            % to the undo stack in case the user wants to undo their reset.
            app.UpdateUndoStack

            if app.ispointsdata
                % Points data

                % Delete plot objects
                if ~isempty(app.dataplotH{2}) && isvalid(app.dataplotH{2})
                    app.dataplotH{2}.delete
                end
                if ~isempty(app.dataplotH{3}) && isvalid(app.dataplotH{3})
                    app.dataplotH{3}.delete
                end

                % Resetting insures proper reinitialization
                app.dataplotH{2} = [];
                app.dataplotH{3} = [];

            else
                % Mask data
                if any(app.Results(:))
                    app.dataplotH.AlphaData(app.Results)  = app.transparency;
                    app.dataplotH.AlphaData(~app.Results) = 0;
                else
                    app.dataplotH.AlphaData = zeros(app.mrows, app.ncols, 'double');
                end
            end
        end


    %% Results Editing

        function UpdateResultsPlot(app, newedits)
            % UPDATERESULTSPLOT will determine how to process the new edits
            % depending on the data format of the plotted results.

            % Does nothing with empty input
            if isempty(newedits)
                return
            end

            if app.ispointsdata
                % Points can be removed from the original set or appended
                app.UpdateAsPoints(newedits)
            else
                % Updates latest connected component in case of interior
                % closing call
                app.newcc = newedits;

                % Appends or removes regions from mask
                app.UpdateAsMask(newedits)
            end
        end


        function UpdateAsPoints(app, Mask)
            % UPDATEASPOINTS will accordingly update the points plot
            % depending on whether the user is appending or removing - this
            % depends on the mouse click used.
            if app.appendingdata
                % Append points
                app.AppendOrRestoreDataPoints(Mask)

            elseif app.removingdata
                % Delete points
                app.RemoveDataPoints(Mask)

            end
        end


        function UpdateAsMask(app, Mask)
            % UPDATEASMASK will update the plot if dealing with masked
            % results instead of pointclouds.

            if app.appendingdata
                % New region added
                app.dataplotH.AlphaData(Mask) = app.transparency;

            elseif app.removingdata
                % Region removed by making area transparent
                app.dataplotH.AlphaData(Mask) = 0;

            end
        end
        

        function TouchUpMask(app)
            % TOUCHUPMASK will set pixel values around a point according to
            % the set radius. This essentially acts as a stamp
            try
                p = round(app.Mouseposition);
                y = p(1);
                x = p(2);

                % Single point altered at a time
                Mask       = false(app.mrows, app.ncols);
                Mask(y, x) = true;
                % Isotropic dilation
                Mask = bwdist(Mask)<=app.brushradius;

                % Mask will lead to results plot change
                app.UpdateResultsPlot(Mask)
            catch
            end
        end


        function PaintPoint(app) 
            % PAINTPOINT will mask in a single point or drop a point on the
            % plot.

            % Could potentially alter to account for subpixel resolution
            
            % Paints in a single pixel given the mouse position
            p          = app.Mouseposition;
            % p          = round(p);
            y          = p(1);
            x          = p(2);
            x_inbounds = (1 <= x) && (x<=app.ncols); % account for 0.5?
            y_inbounds = (1 <= y) && (y<=app.mrows);
            if x_inbounds && y_inbounds
                y          = round(y);
                x          = round(x);
                Mask       = false(app.mrows, app.ncols);
                Mask(y, x) = true;
                % Mask will lead to results plot change
                app.UpdateResultsPlot(Mask)
            end
        end


        function DrawLasso(app)
            if ~isempty(app.appendordelete)
                % Start from 0 and go 1 beyond the boundary to allow pixels
                % at boundary to be included in mask
                MP_x = min(max(app.Mouseposition(2), 0), app.ncols+1);
                MP_y = min(max(app.Mouseposition(1), 0), app.mrows+1);
                if isempty(app.editorH)
                    % Changes color to be more visible when removing mask
                    % portion
                    switch app.appendordelete
                        case 1
                            LassoColor         = [app.plotcolor(2,:) app.transparency];
                        case -1
                            LassoColor         = [(1-app.plotcolor(2,:)) app.transparency];
                    end
                    % Mouse pointer will be crosshairs
                    % Simple line will act as lasso
                    app.editorH       = line(app.ax2, 'XData', MP_x, 'YData', MP_y,...
                                              'Color', LassoColor,...
                                              'AlignVertexCenters','on', ...
                                              'LineWidth', 1.5, ...
                                              'LineStyle', '--');
                    app.editorH.XData = [app.editorH.XData  app.editorH.XData];
                    app.editorH.YData = [app.editorH.YData  app.editorH.YData];
                else
                    % Will always have the first data point swapped to the
                    % end to give a lasso appearance
                    app.editorH.XData = [app.editorH.XData(1:end-1) MP_x app.editorH.XData(1)];
                    app.editorH.YData = [app.editorH.YData(1:end-1) MP_y app.editorH.YData(1)];
                end
            end
        end


        function Lasso2Mask(app)
            % LASSO2MASK will convert the bounding curve region into a
            % mask and update the alpha data to show what's been masked
            if ~isempty(app.editorH)
                P             = [app.editorH.XData', app.editorH.YData'];
                app.lassoroiH = images.roi.Freehand(app.ax2, 'Position', P, 'Visible', 'off');
                Mask          = createMask(app.lassoroiH, app.mrows, app.ncols);
                app.editorH.delete
                app.editorH = [];
            else
                Mask = [];
            end

            app.UpdateResultsPlot(Mask)
            app.ClearROI
        end


        function TurboPaint(app)
            % TURBOPAINT allows user to draw animated lines to mask regions
            if ~isempty(app.appendordelete)
                MP_x = min(max(app.Mouseposition(2), 1), app.ncols);
                MP_y = min(max(app.Mouseposition(1), 1), app.mrows);
                if isempty(app.editorH)
                    % Changes color to be more visible when removing mask
                    % portion
                    switch app.appendordelete
                        case 1
                            PaintBrushColor = app.plotcolor(2,:);
                        case -1
                            PaintBrushColor = (1-app.plotcolor(2,:));
                    end
                    
                    % Brush cap is at the start of the painted region
                    app.Brushcap = drawcircle(app.ax2,...
                                          'Center', [MP_x MP_y],...
                                          'Radius', app.brushradius,...
                                          'InteractionsAllowed','none',...
                                          'LineWidth', 1,...
                                          'FaceAlpha', 1,...
                                          'Color', PaintBrushColor);

                    % Animated line w/ scaled width and same color as mask
                    app.editorH = animatedline(app.ax2, MP_x, MP_y,...
                                   'LineWidth', app.brushlinewidth,...
                                   'Color', PaintBrushColor,...
                                   'AlignVertexCenters','on' ,...
                                   'Marker', '.',...
                                   'MarkerSize', app.brushlinewidth*2.925);

                    addpoints(app.editorH, MP_x, MP_y)

                else
                    addpoints(app.editorH, MP_x, MP_y)

                end
            end
        end


        function Brush2Mask(app)
            % BRUSH2MASK will convert the points from an animated line to a
            % masked region based on the brush radius
            
            % Getting points from animated line
            try
                [x, y] = getpoints(app.editorH);
                if ~isempty(app.brushpolyline) && isa(app.brushpolyline, 'images.roi.Polyline')
                    % Clears previous poly line object to prevent flooding
                    % the axes with too many roi objects
                    delete(app.brushpolyline)
                end

                % Determines if user wants interior closed as well or not
                app.brushpolyline = images.roi.Polyline(app.ax2, 'Position', [x', y'], 'Visible','off');
                Mask              = createMask(app.brushpolyline, app.mrows, app.ncols);

                % Thresholding distance transform - isotropic dilation
                Mask = bwdist(Mask, 'euclidean')<=app.brushradius;

                % Will pass results to update
                app.UpdateResultsPlot(Mask)

            catch
                % When mouse does not move sufficiently for an animated
                % line point to register and get drawn
                app.TouchUpMask
            end
        end


        function SeedGrow(app)
            % SEEDGROW will use a flood fill technique on a contiguous
            % image region with similar values based on a user specified
            % tolerance
            P   = round(app.Mouseposition);
            y   = P(1);
            x   = P(2);
            tol = app.seedtol;
            if app.isrgb
                % When RGB, need to consider similar pixels per channel,
                % then look at the intersect of all three - suggests
                % similarly colored pixels with similar luma
                I1 = app.Image(:,:,1);
                I2 = app.Image(:,:,2);
                I3 = app.Image(:,:,3);

                % If compute time for a single channel is over a second, a
                % progress bar is launched to prevent user queues
                t  = tic;
                m1 = grayconnected(I1, y, x, tol);
                t  = toc(t);
                if t > 1
                    wbf = true;
                    wb = uiprogressdlg(app.UIFigure, 'Indeterminate','on');
                else
                    wbf = false;
                end
                m2 = grayconnected(I2, y, x, tol);
                m3 = grayconnected(I3, y, x, tol);
                % Finding pixels that were related in all three channels
                % suggests a region of similar color and luma
                Mask  = m1 & m2 & m3;
                % Will close the progress bar
                if wbf
                    wb.delete
                end
            else
                Mask  = grayconnected(app.Image, y, x, tol);
            end
            % Filling any holes and smoothing mask
            Mask = imfill(Mask, 'holes');
            Mask = imclose(Mask, [0 1 0; 1 1 1; 0 1 0]);
            app.UpdateResultsPlot(Mask)
        end


        function EdgeBasedCut(app)
            % SMARTCUT will use a flood fill technique on a contiguous
            % image region with similar edges
            P   = round(app.Mouseposition);
            y   = P(1);
            x   = P(2);
            tol = app.seedtol;

            % Bound checking the mouse location in the axes
            y   = max(1, y);
            y   = min(app.mrows, y);
            x   = max(1, x);
            x   = min(app.ncols, x);

            if app.isrgb
                % When RGB, need to consider similar pixels per channel,
                % then look at the intersect of all three - suggests
                % similarly colored pixels with similar luma
                I1 = app.Imedges(:,:,1);
                I2 = app.Imedges(:,:,2);
                I3 = app.Imedges(:,:,3);

                % If compute time for a single channel is over a second, a
                % progress bar is launched to prevent user queues
                t  = tic;
                m1 = grayconnected(I1, y, x, tol);
                t  = toc(t);
                if t > 0.5
                    wbf = true;
                    wb = uiprogressdlg(app.UIFigure, 'Indeterminate','on');
                else
                    wbf = false;
                end
                m2 = grayconnected(I2, y, x, tol);
                m3 = grayconnected(I3, y, x, tol);
                % Finding pixels that were related in all three channels
                % suggests a region of similar color and luma
                Mask  = m1 & m2 & m3;
                % Will close the progress bar
                if wbf
                    wb.delete
                end
            else
                Mask  = grayconnected(app.Image, y, x, tol);
            end
            Mask = imfill(Mask, 'holes');
            Mask = imclose(Mask, [0 1 0; 1 1 1; 0 1 0]);
            app.UpdateResultsPlot(Mask)
        end


        %% Misc.
        function ClearROI(app)
            % CLEARROI will delete the ROI drawn in the bounding curve mode
            if ~isempty(app.lassoroiH) && isvalid(app.lassoroiH)
                app.lassoroiH.delete
            end
        end


        function PauseEditing(app)
            uiresume(app.UIFigure)
            app.appendordelete = [];
            app.clickisdown    = false;
            app.ClearROI
        end


        function ComputeEdges(app)
            % COMPUTEEDGES computes the edge enhanced image that results
            % from convolving with the sobel operator. This is done per
            % channel so that transitions across colors can be seen if the
            % image is RGB.
            I = app.Image;
            I  = double(I);
            if app.isrgb
                for i = 1:3
                    I(:,:,i)  = medfilt2(I(:,:,i));
                end
            else
                I = medfilt2(I);
            end
            I  = imgaussfilt(I, 1);
            hy = [1 0 -1; 2 0 -2; 1 0 -1];
            hx = hy';
            Gy = imfilter(I, hy);
            Gx = imfilter(I, hx);
            G  = sqrt(Gx.^2 + Gy.^2);
            G  = round(G);
            app.Imedges = round(G);
        end


        function ChangeMode(app, modename)
            % CHANGEMODE allows user to toggle between different editing
            % modes
            if any(contains(app.editmodes, modename))
                % Sets the edit mode to index value of selected method 
                switch modename
                    case app.editmodes{1}
                        % Touch Up mode
                        app.editmode             = 1;

                    case app.editmodes{2}
                        % Point mode
                        app.editmode             = 2;

                    case app.editmodes{3}
                        % Bounding curve mode
                        app.editmode             = 3;

                    case app.editmodes{4}
                        % Turbo Paint Brush mode
                        app.editmode             = 4;

                    case app.editmodes{5}
                        % Seed grow region mode
                        app.editmode             = 5;

                    case app.editmodes{6}
                        % Seed grow on edges
                        app.editmode             = 6;

                end
            else
                % Displays list of valid mode names
                msg = ['Editing mode must be one of the following types: ' newline];
                vm  = cellfun(@(x) [x newline], app.editmodes, 'UniformOutput', false);
                msg = [msg vm{:}];
                error(msg)
            end
        end


        function AutoContrast(app)
            % AUTOCONTRAST will launch a selection dialog box for the user
            % to select their prefered autocontrast method. This method
            % will work for both RGB and grayscale images
            I  = app.Image;
            Im = double(I);
            
            if app.autocontrastmethod == 1
                if app.isrgb
                    Im = mean(Im, 3);
                end
                ordI   = sort(Im(:));
                n      = numel(Im);
                lb_idx = round(0.01*n);
                ub_idx = round(0.999*n);
                lb     = ordI(lb_idx);
                ub     = ordI(ub_idx);
            elseif app.autocontrastmethod == 2
                if app.isrgb
                    for i = 1:3
                        Im(:,:,i) = medfilt2(Im(:,:,i));
                    end
                else
                    Im        = medfilt2(Im);
                end
                [lb, ub] = bounds(Im(:));
            elseif app.autocontrastmethod == 3

                [lb, ub] = bounds(Im(:));
            end
            if app.isrgb
                app.imH.CData = (I-lb).*(255/(ub-lb));
            else
                app.UIAxes.CLim = [lb, ub];
            end
        end


        function ManualContrastRGB(app)
            % MANUALCONTRASTRGB launches an input dialog box for the user
            % to set the bottom and upper limits manually for their RGB
            % image - since imcontrast does not work with RGB
            prompt   = {'Min (0-255)', 'Max (0 - 255)'};
            dlgtitle = 'RGB Image Manual Contrast';
            dims     = [1 30];
            if ~isempty(app.rgbclims)
                definput = {num2str(app.rgbclims(1)), num2str(app.rgbclims(2))};
            else
                definput = {'0', '255'};
            end

            % Input dialog prompts user to define bounds
            answer   = myInputDlg(prompt, dlgtitle, dims, definput);
            focus(app.UIFigure)
            if isempty(answer)
                return
            end
            
            if ~isempty(answer)
                lb        = str2double(answer{1});
                ub        = str2double(answer{2});
                % Bound check the lower bound
                lb        = max(0, lb);
                lb        = min(lb, 254);
                % Bound check the upper bound
                ub        = min(255, ub);
                ub        = max(ub, lb+1);
                app.imH.CData = (app.Image-lb)*(255/(ub-lb));
            end

            % Saving the contrast bounds
            app.rgbclims = [lb ub];
        end
    end


    methods (Access = private)
        %% Configuration
        function AdjustButtonsForDataType(app)
            % ADJUSBUTTONS will disable mask related buttons and their
            % functionality when points are loaded in
            if app.ispointsdata
                app.Unmask.Enable           = false;
                app.MaskAllButton.Enable    = false;
                app.InvertMaskButton.Enable = false;
                app.CloseInterior.Enable    = false;
                app.CloseAllButton.Enable   = false;
            end
        end


        %% Point Editing
        % MIGHT NEED TO DO A UNIQUE POINT CHECK...
        % For now, the unique point check can be done before export in case
        % user appended multiple of the same point or "removed" a point
        % multiple times.

        function InitAppendingPlot(app, P)
            % INITAPPENDINGPLOT will initialize the plot of "appended" points
            markercolor       = 1-app.plotcolor(2,:);
            app.dataplotH{2}  = plot(app.ax2,...
                                     P(:,1), P(:,2), ...
                                     "Color",markercolor, ...
                                     "LineStyle","none", ...
                                     "MarkerSize", app.markersize, ...
                                     "Marker", '+');
        end


        function InitDeletingPlot(app, P)
            % INITDELETINGPLOT will initialize the plot of "deleted" points
            markercolor       = 1 - fliplr(app.plotcolor(2,:));
            app.dataplotH{3}  = plot(app.ax2,...
                                     P(:,1), P(:,2), ...
                                     "Color", markercolor, ...
                                     "LineStyle","none", ...
                                     "MarkerSize", app.markersize, ...
                                     "Marker", 'x');
        end


        function AppendOrRestoreDataPoints(app, Mask)
            % APPENDDATAPOINTS will adjust the second plot to have appended
            % points

            % Number of points in original data
            nP_og = size(app.Results, 1);

            % Finding points in region from original set
            idx = app.FindPointsInRegion(Mask);

            % No points found
            if isempty(idx)
                return
            end

            idx = idx(1:nP_og);

            % If original points were found in region and some points have
            % been removed, we can restore the points
            if any(idx) && ~isempty(app.dataplotH{3})
                x    = app.dataplotH{1}.XData(idx);
                y    = app.dataplotH{1}.YData(idx);
                x_d  = app.dataplotH{3}.XData;
                y_d  = app.dataplotH{3}.YData;
                P    = [x' y'];
                P_d  = [x_d' y_d'];
                pinD = ismember(P_d, P);
                pinD = pinD(:,1) & pinD(:,2);
                pinD = pinD';


                % No need to compute a mask centroid to append if we are
                % restoring points
                if any(pinD)
                    % Drops any original set points found in the region
                    app.dataplotH{3}.XData = app.dataplotH{3}.XData(~pinD);
                    app.dataplotH{3}.YData = app.dataplotH{3}.YData(~pinD);
                    return
                end
            end

            % When there are no original points in the region:
            % In lieu of point restoration, new point is appended
            P = app.Mask2Point(Mask);
            x = P(:,1);
            y = P(:,2);

            % When plot has yet to be filled
            if isempty(app.dataplotH{2})
                app.InitAppendingPlot(P)
                return
            end

            % Grow the list of points
            X = app.dataplotH{2}.XData;
            Y = app.dataplotH{2}.YData;
            app.dataplotH{2}.XData = [X x];
            app.dataplotH{2}.YData = [Y y];
        end


        function restorable = PointsRestorable(app, idx)
            if isempty(app.dataplotH{3})
                restorable = false;
            else
                x = app.dataplotH{1}.XData;
                y = app.dataplotH{1}.YData;
                P = [x(idx) y(idx)];
            end
        end


        function RemoveDataPoints(app, mask)
            % REMOVEDATAPOINTS will try to find points inside the masked
            % region but if no points are found, then a nearest neighbor
            % approach is taken instead

            % Polygon approach
            foundpoints = app.RemovePointsInRegion(mask);

            % NN approach taken if none found in polygon
            if ~foundpoints
                [r, c] = find(mask);
                P      = [c r];
                app.DeleteNearestDataPoint(P)
            end
        end


        function idx = FindPointsInRegion(app, mask)
            % Polygon Vertice points
            BWB = bwboundaries(mask);
            if ~isempty(BWB)
                P   = BWB{1};
                xv  = P(:,1);
                yv  = P(:,2);
    
                % Grabs points inside polygon and on polygon edge
                Vpoints  = app.Currentpoints;
                xq       = Vpoints(:,2);
                yq       = Vpoints(:,1);
                [in, on] = inpolygon(xq, yq, xv, yv);
                idx      = in | on;
            else
                idx = [];
            end
        end


        function foundpoints = RemovePointsInRegion(app, mask)
            % REMOVEPOINTSINREGION will find the boundary of a mask and
            % then "removes" any points in that region by marking them with
            % an x.

            % Find points inside region
            idx = app.FindPointsInRegion(mask);
            
            % Whether or not any points were found inside
            foundpoints = any(idx);
            if ~foundpoints
                return
            end
            
            % Number of points in original data
            nP_og = size(app.Results, 1);

            % original points
            idx_og  = idx(1:nP_og);

            % appended points
            idx_app = idx((nP_og+1):end); 

            % Drawing "deletion" marker atop the original data
            if ~isempty(idx_og) && any(idx_og)
                idx_og = find(idx_og);
                app.OriginalPoint2DeletedPoint(idx_og)
            end

            % Removing from appended plot
            if ~isempty(idx_app) && any(idx_app)
                idx_app = find(idx_app);
                app.RemoveFromAppendPlot(idx_app)
            end
        end


        function DeleteNearestDataPoint(app, P)
            % DELETEDATAPOINTS will find the point that is the nearest 
            % neighbor and mark it with an x if from the original results
            % or delete it if it was an appended point.
            
            % Extracting valid points
            Vpoints = app.Currentpoints;

            % Finding the nearest neighbor index
            [~, D] = knnsearch(P, Vpoints, "K", 1);

            % Alternative max distance could be max distance between
            % polygons points from x and y vars

            % Returns empty indices list if further than 2 times the brush
            % radius
            r   = 2*app.brushradius;
            idx = D<r;

            % No points found, return
            if ~any(idx)
                return
            end
            
            % Number of points in original data
            nP_og   = size(app.Results, 1);

            % Original points
            idx_og  = idx(1:nP_og);

            % Appended points
            idx_app = idx(nP_og:end);

            % Drawing "deletion" marker atop the original data
            if ~isempty(idx_og) && any(idx_og)
                idx_og = find(idx_og);
                app.OriginalPoint2DeletedPoint(idx_og)
            end

            % Removing from appended plot
            if ~isempty(idx_app) && any(idx_app)
                idx_app = find(idx_app);
                app.RemoveFromAppendPlot(idx_app)
            end
        end


    % Point "deleting" functions
        function OriginalPoint2DeletedPoint(app, idx)
            % APPEND2DELETEDPLOT will append the specified point to the
            % deleted plot. This makes the asssumption that the point
            % belonged to the original results.

            % Grabs point to "delete"
            VPoints = app.Currentpoints;
            P       = VPoints(idx, :);

            % Will initialize the deleting plot if it doesn't exist yet
            if isempty(app.dataplotH{3})
                app.InitDeletingPlot(P)
                return
            end

            % Original points - have the deleted plot atop
            app.dataplotH{3}.XData = [app.dataplotH{3}.XData P(:,1)'];
            app.dataplotH{3}.YData = [app.dataplotH{3}.YData P(:,2)'];
        end


        function RemoveFromAppendPlot(app, idx)
            % REMOVEFROMAPPENDPLOT will remove points from the appended
            % points plot.
            % Appended points - delete point from the "appended" plot
            allindices             = 1:numel(app.dataplotH{2}.XData);
            idx                    = ~ismember(allindices, idx);
            app.dataplotH{2}.XData = app.dataplotH{2}.XData(idx);
            app.dataplotH{2}.YData = app.dataplotH{2}.YData(idx);
        end
    end


    methods (Access = private)
    %% Interactivity Functions
        function SetPlotCallbacks(app)
            % SETPLOTCALLBACKS sets up the plot / axes related callbacks
            % for the mouse.

            % Enables the callback used for paint brush feature
            set(app.UIFigure, 'WindowButtonMotionFcn', @app.MouseMoving)

            % Will only edit the mask when mouse button is clicked down
            set(app.UIFigure, 'WindowButtonUpFcn', @app.AdjustOff)

            % Enabling scroll wheel zooming
            set(app.UIFigure, 'windowscrollWheelFcn', @app.AdjustZoom)
            set(app.UIAxes.XAxis, 'LimitsChangedFcn', @app.CheckZoom)
            set(app.UIAxes.YAxis, 'LimitsChangedFcn', @app.CheckZoom)
        end


        function MouseMoving(app, ~, ~)
            % MOUSEMOVING will get the mouse position relative to the axes
            % and then update the mask if the mouse is being clicked
            app.Mouseposition = get(app.UIAxes, 'CurrentPoint');
            app.Mouseposition = [app.Mouseposition(1, 2) app.Mouseposition(1, 1)];
            
            app.UpdateCursor
            if ~isempty(app.appendordelete) || app.editmode == 4
                switch app.editmode
                    case 2
                        % if app.clickisdown
                        %     app.ClearROI
                        %     app.PaintPoint
                        % end
                    case 3
                        if app.clickisdown
                            % Will add point to curve of lasso
                            app.DrawLasso
                        end
                    case 4
                        if app.clickisdown
                            % Will append points to animated line
                            app.TurboPaint
                        end
                end
            end

            % "Listener" prevents accidental resizing below a threshold
            app.CheckWindowSize
        end


        function CheckWindowSize(app)
            % CHECKWINDOWSIZE constantly checks the size of the GUI window
            % to ensure accurate rescaling of the animated line width for
            % the turbopaint edit mode.
            
            % Forced resizing of GUI when smaller than initial size
            if app.initialguisize(1) > app.UIFigure.Position(3) || app.initialguisize(2) > app.UIFigure.Position(4)
                Sizedifference        = app.UIFigure.Position(3:4) - app.initialguisize;
                Leftbottom            = app.UIFigure.Position(1:2);
                Delta                 = app.monitorsize - (Leftbottom + app.initialguisize);
                Delta                 = min(Delta.*[1 4], Sizedifference);
                app.UIFigure.Position = max([(Leftbottom + Delta) (app.initialguisize)], 0);
            end

            % Initialization of the previous size
            if isempty(app.oldguisize)
                app.oldguisize = app.UIFigure.Position(3:4);
                return
            end

            % Different sizing - line width needs to be recomputed
            sizechanged = app.oldguisize ~= app.UIFigure.Position(3:4);
            sizechanged = any(sizechanged);
            if sizechanged
                app.ResizeLineWidth
            end
            app.oldguisize = app.UIFigure.Position(3:4);
        end


        function AdjustZoom(app, ~, event)
            % ADJUSTZOOM will allow user to zoom by scrolling on mouse
            % Determining zoom direction and amount
            if app.editmode == 4 && ~isempty(app.appendordelete)
                return
            end
            scaling = max(app.mrows, app.ncols)/512;
            d       = event.VerticalScrollCount;
            amt     = event.VerticalScrollAmount;
            amt     = scaling*amt;
            % Determining where to zoom
            xL      = app.ax2.XLim;
            yL      = app.ax2.YLim;
            if app.CursorOverAxes
                if d > 0
                    % Zoom in
                    amt    = [-amt amt];
                    new_yL = [yL(1), yL(2)] + amt;
                    new_xL = [xL(1), xL(2)] + amt;
                elseif d < 0
                    % Zoom out
                    amt    = [amt -amt];
                    new_yL = [yL(1), yL(2)] + amt;
                    new_xL = [xL(1), xL(2)] + amt;
                end
                % Recentering on the mouse position when the callback is
                % from a key press rather than the mouse scroll.
                if isfield(event, 'Button')
                    dy     = (new_yL(2)-new_yL(1))/2;
                    dx     = (new_xL(2)-new_xL(1))/2;
                    new_yL = [app.Mouseposition(1)-dy app.Mouseposition(1)+dy];
                    new_xL = [app.Mouseposition(2)-dx app.Mouseposition(2)+dx];
                    new_yL = round(new_yL);
                    new_xL = round(new_xL);
                end
                % Bound Checking on zoom limits
                new_xL = min(app.ncols, max(new_xL, 1));
                new_yL = min(app.mrows, max(new_yL, 1));
                % Setting the new axes limits
                try
                    set(app.ax2, 'XLim', new_xL)
                    set(app.UIAxes, 'XLim', new_xL)
                    set(app.ax2, 'YLim', new_yL)
                    set(app.UIAxes, 'YLim', new_yL)
                catch
                end
            end
            app.ResizeLineWidth
            uiresume(app.UIFigure)
%             drawnow
            pause(0.001)
        end

        
        function InteractivityOff(app)
            % INTERACTIVITYOFF will toggle interactivity off when masking
            % to prevent errors.
            app.TurnOffPan
            app.TurnOffZoom
        end


        function TurnOffZoom(app)
            % Ensures zoom mode off
            app.ZoomOut.State = 'off';
            app.ZoomIn.State  = 'off';
            if ~isempty(app.zoomObj)
                app.zoomObj.enable = 'off';
            end
        end


        function TurnOffPan(app)
            % Ensures the panning mode is off
            if app.Pan.State
                app.Pan.State = 'off';
                app.PanClicked
            end
        end


        function UpdateUndoStack(app)
            % UPDATEUNDOSTACK will update the results data that you can
            % undo back to after edits.

            % Sets the undostack as needed for the results format
            if app.ispointsdata
                % Points case loops through all plots
                for i = 1:3
                    % Will fail when plot is void
                    try
                        app.Undostack{1,i,1} = app.dataplotH{i}.XData;
                        app.Undostack{2,i,1} = app.dataplotH{i}.YData;
                    catch
                    end
                end
            else
                % Mask case
                app.Undostack(:,:,1) = app.dataplotH.AlphaData;
            end

            % User can undo now after updating edits
            app.UIFigure.Tag = 'undo';
        end


        function UndoOrRedo(app, isundorequest)
            % UNDOORREDO will either undo or redo the changes to plotted
            % results. The allowable action is updated to prevent 
            % overwrites.

            % True - can undo
            % False - can redo
            canundo = strcmp(app.UIFigure.Tag, 'undo');
            if isundorequest && canundo
                % Assigns the previous data but now allows for a redo
                app.UIFigure.Tag = 'redo';
                if app.ispointsdata
                    % Points case loops through all plots
                    for i = 1:3
                        try
                        % Indices of the undostack correspond to:
                        % Dimension(x or y), plot index, stack index
                        app.Undostack{1,i,2}   = app.dataplotH{i}.XData;
                        app.Undostack{2,i,2}   = app.dataplotH{i}.YData;
                        app.dataplotH{i}.XData = app.Undostack{1,i,1};
                        app.dataplotH{i}.YData = app.Undostack{2,i,1};
                        catch
                        end
                    end
                
                else
                    % Mask case
                    app.Undostack(:,:,2)    = app.dataplotH.AlphaData;
                    app.dataplotH.AlphaData = app.Undostack(:,:,1);
                end

            elseif ~isundorequest && ~canundo
                % Reassigns changes that were made but allows for undo
                app.UIFigure.Tag = 'undo';
                if app.ispointsdata
                    % Points case
                    for i = 1:3
                        try
                        % Indices of the undostack correspond to:
                        % Dimension(x or y), plot index, stack index
                        app.Undostack{1,i,1} = app.dataplotH{i}.XData;
                        app.Undostack{2,i,1} = app.dataplotH{i}.YData;
                        app.dataplotH{i}.XData = app.Undostack{1,i,2};
                        app.dataplotH{i}.YData = app.Undostack{2,i,2};
                        catch
                        end
                    end

                else
                    % Mask case
                    app.Undostack(:,:,1)    = app.dataplotH.AlphaData;
                    app.dataplotH.AlphaData = app.Undostack(:,:,2);
                end

            end
        end


        function PanAxes(app, ~, KeyData)
            % PANAXES will allow user to pan around the axes with the awsd
            % keys - when the shift key is pressed down
            Key      = KeyData.Key;

            % Scaling the pan by image size
            scaling      = max(app.mrows, app.ncols)/512;
            amt          = scaling*10;
            amt          = round(amt);
            switch Key
                % Origin of image is at top left (inverted y axis)
                case 'w'
                    % Shift up
                    NewYLim = app.UIAxes.YLim - amt;
                    app.UIAxes.YLim = NewYLim;
                case 'a'
                    % Shift left
                    NewXLim         = app.UIAxes.XLim - amt;
                    app.UIAxes.XLim = NewXLim;
                case 's'
                    % Shift down
                    NewYLim         = app.UIAxes.YLim + amt;
                    app.UIAxes.YLim = NewYLim;
                case 'd'
                    % Shift right
                    NewXLim         = app.UIAxes.XLim + amt;
                    app.UIAxes.XLim = NewXLim;
            end
            % Since zoom might change, curve line width is updated
            app.ResizeLineWidth
        end


    %% Data Editing
        function AdjustOn(app, ~, event)
            % ADJUSTON will enable the mask editing by making the
            % appropriate function call based on whether a right or left
            % click was used and the edit mode that is currently set.

            % A left click will add to the mask and a right click will
                % subtract from the click - allows for fast edits
            switch event.Button
                case 1
                    app.appendordelete = 1;
                case 3
                    app.appendordelete = -1;
            end

            % Resumes graphical updates
            uiresume(app.UIFigure)

            % Setting current data as the undo step before editing
            app.UpdateUndoStack

            % Sets click logical
            app.clickisdown = true;

            % Edit mode determines data modifying behavior
            if app.editmode == 1
                % Touches up by changing a single region
                app.TouchUpMask

            elseif app.editmode == 2
                % Masks a single point
                app.PaintPoint

            elseif app.editmode == 3
                app.DrawLasso

            elseif app.editmode == 4
                % Masks the region selected by the animated line
                if ~isempty(app.brushroi)
                    set(app.brushroi, 'FaceAlpha', 1)
                    if app.appendingdata
                        set(app.brushroi, 'Color', app.plotcolor(2,:))
                    elseif app.removingdata
                        set(app.brushroi, 'Color', fliplr(1-app.plotcolor(2,:)))
                    end
                end
                app.TurboPaint

            elseif app.editmode == 5
                app.SeedGrow

            elseif app.editmode == 6
                app.EdgeBasedCut

            end
        end


        function AdjustOff(app, ~, ~)
            % ADJUSTOFF will stop the mouse motion callback from getting
            % coordinates so no edits will take place
            if app.editmode == 1
                % TouchUp / Stamp Mode
                app.clickisdown = false;

            elseif app.editmode == 2
                % Point Mode
                app.clickisdown = false;

            elseif app.editmode == 3
                % Bounding Curve / Lasso Mode
                % Updates result edits AFTER releasing mouse click
                app.clickisdown = false;
                
                % Updates edits
                app.UpdateUndoStack
                app.Lasso2Mask
                
            elseif app.editmode == 4
                % TurboPaint Mode
                % Updates result edits AFTER releasing mouse click
                app.clickisdown = false;
                app.Brush2Mask
                if isa(app.editorH, 'matlab.graphics.animation.AnimatedLine')
                    app.editorH.delete
                end
                app.editorH = [];
                delete(app.Brushcap)
                set(app.brushroi, 'FaceAlpha', 0.1)
                app.UpdateCursorColor

            end

            % Prevents additional edits until another mouse click
            app.appendordelete = [];
        end


        function UpdateCursor(app)
            % UPDATECURSOR will create a circular ROI for the mouse cursor
            % when in paint brush mode if the ROI object does not yet
            % exist. Otherwise, it updates the brush ROI object.
            if isempty(app.brushroi) && ~app.clickisdown
                app.brushroi = drawcircle(app.ax2,...
                                'Center', [app.Mouseposition(2) app.Mouseposition(1)],...
                                'Radius', app.brushradius,...
                                'InteractionsAllowed','none',...
                                'LineWidth', 1,...
                                'FaceAlpha', 0.1,...
                                'Color', app.cursorcolor);
            end

            % Cursor transitioning on/off axes triggers cursor change
            isoveraxes = app.CursorOverAxes;
            if isoveraxes ~= app.wasoveraxes && ~app.Pan.State && ~app.ZoomIn.State && ~app.ZoomOut.State
                app.wasoveraxes = isoveraxes;
                if isoveraxes
                    switch app.editmode
                        case 1
                            % Turns on circular ROI and hides the cursor/pointer
                            app.UIFigure.Pointer           = 'custom';
                            app.UIFigure.PointerShapeCData = nan(16, 16);
                            app.brushroi.Visible           = 'on';

                        case {2, 5, 6}
                            % Turns off circular ROI and shows crosshair cursor
                            app.brushroi.Visible           = 'off';
                            app.UIFigure.Pointer           = 'crosshair';

                        case 3
                            % Circle ROI off and cursor automatic when drawing bounding
                            % curve
                            app.brushroi.Visible           = 'off';

                        case 4
                            % Turns on circular ROI and hides the cursor/pointer
                            app.UIFigure.Pointer           = 'custom';
                            app.UIFigure.PointerShapeCData = nan(16, 16);
                            app.brushroi.Visible           = 'on';

                    end
                else
                    % When pointer is outside axes bounds, arrow pointer appears
                    app.UIFigure.Pointer = 'arrow';
                    app.brushroi.Visible = 'off';
                    
                end
            end

            if isoveraxes
                % When over the axes and in paint mode, the circle ROI
                % position updates
                if app.editmode
                    app.brushroi.Center = [app.Mouseposition(2) app.Mouseposition(1)];
                end
            end
        end


    %% Visual Updates
    % Cursor Related
        function UpdateCursorColor(app, focusFlag)
            % Averages across the complement of the brightest color in the
            % image color map and the complement of the color of the mask
            % Focus flag will ensure the app is in the foreground in case
            % the previous button callback triggered a dialog box
            if nargin < 2
                focusFlag = false;
            end
            if focusFlag
                figure(app.UIFigure)
            end
            if app.isrgb
                mc       = mean(app.Image, [1 2]);
                mc       = mc/255;
                mc       = mc(:);
                im_rgb   = 1-mc';
            else
                im_rgb   = app.cmap(end,:);
            end
            mask_rgb = app.plotcolor(2,:);
            rgb_comp = mean([im_rgb; mask_rgb], 1);
            % Will either directly update cursor color or change the
            % property prior to circle ROI creation
            if app.appendingdata
                app.cursorcolor = app.plotcolor(2,:);
            elseif app.removingdata
                app.cursorcolor = 1-app.plotcolor(2,:);
            else
                if isa(app.brushroi, 'images.roi.Circle') && isvalid(app.brushroi)
                    app.brushroi.Color = rgb_comp;
                else
                    app.cursorcolor  = rgb_comp;
                end
            end
        end


        function Flag = CursorOverAxes(app)
            % Use the mouse position based on the relative units in the
            % figure window rather than the mouse position relative to the
            % axes
            % Check for non negative position values
            mousefigpos = get(app.UIFigure, 'CurrentPoint');
            y_ispos     = mousefigpos(1) > app.Panel.Position(1);
            x_ispos     = mousefigpos(2) > app.Panel.Position(2);

            % Check for values inside axes
            y_in        = app.Panel.Position(3) > mousefigpos(1);
            x_in        = app.Panel.Position(4) > mousefigpos(2);
            y_in        = y_in && y_ispos;
            x_in        = x_in && x_ispos;
            Flag        = y_in && x_in;
        end


    % Live Feedback
        function CheckZoom(app, ~, ~)
            % CHECKZOOM is a wrapper function that will update the paint
            % brush line width whenever the axes limits change
            app.ResizeLineWidth
        end


        function ResizeLineWidth(app)
            % RESIZELINEWIDTH scales the width of the animated line that
            % is drawn when in turbopaint mode so that it accurately 
            % shows which pixels are getting masked
            try
                if app.editmode == 4
                    % Points per pixel (72 pts/inch divided by pixels per inch) = pts/pixel
                    ScrnPts = 72/get(0, 'ScreenPixelsPerInch');

                    % If X dimension is greater, the pixel scaling has
                    % to be scaled based on X rather than Y
                    AxesWidth = app.UIAxes.Position(3);
                    DeltaLim  = app.UIAxes.XLim(2) - app.UIAxes.XLim(1);
                    % Number of screen pixels per axes' pixel
                    Scrn2AxPx = AxesWidth/DeltaLim;
                    % Pts per axes pixel conversion factor
                    ConvF     = ScrnPts*Scrn2AxPx;
                    % Number of axes pixels for the curve line width
                    widthX    = app.brushradius*ConvF;

                    % When Y dimension is greater or image is a square,
                    % Y axes limits can be used
                    AxesWidth = app.UIAxes.Position(4);
                    DeltaLim  = (app.UIAxes.YLim(2) - app.UIAxes.YLim(1));
                    % Number of screen pixels per axes' pixel
                    Scrn2AxPx = AxesWidth/DeltaLim;
                    % Pts per axes pixel conversion factor
                    ConvF     = ScrnPts*Scrn2AxPx;
                    % Number of axes pixels for the curve line width
                    widthY    = app.brushradius*ConvF;

                    % Selecting the smallest of the axes limits - since
                    % clipping is off, there are times where the other
                    % limits can go well beyond the monitor, throwing off
                    % the brush scaling
                    width = min([widthX, widthY]);

                    % Converting to screen pixels
                    app.brushlinewidth = round(2*width);
                    app.brushlinewidth = max(1, app.brushlinewidth);
                    uiresume(app.UIFigure)
                end
            catch ME
                rethrow(ME)
            end
        end


    %% Masking Operations
        function ClosePaintInterior(app, flag)
            % CLOSEPAINTINTERIOR will close the interior of the latest
            % region to have been masked

            % Flag will determine if the entire mask will have its
            % interior closed or if just the latest drawn region will have
            % interior closed
            app.UpdateUndoStack
            if flag
                Mask = app.dataplotH.AlphaData>0;
            else
                Mask = app.newcc;
            end
            Maskfilled = imfill(Mask, 4, "holes");
            % Comparison of mask prior to filling. If nothing changes, this
            % suggests the poly line was open but need to be closed
            comp      = Maskfilled(:) ~= Mask(:);
            if ~any(comp) && isa(app.brushpolyline, 'images.roi.Polyline')
                P    = app.brushpolyline.Position;
                FH   = images.roi.Freehand(app.ax2, 'Position', P, 'Visible', 'off');
                Maskfilled = createMask(FH, app.mrows, app.ncols);
                % Ensures the new mask with closed interior has proper dilation
                Maskfilled = bwdist(Maskfilled) <= app.brushradius;
            end
            app.dataplotH.AlphaData(Maskfilled) = app.transparency;
        end


        function MaskAll(app)
            % MASKALL will mask the entire image. Allows for a subtractive
            % approach to masking image regions
            % In case user did not mean to mask everything
            app.UpdateUndoStack
            app.dataplotH.AlphaData(:,:) = app.transparency;
        end


        function InvertMask(app)
            % INVERTMASK will invert the region that has been masked by the
            % user
            app.UpdateUndoStack
            Msk = app.dataplotH.AlphaData > 0;
            app.dataplotH.AlphaData(Msk) = 0;
            app.dataplotH.AlphaData(~Msk) = app.transparency;
        end
       

    %% Misc Visual Setup
        function SetToolbarIcons(app)
            % SETTOOLBARICONS will create the toolbar icons to eliminate a
            % need for additional files
            IconFolder              = [];
            app.ManualContrast.Icon = [IconFolder 'brightness-window.svg'];
            app.Right.Icon          = [IconFolder 'nav-arrow-right.svg'];
            app.Left.Icon           = [IconFolder 'nav-arrow-left.svg'];
            app.Down.Icon           = [IconFolder 'nav-arrow-down.svg'];
            app.Up.Icon             = [IconFolder 'nav-arrow-up.svg'];
            app.ResetView.Icon      = [IconFolder 'maximize.svg'];
            app.Pan.Icon            = [IconFolder 'drag-hand-gesture.svg'];
            app.ZoomIn.Icon         = [IconFolder 'zoom-in.svg'];
            app.ZoomOut.Icon        = [IconFolder 'zoom-out.svg'];
            app.Reset.Icon          = [IconFolder 'restart.svg'];
            app.Colormap.Icon       = [IconFolder 'palette.svg'];
            app.MaskColor.Icon      = [IconFolder 'color-filter.svg'];
            app.Finished.Icon       = [IconFolder 'accept.svg'];
            app.Help.Icon           = [IconFolder 'question-mark.svg'];
            app.Finished.Icon       = [IconFolder 'accept.svg'];
            app.Cancel.Icon         = [IconFolder 'cancel.svg'];

            % For fun...
            % app.Pan.Icon            = [IconFolder 'spock-hand-gesture.svg'];
        end


        function MakeAppLogo(app)
            % MAKEAPPLOGO makes a logo consisting of a dark ring and a
            % gray circle offset from each other
            J  = zeros(54);
            s  = (-20:20).^2;
            h  = (s+s')<=(20^2);
            h2 = h~=imerode(h, ones(5));
            C1 = double(h2)*128;
            C2 = double(h)*64;
            J(1:41, 1:41)   = C1;
            J(14:54, 14:54) = J(14:54, 14:54)+C2;
            J2 = 255-repmat(J, [1 1 3]);
            J2 = uint8(J2);
            app.UIFigure.Icon = J2;
            app.UIFigure.Tag  = 'ready';
        end


        function SetPanelPositions(app)
            % Ensures positions preserved for the panels
            if ~isempty(app.BtnPanelDims)
            end
            app.Panel.Units      = 'normalized';
            app.Panel.Position   = [0.02225 0.02825 0.9522 0.7935];
            app.Panel_2.Units    = 'normalized';
            app.Panel_2.Position = [-0.0016 0.8198 1.0032 0.1822];
            app.UIAxes.Units     = 'normalized';
            app.UIAxes.Position  = [0 0 1 1];
            app.ax2.Units        = 'normalized';
            app.ax2.Position     = [0 0 1 1];
            
        end


        function ColormapSelector(app)
            depth   = 1024;
            choices = {'Gray','Fire','Ice','Emerald','Bone','Solar','Thermal',...
            'Haline','Polar','DarkPolar','Ultra','Spectrum','Scatter',...
            'Ocean','Red','Green','Blue','Yellow','Inverted','Parula','DarkParula', 'CoolMagma'};
            idx = listdlg('ListString',choices);

            % Prevents flicker
            focus(app.UIFigure)

            if isempty(idx)
                app.cmap = bone(256);
            else
                map  = choices{idx(1)};
                map  = ['app.' map];
                app.cmap = eval([map '(' num2str(depth) ');']); %#ok<EVLDOT>
            end
            colormap(app.UIAxes, app.cmap)
        end
        

        function HelpInstruction(app)
            % HTML Launch with instructions for user
            if isempty(app.Helpwindow) || ~isvalid(app.Helpwindow)
                pos                       = [128 128 640 640];
                hfig                      = uifigure('Name', 'GUI Instructions', 'Position', pos);
                hfig.Icon                 = ['SVGIcons' filesep 'question-mark.png'];
                app.Helpwindow            = uihtml(hfig, 'Position', [32 32 576 576]);
                filepath                  = mfilename("fullpath");
                pathname                  = fileparts(filepath);
                app.Helpwindow.HTMLSource = fullfile(pathname, 'HelpPage_MaskingTool.html');
            end
        end
    end


    methods (Static)
        function P = Mask2Point(Mask)
            % MASK2POINT will fill in a mask to ensure no holes are present
            % and then compute the centroid of the resulting mask to
            % produce a single point. This returns the x, y coordinates
            [~, c] = size(Mask, [1 2]);
            if c > 2
                Mask = imfill(Mask, 'holes');
                C    = regionprops(Mask, 'Centroid');
                P    = [C.Centroid];
            else
                P = Mask;
            end
        end


        function Im = ImageFileLoad(fid, M, N, D)
            % IMAGEFILELOAD will load an RGB image or compute a maximum
            % intensity projection from a tiff image stack in a memory
            % efficient manner. Otherwise, if just a 2D image file, the 
            % image is loaded regularly
            if nargin < 4 
                if ischar(fid) || isstring(fid)
                    im_info = imfinfo(fid);
                    M       = im_info(1).Height;
                    N       = im_info(1).Width;
                    D       = numel(im_info);
                end
            end
        
            dtype = im_info(1).BitDepth;
            if dtype > 24
                % Flag for rescaling to uint8 when RGB
                rescale = true;
            else
                rescale = false;
            end
            if mod(dtype, 3) == 0 && dtype >= 24
                % In case the bitdepth is 24 or 48, the image will be RGB
                D     = 3;
            else
                dtype = ['uint' num2str(dtype)];
                Im   = zeros([M, N], dtype);
            end
            
            if D > 3
                wb    = waitbar(0, 'Computing maximum projection');
                for i = 1:D
                    im  = tiffreadVolume(fid, 'PixelRegion', {[1 inf], [1 inf], [i i]});
                    Im  = max(im, Im);
                    wb  = waitbar(i/D);
                    pause(0.001)
                end
                wb.delete
            else
                if contains(fid, {'.ome', '.czi'})
                    % Will need to use bioformats - NOTE: cannot use imfinfo for
                    % these files
                else
                    % In case a different format / an RGB image
                    Im = imread(fid);
                    if rescale
                        % Will reduce the image to 8 bits
                        Im = double(Im);
                        lb  = min(Im(:));
                        ub  = max(Im(:));
                        Im = (Im-lb)*(255/(ub-lb));
                        Im = uint8(Im);
                    end
                end
            end
            
        end

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%% COLORMAP FUNCTIONS
        function cmap = DarkParula(N)
            n    = round(2*N/3);
            cmap = parula(n);
            cmap = [linspace(0,1,round(N/3))'.*cmap(1,:);cmap]; 
        end
        
        function cmap = Gray(N)
            cmap = gray(N);
        end
        
        function cmap = Fire(N)
        
            n1    = round(N/3); n2 = round(2*N/3); n3 = N;
            Blue  = [zeros(1,n2) linspace(0,1,n3-n2)];
            Green = [zeros(1,n1) linspace(0,1,n2-n1) ones(1,n3-n2)];
            Red   = [linspace(0,1,n1) ones(1,n3-n1)];
            cmap  = [Red(:) Green(:) Blue(:)];
        
        end
        
        function cmap = Ice(N)
        
            n1    = round(N/3); n2 = round(2*N/3); n3 = N;
            Red   = [zeros(1,n2) linspace(0,1,n3-n2)];
            Green = [zeros(1,n1) linspace(0,1,n2-n1) ones(1,n3-n2)];
            Blue  = [linspace(0,1,n1) ones(1,n3-n1)];
            cmap  = [Red(:) Green(:) Blue(:)];
        
        end
        
        function cmap = Emerald(N)
        
            n1    = round(N/3); n2 = round(2*N/3); n3 = N;
            Blue  = [zeros(1,n2) linspace(0,1,n3-n2)];
            Red   = [zeros(1,n1) linspace(0,1,n2-n1) ones(1,n3-n2)];
            Green = [linspace(0,1,n1) ones(1,n3-n1)];
            cmap  = [Red(:) Green(:) Blue(:)];
        
        end
        
        function cmap = Bone(N)
            levels = linspace(0,1,N);
            cmap   = [levels(:) levels(:) levels(:)];
        end
        
        function cmap = Solar(N)
            n1    = round(N/3); n2 = round(2*N/3); n3 = N;
            Blue  = [zeros(1,n2) linspace(0,1,n3-n2)];
            Green = [linspace(0,1,n2) ones(1,n3-n2)];
            Red   = [linspace(0,1,n1) ones(1,n3-n1)];
            cmap  = [Red(:) Green(:) Blue(:)];
        end
        
        function cmap = Thermal(N)
            if mod(N,2)==0
                N=N+1;
            end
        
            n = floor(N/2);
        
            n1      = round(n/3); 
            n2      = round(2*n/3); 
            n3      = n;
            Red     = [zeros(1,n2) linspace(0,1,n3-n2)];
            Green   = [zeros(1,n1) linspace(0,1,n2-n1) ones(1,n3-n2)];
            Blue    = [linspace(0,1,n1) ones(1,n3-n1)];
            IceMap  = [Red(:) Green(:) Blue(:)];
        
            n1      = round(n/3); n2 = round(2*n/3); n3 = n;
            Blue    = [zeros(1,n2) linspace(0,1,n3-n2)];
            Green   = [zeros(1,n1) linspace(0,1,n2-n1) ones(1,n3-n2)];
            Red     = [linspace(0,1,n1) ones(1,n3-n1)];
            FireMap = [Red(:) Green(:) Blue(:)];
        
            cmap    = [flipud(IceMap);0 0 0;FireMap];
        end
        
        function cmap = Ultra(N)
            n1    = round(N/3); n2 = round(2*N/3); n3 = N;
            Green = [zeros(1,n2) linspace(0,1,n3-n2)];
            Blue  = [linspace(0,1,n2) ones(1,n3-n2)];
            Red   = [linspace(0,1,n1) ones(1,n3-n1)];
            cmap  = [Red(:) Green(:) Blue(:)];
        end
        
        function cmap = Ocean(N)
            n2    = round(2*N/3); n3 = N;
            Blue  = [zeros(1,n2) linspace(0,1,n3-n2)];
            Green = [linspace(0,1,n2) fliplr(linspace(0,1,n3-n2))];
            Red   = zeros(1,N);
            cmap  = [Red(:) Green(:) Blue(:)];
        end
        
        function cmap = Haline(N)
          colors = [...
              0 0 0;...
              0 0 1;...
              0 0.5 1;...
              0 1 0.5;...
              1 1 0;...
              1 1 1];
          [x,y]   = meshgrid(1:3,1:size(colors,1));
          [xf,yf] = meshgrid(1:3,linspace(1,size(colors,1),N));
          cmap    = interp2(x,y,colors,xf,yf);
        end
        
        function cmap = Polar(N)
          colors = [...
              0 0 1;...
              1 1 1;...
              1 0 0];
          [x,y]   = meshgrid(1:3,1:size(colors,1));
          [xf,yf] = meshgrid(1:3,linspace(1,size(colors,1),N));
          cmap    = interp2(x,y,colors,xf,yf);
        end
        
        function cmap = DarkPolar(N)
          colors = [...
              0 0 1;...
              0 0 0;...
              1 0 0];
          [x,y]   = meshgrid(1:3,1:size(colors,1));
          [xf,yf] = meshgrid(1:3,linspace(1,size(colors,1),N));
          cmap    = interp2(x,y,colors,xf,yf);
        end
        
        function cmap = Red(N)
          colors = [...
              1 1 1;...
              1 0 0];
          [x,y]   = meshgrid(1:3,1:size(colors,1));
          [xf,yf] = meshgrid(1:3,linspace(1,size(colors,1),N));
          cmap    = interp2(x,y,colors,xf,yf);
        end
        
        function cmap = Green(N)
          colors = [...
              1 1 1;...
              0 1 0];
          [x,y]   = meshgrid(1:3,1:size(colors,1));
          [xf,yf] = meshgrid(1:3,linspace(1,size(colors,1),N));
          cmap    = interp2(x,y,colors,xf,yf);
        end
        
        function cmap = Blue(N)
          colors = [...
              1 1 1;...
              0 0 1];
          [x,y]   = meshgrid(1:3,1:size(colors,1));
          [xf,yf] = meshgrid(1:3,linspace(1,size(colors,1),N));
          cmap    = interp2(x,y,colors,xf,yf);
        end
        
        function cmap = Yellow(N)
          colors = [...
              0 0 0;...
              1 1 0];
          [x,y]   = meshgrid(1:3,1:size(colors,1));
          [xf,yf] = meshgrid(1:3,linspace(1,size(colors,1),N));
          cmap    = interp2(x,y,colors,xf,yf);
        end
        
        function cmap = Inverted(N)
             colors = [...
              1 1 1;...
              0 0 0];
          [x,y]   = meshgrid(1:3,1:size(colors,1));
          [xf,yf] = meshgrid(1:3,linspace(1,size(colors,1),N));
          cmap    = interp2(x,y,colors,xf,yf);
        end
        
        function cmap = Spectrum(N)
            colors = [...
              0.5 1 0;...
              1 0 0;...
              0.1 0.5 0;...
              1 1 0;...
              0 1 1;...
              0 0 1;...
              0.5 0 1;...
              1 0.2 1];
          [x,y]   = meshgrid(1:3,1:size(colors,1));
          [xf,yf] = meshgrid(1:3,linspace(1,size(colors,1),N));
          cmap    = interp2(x,y,colors,xf,yf);
        end
        
        function cmap = Parula(N)
            cmap = parula(N);
        end
        
        function cmap = Scatter(N)
            cmap      = parula(N);
            cmap(1,:) =[1 1 1];
            cmap      = imfilter(cmap,ones(5,1)/5,'replicate');
            cmap(1,:) =[1 1 1];
        end
        
        function cmap = CoolMagma(N)
            colors = [...
                  0 0 0;...
                  0 0 1;...
                  0 0.5 1;...
                  0 1 0.5;...
                  1 1 0;...
                  1 1 1];
            [x,y]   = meshgrid(1:3,1:size(colors,1));
            [xf,yf] = meshgrid(1:3,linspace(1,size(colors,1),N));
            cmap    = interp2(x,y,colors,xf,yf);
            cmap    = 1 - flipud(cmap);
        end
    end


    methods
        %% Get / Set Methods
        % e.g. dependent properties
        % For improved readability and simple syntax across methods

        function ispoints = get.ispointsdata(app)
            % returns true when results data is a set of points
            ispoints = any(strcmp(app.Datatype, {'pointcloud', 'contour'}));
        end

        function isappending = get.appendingdata(app)
            % true when user is appending data
            isappending = app.appendordelete == 1;
        end

        function isremoving = get.removingdata(app)
            % true when user is removing data
            isremoving = app.appendordelete == -1;
        end

        function Vpoints = get.Currentpoints(app)
            % original and appended data points
            % Will return empty
            if ~app.ispointsdata
                Vpoints = [];
                return
            end

            % Original points
            X = app.dataplotH{1}.XData;
            Y = app.dataplotH{1}.YData;

            % Appended points if present
            if ~isempty(app.dataplotH{2})
                X = [X app.dataplotH{2}.XData];
                Y = [Y app.dataplotH{2}.YData];
            end

            % Convert to 2-column array
            Vpoints = [X' Y'];
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, I, resultsdata, cmap, clims, plotcolor)
            % Hides GUI prior to setup to prevent flicker
            app.UIFigure.Visible = 'off';
            
            % Initializes the monitor/screen size
            app.monitorsize = get(0, 'ScreenSize');
            app.monitorsize = app.monitorsize(3:4);


            % Gets parent app that is calling the masking tool to ensure
            % proper refocusing of the windows when dialog windows appear
            f = gcbf;
            if ~isempty(f)
                app.Parent = f;
            else
                % When running on its own, needs path added to properly run
                fp         = mfilename('fullpath');
                [path, ~]  = fileparts(fp);
                path       = genpath(path);
                addpath(path);
                app.Parent = 0;
            end

            % Setting the toolbar spacer to be a blank icon
            Offwhite               = [0.94 0.94 0.94];
            Blankicon              = reshape(Offwhite, [1 1 3]);
            Blankicon              = repmat(Blankicon, [40 40]);
            app.ToolbarSpacer.Icon = Blankicon;            

            % Recentering the GUI
            if ~isempty(app.Parent)
                centerfig(app.UIFigure, app.Parent)
            else
                centerfig(app.UIFigure)
            end
            drawnow

            % Mask color
            if nargin < 6
                app.plotcolor = [0 0 0; 1 1 1];
            else
                app.plotcolor = [0 0 0; plotcolor];
            end
            
            % Image loading and plotting
            if nargin < 5
                clims = [];
            end

            % Colormap setting
            if nargin < 4
                app.cmap = bone;
            else
                app.cmap  = cmap;
            end

            % Results
            if nargin < 3
                resultsdata = [];
            end
            
            if nargin < 2 || isempty(I)
                % Loads image from a dialog box
                filt = {'*.jpg; *.jpeg; *.png; *.tif; *.tiff'};
                [fname, pname]  = uigetfile(filt, 'Select image file', '', 'MultiSelect','off');
                % If user clicks cancel, the app will just close
                if isnumeric(fname)
                    app.delete
                    return
                end
                fid = fullfile(pname, fname);
                I   = fid;
            end

            % In case someone loads in a mask rather than image
            if islogical(I)
                I = uint8(I);
            end

            % Needed to activate zoom and pan modes
            enableLegacyExplorationModes(app.UIFigure)
            
            % Setting color limits
            app.CLims = clims;

            % Determining the data type and set button functionality
            app.SetData(resultsdata)
            app.AdjustButtonsForDataType

            % Plotting image and results
            app.PlotImage(I)
            app.PlotOriginalResults(resultsdata)

            % Adjusts the limits on the paint radius
            app.RadiusEditField.Limits = [1, floor(min(app.mrows,app.ncols)/2)];

            % Setup all relevant mouse callbacks
            app.SetPlotCallbacks

            % Initial GUI size to later prevent excessive resizing
            app.initialguisize = app.UIFigure.Position(3:4);

            % Get graphic logos
            app.SetToolbarIcons
            app.MakeAppLogo

            % Small pause for graphics to settle
            waitfor(app.UIFigure, 'Tag', 'ready')
            pause(0.001)
            app.UIFigure.Visible = 'on';

            % Sets the current state for the undo stack
            app.UIFigure.Tag     = 'redo';

            % Computes edge enhanced image from image data
            app.ComputeEdges

            % App interrupt settings and framerate cap
            % SetAppInterrupt(app)
            drawnow limitrate
        end

        % Value changed function: RadiusEditField
        function RadiusEditFieldValueChanged(app, event)
            % Edit field will either edit the paint brush radius or the
            % shrink factor (alpha) value - depends on editing method
            value = app.RadiusEditField.Value;
            if app.editmode == 1
                app.brushradius           = value;
                if isa(app.brushroi, 'images.roi.Circle')
                    app.brushroi.Radius = app.brushradius;
                end
            elseif app.editmode == 4
                % Changes the radius of the turbo paint brush
                app.brushradius         = value;
                if isa(app.brushroi, 'images.roi.Circle')
                    app.brushroi.Radius = app.brushradius;
                end
                app.ResizeLineWidth
            elseif app.editmode == 5 || app.editmode == 6
                % Seed grow mode
                % Percentage of the range of pixel intensity values
                value             = value/100;
                scaling           = app.imagerange(2) - app.imagerange(1);
                value             = scaling*value;
                app.seedtol = value + app.imagerange(1);
            end
            app.InteractivityOff
            focus(app.UIFigure)
        end

        % Value changed function: ModeDropDown
        function ModeDropDownValueChanged(app, event)
            % Takes the focus off of the button so the button doesn't
            % appear clicked
            focus(app.UIFigure)
            % Forces the mouse to be off so that no edits are made til the
            % user clicks again after having changed the mode
            app.appendordelete   = [];
            app.clickisdown = false;
            app.ClearROI
            % Sets the edit mode according to selection
            value         = app.ModeDropDown.Value;
            app.ChangeMode(value)
            % Toolbar buttons turned off, assuming user is now planning on
            % editing the mask
            app.TurnOffZoom
            app.TurnOffPan
            switch app.editmode
                case 1
                    % Touch up mode
                    app.RadiusEditField.Enable    = true;
                    app.RadiusEditFieldLabel.Text = 'Radius';
                    app.RadiusEditField.Limits    = [1, floor(min(app.mrows,app.ncols)/2)];
                    app.RadiusEditField.Value     = app.brushradius;
                    app.CloseAllButton.Enable     = 'off';
                    app.CloseInterior.Enable      = 'off';
                case 2
                    % Point Mode
                    app.RadiusEditField.Limits    = [0, 1];
                    app.RadiusEditFieldLabel.Text = '';
                    app.RadiusEditField.Value     = 0;
                    app.RadiusEditField.Enable    = false;
                    app.CloseAllButton.Enable     = 'off';
                    app.CloseInterior.Enable      = 'off';
                case 3
                    % Bounding curve mode
                    app.RadiusEditField.Enable    = false;
                    app.RadiusEditField.Limits    = [0, 1];
                    app.RadiusEditField.Value     = 0;
                    app.RadiusEditFieldLabel.Text = '';
                    app.CloseAllButton.Enable     = 'off';
                    app.CloseInterior.Enable      = 'off';
                    % Init set mode
                    app.appendordelete                   = 1;
                case 4
                    % Turbo Paint mode
                    app.RadiusEditField.Enable    = true;
                    app.RadiusEditFieldLabel.Text = 'Radius';
                    app.RadiusEditField.Limits    = [1, floor(min(app.mrows,app.ncols)/2)];
                    app.RadiusEditField.Value     = app.brushradius;
                    app.CloseAllButton.Enable     = 'on';
                    app.CloseInterior.Enable      = 'on';
                case {5, 6}
                    % Seed Region or Smart cut
                    app.RadiusEditField.Enable    = true;
                    app.RadiusEditFieldLabel.Text = 'Tolerance';
                    app.RadiusEditField.Limits    = [0, 100];
                    % Percentage of the range of pixel intensity values
                    % Scaling the tolerance value back
                    if ~isempty(app.seedtol)
                        value                     = app.seedtol - app.imagerange(1);
                        scaling                   = app.imagerange(2) - app.imagerange(1);
                        value                     = value/scaling;
                        value                     = value*100;
                        app.RadiusEditField.Value = value;
                    else
                        value                     = 1;
                        app.RadiusEditField.Value = value;
                        % Initialize the seed region tolerance value
                        app.RadiusEditFieldValueChanged
                    end
                    app.CloseAllButton.Enable     = 'off';
                    app.CloseInterior.Enable      = 'off';
            end

            % Readjusts buttons if necessary
            app.AdjustButtonsForDataType

            % Ensures cursor and lines show up accurately upon changing
            % edit mode
            app.UpdateCursor
            app.ResizeLineWidth
            uiresume(app.UIFigure)
        end

        % Callback function: Colormap
        function ColormapButtonPushed(app, event)
            % Will allow user to select a colormap and change the plot
            % color
            app.ColormapSelector;
            if ~isempty(app.Parent)
                figure(app.Parent)
            end
            figure(app.UIFigure)
            app.UpdateCursorColor
        end

        % Callback function: MaskColor
        function MaskColorButtonPushed(app, event)
            % Constructs a two color colormap for the mask
            c         = uisetcolor([1 1 1], 'Mask color');
            if ~isempty(app.Parent)
                figure(app.Parent)
            end
            figure(app.UIFigure)
            focus(app.UIFigure)
            app.plotcolor = [0 0 0 ; c];
            colormap(app.ax2, app.plotcolor)
            app.UpdateCursorColor
        end

        % Value changing function: OpaquenessSlider
        function OpaquenessSliderValueChanging(app, event)
            % Adjusts how transparent the mask is atop the image
            changingValue            = event.Value;
            app.transparency            = changingValue;
            idx                      = app.dataplotH.AlphaData(:) > 0;
            app.dataplotH.AlphaData(idx) = app.transparency;
            pause(0)
        end

        % Callback function: Reset
        function RestartButtonPushed(app, event)
            app.ResetResultsPlot
            focus(app.UIFigure)
        end

        % Callback function: ManualContrast
        function ManualContrastButtonPushed(app, event)
            % Launches manual contrast tool - differs if RGB image
            if ~app.isrgb
                app.imcontrastH = imcontrast(app.imH);
            else
                app.ManualContrastRGB
            end
        end

        % Button pushed function: AutocontrastButton
        function AutocontrastButtonPushed(app, event)
            % Takes the focus off of the button so the button doesn't
            % appear clicked
            focus(app.UIFigure)
           if ~isempty(app.imcontrastH) && isvalid(app.imcontrastH)
                app.imcontrastH.delete
            end
            app.AutoContrast 
        end

        % Callback function: Up
        function UpClicked(app, event)
            % Takes the focus off of the button so the button doesn't
            % appear clicked
            focus(app.UIFigure)
            % Will pan up
            KeyData.Modifier = 'shift';
            KeyData.Key      = 's';
            app.PanAxes([], KeyData)
        end

        % Callback function: Down
        function DownClicked(app, event)
            % Takes the focus off of the button so the button doesn't
            % appear clicked
            focus(app.UIFigure)
            % Will pan down
            KeyData.Modifier = 'shift';
            KeyData.Key      = 'w';
            app.PanAxes([], KeyData)
        end

        % Callback function: Left
        function LeftClicked(app, event)
            % Takes the focus off of the button so the button doesn't
            % appear clicked
            focus(app.UIFigure)
            % Will pan left
            KeyData.Modifier = 'shift';
            KeyData.Key      = 'a';
            app.PanAxes([], KeyData)
        end

        % Callback function: Right
        function RightClicked(app, event)
            % Takes the focus off of the button so the button doesn't
            % appear clicked
            focus(app.UIFigure)
            % Will pan right
            KeyData.Modifier = 'shift';
            KeyData.Key      = 'd';
            app.PanAxes([], KeyData)
        end

        % Callback function: ResetView
        function ResetViewClicked(app, event)
            % Takes the focus off of the button so the button doesn't
            % appear clicked
            focus(app.UIFigure)
            % Recenter the image and resets the zoom
            app.UIAxes.XLim = [1 app.ncols];
            app.UIAxes.YLim = [1 app.mrows];
%             drawnow
            pause(0.001)
        end

        % Callback function: Pan
        function PanClicked(app, event)
            % Takes the focus off of the button so the button doesn't
            % appear clicked
            focus(app.UIFigure)
            % Zoom turned off so pan mode can be used
            app.TurnOffZoom
            if app.Pan.State
                % Ensures editing is off while navigating image
                app.PauseEditing
                pan(app.UIAxes, 'on');
%                 drawnow
                pause(0.001)
            else
                % Go back to triggering the ROI drawing if in that mode
                uiresume(app.UIFigure)
                pan(app.UIAxes, 'off');
                % Forces stack to clear so the pointer can return to normal
%                 drawnow
                pause(0.001)
                app.UIFigure.Pointer = 'arrow';
            end
        end

        % Callback function: ZoomIn
        function ZoomInClicked(app, event)
            % Takes the focus off of the button so the button doesn't
            % appear clicked
            focus(app.UIFigure)
            if app.Pan.State
                pan(app.UIAxes, 'off');
                app.Pan.State = 'off';
            end
            if app.ZoomIn.State
                app.PauseEditing
                app.ZoomOut.State = 'off';
                if isempty(app.zoomObj)
                    app.zoomObj = zoom(app.UIFigure);
                end
                app.zoomObj.Enable    = 'on';
                app.zoomObj.Direction = 'in';
            else
                app.zoomObj.Enable    = 'off';
            end
        end

        % Callback function: ZoomOut
        function ZoomOutClicked(app, event)
            % Takes the focus off of the button so the button doesn't
            % appear clicked
            focus(app.UIFigure)
            if app.Pan.State
                pan(app.UIAxes, 'off');
                app.Pan.State = 'off';
            end
            if app.ZoomOut.State
                app.PauseEditing
                app.ZoomIn.State = 'off';
                if isempty(app.zoomObj)
                    app.zoomObj = zoom(app.UIFigure);
                end
                app.zoomObj.Enable    = 'on';
                app.zoomObj.Direction = 'out';
            else
                app.zoomObj.Enable    = 'off';
            end
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            if ~isempty(event.Modifier)
                Modifier = event.Modifier;
                Modifier = Modifier{:};
                % A modifier is needed for the axes pan shortcut
                switch Modifier
                    case 'shift'
                        app.PanAxes([], event)
                    case 'control'
                        switch key
                            case 'z'
                                app.UndoOrRedo(true)
                            case 'y'
                                app.UndoOrRedo(false)
                        end
                end
            else
                % The +/- buttons will allow the user to zoom into
                % wherever their mouse is currently
                switch key
                    case 'subtract'
                        scroll.VerticalScrollCount  = 1;
                        scroll.VerticalScrollAmount = 5;
                        scroll.Button               = true;
                        app.AdjustZoom([], scroll)
                    case 'add'
                        scroll.VerticalScrollCount  = -1;
                        scroll.VerticalScrollAmount = 5;
                        scroll.Button               = true;
                        app.AdjustZoom([], scroll)
                end
            end
        end

        % Button pushed function: CloseInterior
        function CloseInteriorButtonPushed(app, event)
            % Takes the focus off of the button so the button doesn't
            % appear clicked
            focus(app.UIFigure)
            if app.removingdata
                return
            end
            app.ClosePaintInterior(false)
        end

        % Button pushed function: CloseAllButton
        function CloseAllButtonPushed(app, event)
            % Takes the focus off of the button so the button doesn't
            % appear clicked
            focus(app.UIFigure)
            app.ClosePaintInterior(true)
        end

        % Value changed function: AutoCMethods
        function AutoCMethodsValueChanged(app, event)
            % Takes the focus off of the button so the button doesn't
            % appear clicked
            focus(app.UIFigure)
            % User may change the way auto contrasted image is computed
            value = app.AutoCMethods.Value;
            switch value
                case 'Match Intensity Range'
                    app.autocontrastmethod = 1;
                case 'Match Median Filtered Image Intensity Range'
                    app.autocontrastmethod = 2;
                case '1% Quartiles'
                    app.autocontrastmethod = 3;
            end
            app.AutoContrast
        end

        % Button pushed function: MaskAllButton
        function MaskAllButtonPushed(app, event)
            % Takes the focus off of the button so the button doesn't
            % appear clicked
            focus(app.UIFigure)
            app.MaskAll
        end

        % Button pushed function: InvertMaskButton
        function InvertMaskButtonPushed(app, event)
            % Takes the focus off of the button so the button doesn't
            % appear clicked
            focus(app.UIFigure)
            app.InvertMask
        end

        % Button pushed function: Unmask
        function UnmaskButtonPushed(app, event)
            % Takes the focus off of the button so the button doesn't
            % appear clicked
            focus(app.UIFigure)
            app.UpdateUndoStack
            app.dataplotH.AlphaData = zeros(app.mrows, app.ncols, 'double');
        end

        % Callback function: Help
        function HelpClicked(app, event)
            % Takes the focus off of the button so the button doesn't
            % appear clicked
            focus(app.UIFigure)
            app.HelpInstruction
        end

        % Callback function: Finished
        function FinishedButtonPushed(app, event)
            % Updates the parent GUI's property and then closes
            app.appendordelete  = [];
            if app.ispointsdata
                % In the case of points

                % Points
                P = app.Currentpoints;

                % Deleted points
                if ~isempty(app.dataplotH{3})
                    x_del = app.dataplotH{3}.XData;
                    y_del = app.dataplotH{3}.YData;
                    P_del = [x_del' y_del'];

                    % Only returns the points
                    idx = ~ismember(P, P_del, "rows");
                    r   = find(idx);
                    P   = P(r, :);
                end

                % Pass data to edited results property
                app.Editedresults = P;

            else
                app.Editedresults   = app.dataplotH.AlphaData>0;

            end

            % Passes the edited results into the app data of the main GUI
            if ~isempty(app.Parent)
                setappdata(app.Parent, 'Editedresults', app.Editedresults)
            end
            app.delete
        end

        % Callback function: Cancel, UIFigure
        function UIFigureCloseRequest(app, event)
            % Will immediately close window if done button was clicked but
            % will ask user if they would like to save mask if close button
            % pressed

            % If closed via the window's close button rather than the
            % close button in the GUI
            h = gcbo;
            if ~isa(h, 'matlab.ui.control.Button')
                qans = questdlg('Keep mask changes?', 'Mask Update', 'Yes', 'No', 'Cancel', 'Yes');
                if strcmp(qans, 'Yes')
                    app.FinishedButtonPushed
                elseif strcmp(qans, 'No')
                    if ~isempty(app.Parent)
                        setappdata(app.Parent, 'Editedresults', NaN)
                    end
                    delete(app)
                else
                    % Figure remains
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Data Editing Tool';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.Toolbar = uitoolbar(app.UIFigure);
            app.ManualContrast = uipushtool(app.Toolbar);
            app.ManualContrast.Tooltip = {'Manual Contrast'};
            app.ManualContrast.ClickedCallback = createCallbackFcn(app, @ManualContrastButtonPushed, true);
            app.Up = uipushtool(app.Toolbar);
            app.Up.Tooltip = {'Pan Up'};
            app.Up.ClickedCallback = createCallbackFcn(app, @UpClicked, true);
            app.Down = uipushtool(app.Toolbar);
            app.Down.Tooltip = {'Pan Down'};
            app.Down.ClickedCallback = createCallbackFcn(app, @DownClicked, true);
            app.Left = uipushtool(app.Toolbar);
            app.Left.Tooltip = {'Pan Left'};
            app.Left.ClickedCallback = createCallbackFcn(app, @LeftClicked, true);
            app.Right = uipushtool(app.Toolbar);
            app.Right.Tooltip = {'Pan Right'};
            app.Right.ClickedCallback = createCallbackFcn(app, @RightClicked, true);
            app.ResetView = uipushtool(app.Toolbar);
            app.ResetView.Tooltip = {'Maximize View'};
            app.ResetView.ClickedCallback = createCallbackFcn(app, @ResetViewClicked, true);
            app.Pan = uitoggletool(app.Toolbar);
            app.Pan.Tooltip = {'Pan Mode'};
            app.Pan.ClickedCallback = createCallbackFcn(app, @PanClicked, true);
            app.ZoomIn = uitoggletool(app.Toolbar);
            app.ZoomIn.Tooltip = {'Zoom In'};
            app.ZoomIn.ClickedCallback = createCallbackFcn(app, @ZoomInClicked, true);
            app.ZoomOut = uitoggletool(app.Toolbar);
            app.ZoomOut.Tooltip = {'Zoom Out'};
            app.ZoomOut.ClickedCallback = createCallbackFcn(app, @ZoomOutClicked, true);
            app.Reset = uipushtool(app.Toolbar);
            app.Reset.Tooltip = {'Reset Mask'};
            app.Reset.ClickedCallback = createCallbackFcn(app, @RestartButtonPushed, true);
            app.Colormap = uipushtool(app.Toolbar);
            app.Colormap.Tooltip = {'Colormap'};
            app.Colormap.ClickedCallback = createCallbackFcn(app, @ColormapButtonPushed, true);
            app.MaskColor = uipushtool(app.Toolbar);
            app.MaskColor.Tooltip = {'Mask Color'};
            app.MaskColor.ClickedCallback = createCallbackFcn(app, @MaskColorButtonPushed, true);
            app.Help = uipushtool(app.Toolbar);
            app.Help.Tooltip = {'Help'};
            app.Help.ClickedCallback = createCallbackFcn(app, @HelpClicked, true);
            app.Finished = uipushtool(app.Toolbar);
            app.Finished.Tooltip = {'Finished'};
            app.Finished.ClickedCallback = createCallbackFcn(app, @FinishedButtonPushed, true);
            app.ToolbarSpacer = uipushtool(app.Toolbar);
            app.ToolbarSpacer.Enable = 'off';
            app.Cancel = uipushtool(app.Toolbar);
            app.Cancel.Tooltip = {'Clear Mask'};
            app.Cancel.ClickedCallback = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Panel = uipanel(app.UIFigure);
            app.UIAxes = uiaxes(app.Panel);
            app.UIAxes.ALim = [0 1];
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.Visible = 'off';
            app.Panel_2 = uipanel(app.UIFigure);
            app.AutocontrastButton = uibutton(app.Panel_2, 'push');
            app.AutocontrastButton.ButtonPushedFcn = createCallbackFcn(app, @AutocontrastButtonPushed, true);
            app.AutocontrastButton.Text = 'Autocontrast';
            app.ModeDropDownLabel = uilabel(app.Panel_2);
            app.ModeDropDownLabel.Text = 'Mode';
            app.ModeDropDown = uidropdown(app.Panel_2);
            app.ModeDropDown.Items = {'Touch Up', 'Point', 'Bounding Curves', 'Turbo Paint Brush', 'Seed Region', 'Edge Based Cut'};
            app.ModeDropDown.ValueChangedFcn = createCallbackFcn(app, @ModeDropDownValueChanged, true);
            app.ModeDropDown.Value = 'Turbo Paint Brush';
            app.RadiusEditFieldLabel = uilabel(app.Panel_2);
            app.RadiusEditFieldLabel.Text = 'Radius';
            app.RadiusEditField = uieditfield(app.Panel_2, 'numeric');
            app.RadiusEditField.Limits = [0 256];
            app.RadiusEditField.ValueChangedFcn = createCallbackFcn(app, @RadiusEditFieldValueChanged, true);
            app.RadiusEditField.Value = 3;
            app.OpaquenessSliderLabel = uilabel(app.Panel_2);
            app.OpaquenessSliderLabel.Text = 'Opaqueness';
            app.OpaquenessSlider = uislider(app.Panel_2);
            app.OpaquenessSlider.Limits = [2.22044604925031e-16 1];
            app.OpaquenessSlider.MajorTicks = [];
            app.OpaquenessSlider.ValueChangingFcn = createCallbackFcn(app, @OpaquenessSliderValueChanging, true);
            app.OpaquenessSlider.MinorTicks = [];
            app.OpaquenessSlider.Value = 0.4;
            app.CloseAllButton = uibutton(app.Panel_2, 'push');
            app.CloseAllButton.ButtonPushedFcn = createCallbackFcn(app, @CloseAllButtonPushed, true);
            app.CloseAllButton.Text = 'Close All';
            app.AutoCMethods = uidropdown(app.Panel_2);
            app.AutoCMethods.Items = {'Match Intensity Range', 'Match Median Filtered Image Intensity Range', '1% Quantiles'};
            app.AutoCMethods.ValueChangedFcn = createCallbackFcn(app, @AutoCMethodsValueChanged, true);
            app.AutoCMethods.Value = 'Match Intensity Range';
            app.CloseInterior = uibutton(app.Panel_2, 'push');
            app.CloseInterior.ButtonPushedFcn = createCallbackFcn(app, @CloseInteriorButtonPushed, true);
            app.CloseInterior.Text = 'Close Interior';
            app.InvertMaskButton = uibutton(app.Panel_2, 'push');
            app.InvertMaskButton.ButtonPushedFcn = createCallbackFcn(app, @InvertMaskButtonPushed, true);
            app.InvertMaskButton.Text = 'Invert Mask';
            app.MaskAllButton = uibutton(app.Panel_2, 'push');
            app.MaskAllButton.ButtonPushedFcn = createCallbackFcn(app, @MaskAllButtonPushed, true);
            app.MaskAllButton.Text = 'Mask All';
            app.Unmask = uibutton(app.Panel_2, 'push');
            app.Unmask.ButtonPushedFcn = createCallbackFcn(app, @UnmaskButtonPushed, true);
            app.Unmask.Text = 'Unmask';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EditingTool(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end