classdef MaskingTool < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        Toolbar                matlab.ui.container.Toolbar
        ManualContrast         matlab.ui.container.toolbar.PushTool
        Up                     matlab.ui.container.toolbar.PushTool
        Down                   matlab.ui.container.toolbar.PushTool
        Left                   matlab.ui.container.toolbar.PushTool
        Right                  matlab.ui.container.toolbar.PushTool
        ResetView              matlab.ui.container.toolbar.PushTool
        Pan                    matlab.ui.container.toolbar.ToggleTool
        ZoomIn                 matlab.ui.container.toolbar.ToggleTool
        ZoomOut                matlab.ui.container.toolbar.ToggleTool
        Reset                  matlab.ui.container.toolbar.PushTool
        Colormap               matlab.ui.container.toolbar.PushTool
        MaskColor              matlab.ui.container.toolbar.PushTool
        Help                   matlab.ui.container.toolbar.PushTool
        Finished               matlab.ui.container.toolbar.PushTool
        ToolbarSpacer          matlab.ui.container.toolbar.PushTool
        Cancel                 matlab.ui.container.toolbar.PushTool
        Panel_2                matlab.ui.container.Panel
        Unmask                 matlab.ui.control.Button
        MaskAllButton          matlab.ui.control.Button
        InvertMaskButton       matlab.ui.control.Button
        CloseInterior          matlab.ui.control.Button
        AutoCMethods           matlab.ui.control.DropDown
        CloseAllButton         matlab.ui.control.Button
        OpaquenessSlider       matlab.ui.control.Slider
        OpaquenessSliderLabel  matlab.ui.control.Label
        RadiusEditField        matlab.ui.control.NumericEditField
        RadiusEditFieldLabel   matlab.ui.control.Label
        ModeDropDown           matlab.ui.control.DropDown
        ModeDropDownLabel      matlab.ui.control.Label
        AutocontrastButton     matlab.ui.control.Button
        Panel                  matlab.ui.container.Panel
        UIAxes                 matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        % Figure related
        monitorsize                       % Size of monitor to ensure the window never goes off screen
        oldposition                       % Holds the previous window width and height after a change is made - allows a query to then trigger a line width update on the pain brush
        Parent                            % Figure / app calling the masking tool
        initialguisize                    % Initial size of the GUI - prevents downsizing too much
        Helpwindow

        % Image related
        isrgb                             % Flag for whether or not the image is RGB
        rgbclims                          % Bounds for contrasting that change appearance of RGB image
        Imedges                           % Image with edges enhanced for the smart cut mode
        ax2                               % Second axes for plotting mask
        Image                             % Underlying image data
        Mask1                             % Original mask held in case user wants to restart
        Mask2                             % Holds the mask for the image. Only updates once done and can be passed to external app / workspace using the getmask method
        mrows                             % Number of rows in the image (y dim)
        ncols                             % Number of cols in the image (x dim)
        imagerange                        % Upper and lower limits of the image's pixel intensity values
        newcc                             % New connected component to be appended to the mask
        brushpolyline                     % The poly line object corresponding to the latest mask allows us to close the loop if left open prior to interior closing
        Undostack                         % Holds previous results/edits in case user makes a mistake

        % Plotting related
        clickisdown        = false        % True when mouse clicked down - flag for updating mask in brush mode
        imH                               % Image graphics object handle for the image in the axes
        maskH                             % Image graphics object handle for the mask
        transparency       = 0.4          % Transparency value for the mask plotted atop the image in the axes
        imcontrastH                       % Manual contrast tool handle
        autocontrastmethod = 1            % Auto contrast Method index

        % Editing Mode for either points or masks

        % Mask Editing
        editmodes     = {'Touch Up',...
                          'Point',...
                          'Bounding Curves',...
                          'Turbo Paint Brush',...
                          'Seed Region',...
                          'Edge Based Cut'} % Modes that a user can select to use and switch to
        editmode       = 4                  % Default editing mode, determines how user edits the mask
        appendordelete                      % if 1, appending to mask, if -1, deleting region from mask
        seedtol                             % Tolerance of intensity values for the seed region mode. Relative to the pixel intensity range of an image. Expressed as a percent
        brushradius    = 3                  % Size in pixels of the masking brush - paint brush method
        brushroi                            % A circular ROI object to show region to be masked- for pain brush mode
        cursorcolor    = [1 1 1]            % Color of the circular ROI for cursor - for paint brush mode

        % Interactivity
        Mouseposition                     % Mouse cursor position in the axes
        editingObj                        % Animated line object representing the painted region when in Turbo Paint brush mode
        zoomObj                           % Zoom Mode object used to toggle legacy zoom functionality on/off
        brushlinewidth = 1                % Shrink factor for the alpha shapes computation 
        Points                            % Points computed from the alpha shapes - bounding curve method
        lassoroiH                         % roi object handle to be drawn in the axes - bounding curve method
        Brushcap                          % Graphics object drawn upon clicking when in turbo mode for accurate representation of masked region
        cmap                              % Colormap used to display image and overlayed mask
        cmask                             % Colormap for the mask
        CLims                             % Color limits for the image - i.e. range of intensity values for contrast adjustment
        isoveraxes     = false            % A change in this flag will trigger changes to the pointer. Flag prevents unnecessary changes when condition is static
    end


    methods (Access = public)
        function PlotImage(app, I)
            % PLOTIMAGE will plot the image passed to the app. It will
            % determine how the image is to be displayed and how contrast
            % will be set depending on whether the image is RGB or
            % grayscale
            if ~isnumeric(I) && ~islogical(I)
                I = app.ImageFileLoad(I);
            end
            if isempty(app.cmap)
                app.cmap   = bone();
                app.cmask  = [0 0 0 ; 1 1 1];
            end
            % Image size and pixel intensity range information
            [app.mrows, app.ncols] = size(I, [1 2]);
            app.Image      = I;
            [mn, mx]       = bounds(I, 'all');
            app.imagerange   = double([mn mx]);

            % Plotting and determination of colormap need
            app.imH     = imagesc(app.UIAxes, I);
            if size(I, 3) == 3
                % Turns off colormap functionality for RGB images
                app.isrgb    = true;
                app.Colormap.Enable = 'off';
            elseif size(I, 3) == 1
                % Grayscle image
                app.isrgb    = false;
            end
            if ischar(app.cmap)
                app.cmap   = eval(app.cmap);
            end
            
            % Ensures axes proportions and pixel aspect ratio is accurate
            axis(app.UIAxes, 'equal')
            app.UIAxes.Visible = 'off';

            % Will set colormap and color limits if the image is not RGB
            if ~app.isrgb
                colormap(app.UIAxes, app.cmap)
                if ~isempty(app.CLims)
                    app.UIAxes.CLim = app.CLims;
                end
            end
            % Initializing settings for cursor ROI since dominant color in
            % image will determine the cursor circle color
            app.UpdateCursorColor
            % Line width for animated line depends on the ratio of image
            % pixels to screen pixels
            app.UpdateLineWidth
        end


        function PlotMask(app, initialMask)
            % PLOTMASK will plot a ones array on a second axes object and
            % then set the alpha values in the region covered by the
            % initial mask. This reduces graphical objects/properties to be
            % set/changed
            if nargin < 2 || isempty(initialMask)
                initialMask = zeros([app.mrows app.ncols], 'logical');
            end
            % Init mask as all true and its alpha data as all false
            app.Mask2      = true(app.mrows, app.ncols);
            app.Mask1    = initialMask;
            FirstMask     = double(app.Mask1).*app.transparency;
            app.ax2       = uiaxes(app.Panel);
            linkprop([app.UIAxes, app.ax2], {'Position', 'XLim', 'YLim', 'XTick', 'YTick', 'XColor', 'YColor'});
            linkaxes([app.UIAxes, app.ax2], 'xy')
            
            app.maskH     = imagesc(app.ax2, app.Mask2, 'AlphaData', FirstMask, 'ButtonDownFcn', @app.AdjustOn);
            axis(app.ax2, 'equal')
            colormap(app.ax2, app.cmask)


            % THIS WAS INITIALLY BELOW the linkaxes line
            % Setting zoom clip limits off to allow accurate scroll zoom
            % functionality
            set(app.ax2, 'Clipping', false)
            app.ax2.Toolbar.Visible    = 'off';
            app.ax2.Visible            = 'off';
            app.UIAxes.Toolbar.Visible = 'off';
            app.UIAxes.Visible         = 'off';
            % Deletes toolbars to prevent slowdown from useless callbacks
            app.ax2.Toolbar            = [];
            app.UIAxes.Toolbar         = [];
        end


        function PauseMasking(app)
            uiresume(app.UIFigure)
            app.appendordelete   = [];
            app.clickisdown = false;
            app.ClearROI
        end


        function ManualContrastRGB(app)
            % MANUALCONTRASTRGB launches an input dialog box for the user
            % to set the bottom and upper limits manually for their RGB
            % image - since imcontrast does not work with RGB
            prompt   = {'Min (0-255)', 'Max (0 - 255)'};
            dlgtitle = 'RGB Image Manual Contrast';
            dims     = [1 30];
            if ~isempty(app.rgbclims)
                definput = {num2str(app.rgbclims(1)), num2str(app.rgbclims(2))};
            else
                definput = {'0', '255'};
            end

            % Input dialog prompts user to define bounds
            answer   = myInputDlg(prompt, dlgtitle, dims, definput);
            focus(app.UIFigure)
            if isempty(answer)
                return
            end
            
            if ~isempty(answer)
                lb        = str2double(answer{1});
                ub        = str2double(answer{2});
                % Bound check the lower bound
                lb        = max(0, lb);
                lb        = min(lb, 254);
                % Bound check the upper bound
                ub        = min(255, ub);
                ub        = max(ub, lb+1);
                app.imH.CData = (app.Image-lb)*(255/(ub-lb));
            end

            % Saving the contrast bounds
            app.rgbclims = [lb ub];
        end


        function ResetMask(app)
            % RESETMASK will allow user to restart from initial mask
            % Saves previous mask before the resetting of the mask is
            % actually triggered
            app.UpdateUndoStack
            if any(app.Mask1(:))
                app.maskH.AlphaData(app.Mask1)  = app.transparency;
                app.maskH.AlphaData(~app.Mask1) = 0;
            else
                app.maskH.AlphaData = zeros(app.mrows, app.ncols, 'double');
            end
        end


        function ChangeMode(app, modename)
            % CHANGEMODE allows user to toggle between different editing
            % modes
            if any(contains(app.editmodes, modename))
                % Sets the edit mode to index value of selected method 
                switch modename
                    case app.editmodes{1}
                        % Touch Up mode
                        app.editmode             = 1;
                    case app.editmodes{2}
                        % Point mode
                        app.editmode             = 2;
                    case app.editmodes{3}
                        % Bounding curve mode
                        app.editmode             = 3;
                    case app.editmodes{4}
                        % Turbo Paint Brush mode
                        app.editmode             = 4;
                    case app.editmodes{5}
                        % Seed grow region mode
                        app.editmode             = 5;
                    case app.editmodes{6}
                        app.editmode             = 6;
                end
            else
                % Displays list of valid mode names
                msg = ['Editing mode must be one of the following types: ' newline];
                vm  = cellfun(@(x) [x newline], app.editmodes, 'UniformOutput', false);
                msg = [msg vm{:}];
                error(msg)
            end
        end


        function DrawLasso(app)
            if ~isempty(app.appendordelete)
                % Start from 0 and go 1 beyond the boundary to allow pixels
                % at boundary to be included in mask
                MP_x = min(max(app.Mouseposition(2), 0), app.ncols+1);
                MP_y = min(max(app.Mouseposition(1), 0), app.mrows+1);
                if isempty(app.editingObj)
                    % Changes color to be more visible when removing mask
                    % portion
                    switch app.appendordelete
                        case 1
                            LassoColor         = [app.cmask(2,:) app.transparency];
                        case -1
                            LassoColor         = [(1-app.cmask(2,:)) app.transparency];
                    end
                    % Mouse pointer will be crosshairs
                    % Simple line will act as lasso
                    app.editingObj       = line(app.ax2, 'XData', MP_x, 'YData', MP_y,...
                                              'Color', LassoColor,...
                                              'AlignVertexCenters','on', ...
                                              'LineWidth', 1.5, ...
                                              'LineStyle', '--');
                    app.editingObj.XData = [app.editingObj.XData  app.editingObj.XData];
                    app.editingObj.YData = [app.editingObj.YData  app.editingObj.YData];
                else
                    % Will always have the first data point swapped to the
                    % end to give a lasso appearance
                    app.editingObj.XData = [app.editingObj.XData(1:end-1) MP_x app.editingObj.XData(1)];
                    app.editingObj.YData = [app.editingObj.YData(1:end-1) MP_y app.editingObj.YData(1)];
                end
            end
        end


        function TurboPaint(app)
            % TURBOPAINT allows user to draw animated lines to mask regions
            if ~isempty(app.appendordelete)
                MP_x = min(max(app.Mouseposition(2), 1), app.ncols);
                MP_y = min(max(app.Mouseposition(1), 1), app.mrows);
                if isempty(app.editingObj)
                    % Changes color to be more visible when removing mask
                    % portion
                    switch app.appendordelete
                        case 1
                            PaintBrushColor = app.cmask(2,:);
                        case -1
                            PaintBrushColor = (1-app.cmask(2,:));
                    end
                    
                    % Brush cap is at the start of the painted region
                    app.Brushcap = drawcircle(app.ax2,...
                                          'Center', [MP_x MP_y],...
                                          'Radius', app.brushradius,...
                                          'InteractionsAllowed','none',...
                                          'LineWidth', 1,...
                                          'FaceAlpha', 1,...
                                          'Color', PaintBrushColor);
                    % Animated line w/ scaled width and same color as mask
                    app.editingObj = animatedline(app.ax2, MP_x, MP_y,...
                                   'LineWidth', app.brushlinewidth,...
                                   'Color', PaintBrushColor,...
                                   'AlignVertexCenters','on' ,...
                                   'Marker', '.',...
                                   'MarkerSize', app.brushlinewidth*2.925);
                    addpoints(app.editingObj, MP_x, MP_y)
                else
                    addpoints(app.editingObj, MP_x, MP_y)
                end
            end
        end


        function Curve2Mask(app)
            % CURVE2MASK will convert the points from an animated line to a
            % masked region based on the brush radius
            % Getting points from animated line
            try
                [x, y]    = getpoints(app.editingObj);
                if ~isempty(app.brushpolyline) && isa(app.brushpolyline, 'images.roi.Polyline')
                    % Clears previous poly line object to prevent flooding
                    % the axes with too many roi objects
                    delete(app.brushpolyline)
                end

                % Determines if user wants interior closed as well or not
                app.brushpolyline  = images.roi.Polyline(app.ax2, 'Position', [x', y'], 'Visible','off');
                Temp_mask = createMask(app.brushpolyline, app.mrows, app.ncols);

                % Thresholding distance transform - isotropic dilation
                isomask = bwdist(Temp_mask, 'euclidean')<=app.brushradius;
                % Dilates mask used as logical indices to adjust alpha
                if app.appendordelete == 1
                    app.maskH.AlphaData(isomask) = app.transparency;
                    % Latest mask is the last masked region which can be
                    % closed individually 
                    app.newcc           = isomask;
                elseif app.appendordelete == -1
                    app.maskH.AlphaData(isomask) = 0;
                end
            catch
            end
        end


        function PaintPoint(app)
            % Paints in a single pixel given the mouse position
            p       = app.Mouseposition;
            p       = round(p);
            y       = p(1);
            x       = p(2);
            check_x = (1 <= x) && (x<=app.ncols);
            check_y = (1 <= y) && (y<=app.mrows);
            if check_x && check_y
                if app.appendordelete == 1
                    app.maskH.AlphaData(y,x) = app.transparency;
                elseif app.appendordelete == -1
                    app.maskH.AlphaData(y,x) = 0;
                end
            end
        end


        function MaskRegion(app)
            % Mouse coordinates
            p   = round(app.Mouseposition);
            ydx = p(1);
            xdx = p(2);

            % Masking disk
            s = (-app.brushradius:app.brushradius).^2;
            h = (s+s')<=(app.brushradius^2);

            % if y is within r of boundary, zero top margin up to...
            dy = (app.brushradius+1)-ydx;
            if dy
                h(1:dy,:)=0;
            end
            % likewise for bottom margin
            dy = ydx-app.mrows+app.brushradius;
            if dy
                h(end-dy+1:end,:)= 0;
            end
            %..for left margin
            dx = (app.brushradius+1)-xdx;
            if dx
                h(:,1:dx)=0;
            end
            % ..for right margin
            dx = xdx-app.ncols+app.brushradius;
            if dx
                h(:,end-dx+1:end)= 0;
            end
            % get what indices are in bounds
            [rdx,cdx] = find(h);
            % get linear indices
            idx = (rdx+(ydx-app.brushradius-1)) + (cdx+(xdx-app.brushradius-2))*app.mrows;

            if app.appendordelete == 1
                % Adds to the mask
                app.maskH.AlphaData(idx) = app.transparency;
            elseif app.appendordelete == -1
                % Subtracts from the mask
                app.maskH.AlphaData(idx) = 0;
            end
        end


        function TouchUpMask(app)
            % TOUCHUPMASK will set pixel values around a point according to
            % the set radius
            try
                p = round(app.Mouseposition);
                y = p(1);
                x = p(2);

                % Single point altered at a time
                Temp_mask       = false(app.mrows, app.ncols);
                Temp_mask(y, x) = true;
                % Isotropic dilation
                Msk             = bwdist(Temp_mask)<=app.brushradius;
                if app.appendordelete == 1
                    app.maskH.AlphaData(Msk) = app.transparency;
                elseif app.appendordelete == -1
                    app.maskH.AlphaData(Msk) = 0;
                end
%                 % Bound checking for approach
%                 if (x >= app.BrushR) && (y >= app.BrushR) && (x <= (app.N - app.BrushR)) && (y <= (app.M-app.BrushR))
%                     s          = (-app.BrushR:app.BrushR).^2;
%                     [rdx, cdx] = find((s+s')<=app.BrushR^2);
%                     idx        = rdx + (cdx+(x-app.BrushR-2))*app.M + (y-app.BrushR-1);    
%                     if app.SetMode == 1
%                         app.MaskH.AlphaData(idx) = app.MaskAlpha;
%                     elseif app.SetMode == -1
%                         app.MaskH.AlphaData(idx) = 0;
%                     end
%                 else
%                     % Used when painting at the boundary - slower but
%                     % capable of painting at boundary
%                     app.MaskRegion
%                 end
            catch
            end
        end


        function Points2Mask(app)
            % POINTS2MASK will convert the bounding curve region into a
            % mask and update the alpha data to show what's been masked
            if ~isempty(app.editingObj)
                P        = [app.editingObj.XData', app.editingObj.YData'];
                app.lassoroiH = images.roi.Freehand(app.ax2, 'Position', P, 'Visible', 'off');
                Msk      = createMask(app.lassoroiH, app.mrows, app.ncols);
                if app.appendordelete == 1
                    app.maskH.AlphaData(Msk) = app.transparency;
                elseif app.appendordelete == -1
                    app.maskH.AlphaData(Msk) = 0;
                end
                app.editingObj.delete
                app.editingObj = [];
            end
            app.ClearROI
        end


        function SeedGrow(app)
            % SEEDGROW will use a flood fill technique on a contiguous
            % image region with similar values based on a user specified
            % tolerance
            P   = round(app.Mouseposition);
            y   = P(1);
            x   = P(2);
            tol = app.seedtol;
            if app.isrgb
                % When RGB, need to consider similar pixels per channel,
                % then look at the intersect of all three - suggests
                % similarly colored pixels with similar luma
                I1 = app.Image(:,:,1);
                I2 = app.Image(:,:,2);
                I3 = app.Image(:,:,3);

                % If compute time for a single channel is over a second, a
                % progress bar is launched to prevent user queues
                t  = tic;
                m1 = grayconnected(I1, y, x, tol);
                t  = toc(t);
                if t > 1
                    wbf = true;
                    wb = uiprogressdlg(app.UIFigure, 'Indeterminate','on');
                else
                    wbf = false;
                end
                m2 = grayconnected(I2, y, x, tol);
                m3 = grayconnected(I3, y, x, tol);
                % Finding pixels that were related in all three channels
                % suggests a region of similar color and luma
                m  = m1 & m2 & m3;
                % Will close the progress bar
                if wbf
                    wb.delete
                end
            else
                m  = grayconnected(app.Image, y, x, tol);
            end
            % Filling any holes and smoothing mask
            m   = imfill(m, 'holes');
            m   = imclose(m, [0 1 0; 1 1 1; 0 1 0]);
            if app.appendordelete == 1
                app.maskH.AlphaData(m) = app.transparency;
            elseif app.appendordelete == -1
                app.maskH.AlphaData(m) = 0;
            end
        end


        function ComputeEdges(app)
            % COMPUTEEDGES computes the edge enhanced image that results
            % from convolving with the sobel operator. This is done per
            % channel so that transitions across colors can be seen if the
            % image is RGB.
            I = app.Image;
            I  = double(I);
            if app.isrgb
                for i = 1:3
                    I(:,:,i)  = medfilt2(I(:,:,i));
                end
            else
                I = medfilt2(I);
            end
            I  = imgaussfilt(I, 1);
            hy = [1 0 -1; 2 0 -2; 1 0 -1];
            hx = hy';
            Gy = imfilter(I, hy);
            Gx = imfilter(I, hx);
            G  = sqrt(Gx.^2 + Gy.^2);
            G  = round(G);
            app.Imedges = round(G);
        end


        function EdgeBasedCut(app)
            % SMARTCUT will use a flood fill technique on a contiguous
            % image region with similar edges
            P   = round(app.Mouseposition);
            y   = P(1);
            x   = P(2);
            tol = app.seedtol;

            % Bound checking the mouse location in the axes
            y   = max(1, y);
            y   = min(app.mrows, y);
            x   = max(1, x);
            x   = min(app.ncols, x);

            if app.isrgb
                % When RGB, need to consider similar pixels per channel,
                % then look at the intersect of all three - suggests
                % similarly colored pixels with similar luma
                I1 = app.Imedges(:,:,1);
                I2 = app.Imedges(:,:,2);
                I3 = app.Imedges(:,:,3);

                % If compute time for a single channel is over a second, a
                % progress bar is launched to prevent user queues
                t  = tic;
                m1 = grayconnected(I1, y, x, tol);
                t  = toc(t);
                if t > 0.5
                    wbf = true;
                    wb = uiprogressdlg(app.UIFigure, 'Indeterminate','on');
                else
                    wbf = false;
                end
                m2 = grayconnected(I2, y, x, tol);
                m3 = grayconnected(I3, y, x, tol);
                % Finding pixels that were related in all three channels
                % suggests a region of similar color and luma
                m  = m1 & m2 & m3;
                % Will close the progress bar
                if wbf
                    wb.delete
                end
            else
                m  = grayconnected(app.Image, y, x, tol);
            end
            m   = imfill(m, 'holes');
            m   = imclose(m, [0 1 0; 1 1 1; 0 1 0]);
            if app.appendordelete == 1
                app.maskH.AlphaData(m) = app.transparency;
            elseif app.appendordelete == -1
                app.maskH.AlphaData(m) = 0;
            end
        end


        function InvertMask(app)
            % INVERTMASK will invert the region that has been masked by the
            % user
            app.UpdateUndoStack
            Msk = app.maskH.AlphaData > 0;
            app.maskH.AlphaData(Msk) = 0;
            app.maskH.AlphaData(~Msk) = app.transparency;
        end


        function UpdateUndoStack(app)
            % UPDATEUNDOSTACK will update the mask that you can go back to
            % with the undo function
            app.Undostack(:,:,1) = app.maskH.AlphaData;
            app.UIFigure.Tag     = 'undo';
        end


        function UndoOrRedo(app, flag)
            % UNDOORREDO will either undo or redo the changes to the mask.
            % The allowable action is updated so as to prevent either the
            % undo or redo from overwriting the mask that's saved for
            % either the forward or backward step
            direction = strcmp(app.UIFigure.Tag, 'undo');
            if flag && direction
                % Switches tag to what you can do
                app.UIFigure.Tag     = 'redo';
                % Assigns the previous step image to the mask
                app.Undostack(:,:,2) = app.maskH.AlphaData;
                app.maskH.AlphaData  = app.Undostack(:,:,1);
            elseif ~flag && ~direction
                % Switches tag to what you can do
                app.UIFigure.Tag     = 'undo';
                % Assigns the forward step image to the mask
                app.Undostack(:,:,1) = app.maskH.AlphaData;
                app.maskH.AlphaData  = app.Undostack(:,:,2);
            end
        end


        function ClearROI(app)
            % CLEARROI will delete the ROI drawn in the bounding curve mode
            if ~isempty(app.lassoroiH) && isvalid(app.lassoroiH)
                app.lassoroiH.delete
            end
        end


        function InteractivityOff(app)
            % INTERACTIVITYOFF will toggle interactivity off when masking
            % to prevent errors.
            app.TurnOffPan
            app.TurnOffZoom
        end


        function TurnOffZoom(app)
            % Ensures zoom mode off
            app.ZoomOut.State = 'off';
            app.ZoomIn.State  = 'off';
            if ~isempty(app.zoomObj)
                app.zoomObj.enable = 'off';
            end
        end


        function TurnOffPan(app)
            % Ensures the panning mode is off
            if app.Pan.State
                app.Pan.State = 'off';
                app.PanClicked
            end
        end


        function AutoContrast(app)
            % AUTOCONTRAST will launch a selection dialog box for the user
            % to select their prefered autocontrast method. This method
            % will work for both RGB and grayscale images
            I  = app.Image;
            Im = double(I);
            
            if app.autocontrastmethod == 1
                if app.isrgb
                    Im = mean(Im, 3);
                end
                ordI   = sort(Im(:));
                n      = numel(Im);
                lb_idx = round(0.01*n);
                ub_idx = round(0.999*n);
                lb     = ordI(lb_idx);
                ub     = ordI(ub_idx);
            elseif app.autocontrastmethod == 2
                if app.isrgb
                    for i = 1:3
                        Im(:,:,i) = medfilt2(Im(:,:,i));
                    end
                else
                    Im        = medfilt2(Im);
                end
                [lb, ub] = bounds(Im(:));
            elseif app.autocontrastmethod == 3

                [lb, ub] = bounds(Im(:));
            end
            if app.isrgb
                app.imH.CData = (I-lb).*(255/(ub-lb));
            else
                app.UIAxes.CLim = [lb, ub];
            end
        end
    end


    methods (Access = private)
        function UpdateCursor(app)
            % UPDATECURSOR will create a circular ROI for the mouse cursor
            % when in paint brush mode if the ROI object does not yet
            % exist. Otherwise, it updates the object.
            if isempty(app.brushroi) && ~app.clickisdown
                app.brushroi = drawcircle(app.ax2,...
                                'Center', [app.Mouseposition(2) app.Mouseposition(1)],...
                                'Radius', app.brushradius,...
                                'InteractionsAllowed','none',...
                                'LineWidth', 1,...
                                'FaceAlpha', 0.1,...
                                'Color', app.cursorcolor);
            end
            % Will only trigger changes when the pointer transitions from
            % being over the axes or not
            COAFlag = app.CursorOverAxes;
            if COAFlag ~= app.isoveraxes && ~app.Pan.State && ~app.ZoomIn.State && ~app.ZoomOut.State
                app.isoveraxes = COAFlag;
                if COAFlag
                    switch app.editmode
                        case 1
                            % Turns on circular ROI and hides the cursor/pointer
                            app.UIFigure.Pointer           = 'custom';
                            app.UIFigure.PointerShapeCData = nan(16, 16);
                            app.brushroi.Visible          = 'on';
                        case {2, 5, 6}
                            % Turns off circular ROI and shows crosshair cursor
                            app.brushroi.Visible          = 'off';
                            app.UIFigure.Pointer           = 'crosshair';
                        case 3
                            % Circle ROI off and cursor automatic when drawing bounding
                            % curve
                            app.brushroi.Visible          = 'off';
                        case 4
                            % Turns on circular ROI and hides the cursor/pointer
                            app.UIFigure.Pointer           = 'custom';
                            app.UIFigure.PointerShapeCData = nan(16, 16);
                            app.brushroi.Visible          = 'on';
                    end
                else
                    % When pointer is outside axes bounds, arrow pointer appears
                    app.UIFigure.Pointer                   = 'arrow';
                    app.brushroi.Visible                  = 'off';
                end
            end

            if COAFlag
                % When over the axes and in paint mode, the circle ROI
                % position updates
                if app.editmode
                    app.brushroi.Center = [app.Mouseposition(2) app.Mouseposition(1)];
                end
            end
        end


        function UpdateCursorColor(app, focusFlag)
            % Averages across the complement of the brightest color in the
            % image color map and the complement of the color of the mask
            % Focus flag will ensure the app is in the foreground in case
            % the previous button callback triggered a dialog box
            if nargin < 2
                focusFlag = false;
            end
            if focusFlag
                figure(app.UIFigure)
            end
            if app.isrgb
                mc       = mean(app.Image, [1 2]);
                mc       = mc/255;
                mc       = mc(:);
                im_rgb   = 1-mc';
            else
                im_rgb   = app.cmap(end,:);
            end
            mask_rgb = app.cmask(2,:);
            rgb_comp = mean([im_rgb; mask_rgb], 1);
            % Will either directly update cursor color or change the
            % property prior to circle ROI creation
            if app.appendordelete == 1
                app.cursorcolor = app.cmask(2,:);
            elseif app.appendordelete == -1
                app.cursorcolor = 1-app.cmask(2,:);
            else
                if isa(app.brushroi, 'images.roi.Circle') && isvalid(app.brushroi)
                    app.brushroi.Color = rgb_comp;
                else
                    app.cursorcolor  = rgb_comp;
                end
            end
        end


        function Flag = CursorOverAxes(app)
            % Use the mouse position based on the relative units in the
            % figure window rather than the mouse position relative to the
            % axes
            % Check for non negative position values
            MousePosFig = get(app.UIFigure, 'CurrentPoint');
            y_nn        = MousePosFig(1) > app.Panel.Position(1);
            x_nn        = MousePosFig(2) > app.Panel.Position(2);
            % Check for values inside axes
            y_in        = app.Panel.Position(3) > MousePosFig(1);
            x_in        = app.Panel.Position(4) > MousePosFig(2);
            y_in        = y_in && y_nn;
            x_in        = x_in && x_nn;
            Flag        = y_in && x_in;
        end


        function AdjustOn(app, ~, event)
            % ADJUSTON will enable the mask editing and make the initial
            % mask edit when the click is first made
            switch event.Button
                % A left click will add to the mask and a right click will
                % subtract from the click - allows for fast edits
                case 1
                    app.appendordelete = 1;
                case 3
                    app.appendordelete = -1;
            end
            uiresume(app.UIFigure)
            % Gets the mask before an edit is done to allow user to undo
            % changes
            app.UpdateUndoStack
            if app.editmode == 1
                % Touches up by changing a single region
                app.clickisdown = true;
                app.TouchUpMask
            elseif app.editmode == 2
                % Masks a single point
                app.clickisdown = true;
                app.PaintPoint
            elseif app.editmode == 3
                app.clickisdown = true;
                app.DrawLasso
            elseif app.editmode == 4
                % Masks the region selected by the animated line
                app.clickisdown = true;
                if ~isempty(app.brushroi)
                    set(app.brushroi, 'FaceAlpha', 1)
                    if app.appendordelete == 1
                        set(app.brushroi, 'Color', app.cmask(2,:))
                    elseif app.appendordelete == -1
                        set(app.brushroi, 'Color', 1-app.cmask(2,:))
                    end
                end
                app.TurboPaint
            elseif app.editmode == 5
                app.clickisdown = true;
                app.SeedGrow
            elseif app.editmode == 6
                app.clickisdown = true;
                app.EdgeBasedCut
            end
        end
        

        function AdjustOff(app, ~, ~)
            % ADJUSTOFF will stop the mouse motion callback from getting
            % coordinates so no edits will take place
            if app.editmode == 1
                app.clickisdown = false;
            elseif app.editmode == 2
                app.clickisdown = false;
            elseif app.editmode == 3
                app.clickisdown = false;
                app.UpdateUndoStack
                app.Points2Mask
            elseif app.editmode == 4
                % Region is masked and animated line object deleted
                app.clickisdown = false;
                app.Curve2Mask
                if isa(app.editingObj, 'matlab.graphics.animation.AnimatedLine')
                    app.editingObj.delete
                end
                app.editingObj = [];
                delete(app.Brushcap)
                set(app.brushroi, 'FaceAlpha', 0.1)
                app.UpdateCursorColor
            end
            app.appendordelete = [];
        end


        function CheckZoom(app, ~, ~)
            % CHECKZOOM is a wrapper function that will update the paint
            % brush line width whenever the axes limits change
            app.UpdateLineWidth
        end


        function ClosePaintInterior(app, flag)
            % CLOSEPAINTINTERIOR will close the interior of the latest
            % region to have been masked

            % Flag will determine if the entire mask will have its
            % interior closed or if just the latest drawn region will have
            % interior closed
            app.UpdateUndoStack
            if flag
                m         = app.maskH.AlphaData>0;
            else
                m         = app.newcc;
            end
            Temp_mask = imfill(m, 4, "holes");
            % Comparison of mask prior to filling. If nothing changes, this
            % suggests the poly line was open but need to be closed
            comp      = Temp_mask(:) ~= m(:);
            if ~any(comp) && isa(app.brushpolyline, 'images.roi.Polyline')
                P         = app.brushpolyline.Position;
                FH        = images.roi.Freehand(app.ax2, 'Position', P, 'Visible', 'off');
                Temp_mask = createMask(FH, app.mrows, app.ncols);
                % Ensures the new mask with closed interior has proper dilation
                Temp_mask = bwdist(Temp_mask) <= app.brushradius;
            end
            app.maskH.AlphaData(Temp_mask) = app.transparency;
        end


        function MaskAll(app)
            % MASKALL will mask the entire image. Allows for a subtractive
            % approach to masking image regions
            % In case user did not mean to mask everything
            app.UpdateUndoStack
            app.maskH.AlphaData(:,:) = app.transparency;
        end


        function MouseMoving(app, ~, ~)
            % MOUSEMOVING will get the mouse position relative to the axes
            % and then update the mask if the mouse is being clicked
            app.Mouseposition = get(app.UIAxes, 'CurrentPoint');
            app.Mouseposition = [app.Mouseposition(1, 2) app.Mouseposition(1, 1)];
            
            app.UpdateCursor
            if ~isempty(app.appendordelete) || app.editmode == 4
                switch app.editmode
                    case 2
                        if app.clickisdown
                            app.ClearROI
                            app.PaintPoint
                        end
                    case 3
                        if app.clickisdown
                            % Will add point to curve of lasso
                            app.DrawLasso
                        end
                    case 4
                        if app.clickisdown
                            % Will append points to animated line
                            app.TurboPaint
                        end
                end
            end

            % Makeshift listener for the window position in case resizing
            % happens
            app.CheckWindow
        end

        function CheckWindow(app)
            % This function will first check to see if the figure window
            % has been shrunk beyond its original size to force a minimum
            % size. Then it will trigger brush line width resizing if the
            % window is resized since the plotted image will take up a
            % different number of pixels than before, requiring a rescaling
            InitDiff = app.UIFigure.Position(3:4) - app.initialguisize;
            if app.initialguisize(1) > app.UIFigure.Position(3) || app.initialguisize(2) > app.UIFigure.Position(4)
                WidthHeight = app.initialguisize;
                LeftBottom  = app.UIFigure.Position(1:2);
                Delta       = app.monitorsize - (LeftBottom + WidthHeight);
                Delta       = min(Delta.*[1 4], InitDiff);
                app.UIFigure.Position = max([(LeftBottom + Delta) (WidthHeight)], 0);
            end

            if isempty(app.oldposition)
                app.oldposition = app.UIFigure.Position(3:4);
            end
            Comparison = app.oldposition ~= app.UIFigure.Position(3:4);
            Comparison = any(Comparison);
            if Comparison
                app.UpdateLineWidth
            end
            app.oldposition = app.UIFigure.Position(3:4);
        end


        function AdjustZoom(app, ~, event)
            % ADJUSTZOOM will allow user to zoom by scrolling on mouse
            % Determining zoom direction and amount
            if app.editmode == 4 && ~isempty(app.appendordelete)
                return
            end
            scaling = max(app.mrows, app.ncols)/512;
            d       = event.VerticalScrollCount;
            amt     = event.VerticalScrollAmount;
            amt     = scaling*amt;
            % Determining where to zoom
            xL      = app.ax2.XLim;
            yL      = app.ax2.YLim;
            if app.CursorOverAxes
                if d > 0
                    % Zoom in
                    amt    = [-amt amt];
                    new_yL = [yL(1), yL(2)] + amt;
                    new_xL = [xL(1), xL(2)] + amt;
                elseif d < 0
                    % Zoom out
                    amt    = [amt -amt];
                    new_yL = [yL(1), yL(2)] + amt;
                    new_xL = [xL(1), xL(2)] + amt;
                end
                % Recentering on the mouse position when the callback is
                % from a key press rather than the mouse scroll.
                if isfield(event, 'Button')
                    dy     = (new_yL(2)-new_yL(1))/2;
                    dx     = (new_xL(2)-new_xL(1))/2;
                    new_yL = [app.Mouseposition(1)-dy app.Mouseposition(1)+dy];
                    new_xL = [app.Mouseposition(2)-dx app.Mouseposition(2)+dx];
                    new_yL = round(new_yL);
                    new_xL = round(new_xL);
                end
                % Bound Checking on zoom limits
                new_xL = min(app.ncols, max(new_xL, 1));
                new_yL = min(app.mrows, max(new_yL, 1));
                % Setting the new axes limits
                try
                    set(app.ax2, 'XLim', new_xL)
                    set(app.UIAxes, 'XLim', new_xL)
                    set(app.ax2, 'YLim', new_yL)
                    set(app.UIAxes, 'YLim', new_yL)
                catch
                end
            end
            app.UpdateLineWidth
            uiresume(app.UIFigure)
%             drawnow
            pause(0.001)
        end


        function PanAxes(app, ~, KeyData)
            % PANAXES will allow user to pan around the axes with the awsd
            % keys - when the shift key is pressed down
            Key      = KeyData.Key;

            % Scaling the pan by image size
            scaling      = max(app.mrows, app.ncols)/512;
            amt          = scaling*10;
            amt          = round(amt);
            switch Key
                % Origin of image is at top left (inverted y axis)
                case 'w'
                    % Shift up
                    NewYLim = app.UIAxes.YLim - amt;
                    app.UIAxes.YLim = NewYLim;
                case 'a'
                    % Shift left
                    NewXLim         = app.UIAxes.XLim - amt;
                    app.UIAxes.XLim = NewXLim;
                case 's'
                    % Shift down
                    NewYLim         = app.UIAxes.YLim + amt;
                    app.UIAxes.YLim = NewYLim;
                case 'd'
                    % Shift right
                    NewXLim         = app.UIAxes.XLim + amt;
                    app.UIAxes.XLim = NewXLim;
            end
            % Since zoom might change, curve line width is updated
            app.UpdateLineWidth
        end


        function UpdateLineWidth(app)
            % UPDATELINEWIDTH scales the width of the animated line that is
            % drawn when in turbopaint mode so that it accurately shows
            % which pixels are getting masked
            try
                if app.editmode == 4
                    % Points per pixel (72 pts/inch divided by pixels per inch) = pts/pixel
                    ScrnPts = 72/get(0, 'ScreenPixelsPerInch');

                    % If X dimension is greater, the pixel scaling has
                    % to be scaled based on X rather than Y
                    AxesWidth = app.UIAxes.Position(3);
                    DeltaLim  = app.UIAxes.XLim(2) - app.UIAxes.XLim(1);
                    % Number of screen pixels per axes' pixel
                    Scrn2AxPx = AxesWidth/DeltaLim;
                    % Pts per axes pixel conversion factor
                    ConvF     = ScrnPts*Scrn2AxPx;
                    % Number of axes pixels for the curve line width
                    widthX    = app.brushradius*ConvF;

                    % When Y dimension is greater or image is a square,
                    % Y axes limits can be used
                    AxesWidth = app.UIAxes.Position(4);
                    DeltaLim  = (app.UIAxes.YLim(2) - app.UIAxes.YLim(1));
                    % Number of screen pixels per axes' pixel
                    Scrn2AxPx = AxesWidth/DeltaLim;
                    % Pts per axes pixel conversion factor
                    ConvF     = ScrnPts*Scrn2AxPx;
                    % Number of axes pixels for the curve line width
                    widthY    = app.brushradius*ConvF;


                    % Selecting the smallest of the axes limits - since
                    % clipping is off, there are times where the other
                    % limits can go well beyond the monitor, throwing off
                    % the brush scaling
                    width = min([widthX, widthY]);

                    % Converting to screen pixels
                    app.brushlinewidth = round(2*width);
                    app.brushlinewidth = max(1, app.brushlinewidth);
                    uiresume(app.UIFigure)
                end
            catch ME
                rethrow(ME)
            end
        end
       

        function SetToolbarIcons(app)
            % SETTOOLBARICONS will create the toolbar icons to eliminate a
            % need for additional files
            IconFolder              = ['SVGIcons' filesep];
            app.ManualContrast.Icon = [IconFolder 'brightness-window.svg'];
            app.Right.Icon          = [IconFolder 'nav-arrow-right.svg'];
            app.Left.Icon           = [IconFolder 'nav-arrow-left.svg'];
            app.Down.Icon           = [IconFolder 'nav-arrow-down.svg'];
            app.Up.Icon             = [IconFolder 'nav-arrow-up.svg'];
            app.ResetView.Icon      = [IconFolder 'maximize.svg'];
%             app.Pan.Icon            = [IconFolder 'spock-hand-gesture.svg'];
            app.Pan.Icon            = [IconFolder 'drag-hand-gesture.svg'];
            app.ZoomIn.Icon         = [IconFolder 'zoom-in.svg'];
            app.ZoomOut.Icon        = [IconFolder 'zoom-out.svg'];
            
            app.Reset.Icon          = [IconFolder 'restart.svg'];
            
            app.Colormap.Icon       = [IconFolder 'palette.svg'];
            app.MaskColor.Icon      = [IconFolder 'color-filter.svg'];
            app.Finished.Icon       = [IconFolder 'accept.svg'];
            app.Help.Icon           = [IconFolder 'question-mark.svg'];
            app.Finished.Icon       = [IconFolder 'accept.svg'];
            app.Cancel.Icon         = [IconFolder 'cancel.svg'];
            
        end


        function MakeAppLogo(app)
            % MAKEAPPLOGO makes a logo consisting of a dark ring and a
            % gray circle offset from each other
            J  = zeros(54);
            s  = (-20:20).^2;
            h  = (s+s')<=(20^2);
            h2 = h~=imerode(h, ones(5));
            C1 = double(h2)*128;
            C2 = double(h)*64;
            J(1:41, 1:41)   = C1;
            J(14:54, 14:54) = J(14:54, 14:54)+C2;
            J2 = 255-repmat(J, [1 1 3]);
            J2 = uint8(J2);
            app.UIFigure.Icon = J2;
            app.UIFigure.Tag  = 'ready';
        end


        function SetPanelPositions(app)
            % Ensures positions preserved for the panels
            if ~isempty(app.BtnPanelDims)
            end
            app.Panel.Units      = 'normalized';
            app.Panel.Position   = [0.02225 0.02825 0.9522 0.7935];
            app.Panel_2.Units    = 'normalized';
            app.Panel_2.Position = [-0.0016 0.8198 1.0032 0.1822];
            app.UIAxes.Units     = 'normalized';
            app.UIAxes.Position  = [0 0 1 1];
            app.ax2.Units     = 'normalized';
            app.ax2.Position  = [0 0 1 1];
            
        end


%         function ResizeApp(app, ~, ~)
%             app.SetPanelPositions
%             app.UpdateLineWidth
%             app.UIFigure.AutoResizeChildren = 'on';
%             app.Panel.AutoResizeChildren = 'on';
%             app.Panel_2.AutoResizeChildren = 'on';
%         end


        function ColormapSelector(app)
            depth   = 1024;
            choices = {'Gray','Fire','Ice','Emerald','Bone','Solar','Thermal',...
            'Haline','Polar','DarkPolar','Ultra','Spectrum','Scatter',...
            'Ocean','Red','Green','Blue','Yellow','Inverted','Parula','DarkParula', 'CoolMagma'};
            idx = listdlg('ListString',choices);
            % Prevents flicker
            focus(app.UIFigure)

            if isempty(idx)
                app.cmap = bone(256);
            else
                map  = choices{idx(1)};
                map  = ['app.' map];
                app.cmap = eval([map '(' num2str(depth) ');']); %#ok<EVLDOT>
            end
            colormap(app.UIAxes, app.cmap)
        end
        
        function HelpInstruction(app)
            % HTML Launch with instructions for user
            if isempty(app.Helpwindow) || ~isvalid(app.Helpwindow)
                pos                       = [128 128 640 640];
                hfig                      = uifigure('Name', 'GUI Instructions', 'Position', pos);
                hfig.Icon                 = ['SVGIcons' filesep 'question-mark.png'];
                app.Helpwindow            = uihtml(hfig, 'Position', [32 32 576 576]);
                filepath                  = mfilename("fullpath");
                pathname                  = fileparts(filepath);
                app.Helpwindow.HTMLSource = fullfile(pathname, 'HelpPage_MaskingTool.html');
            end
        end
    end

    methods (Static)
        function [R, D, L, U] = DrawTriangle(n)
            % DRAWTRIANGLE draws the triangles for the arrow icons that
            % allow the user to pan across their image
            if nargin < 1
                % Size of icon
                n      = 40;
            end
            % Blank image and coordinates
            I          = zeros(n, n);
            [mgX, mgY] = meshgrid(1:n,1:n);
            % Points to isosceles triangle
            P1         = 0.25*n +1;
            P2         = 0.75*n;
            P3         = 0.85*n;
            P4         = 0.5*n;
            P          = [P1 P1; P1 P2; P3 P4];
            % Checking interior coordinates to make triangle bitmap
            pg         = polyshape(P); 
            T          = isinterior(pg, mgX(:), mgY(:));
            I(T)       = 1;
            % Blurring/smoothing and converting to color
            I          = double(I);
            I          = I*.6;
            I          = imgaussfilt(I, 1);
            I          = 0.94-I;
            I          = repmat(I, [1 1 3]);
            % Getting rotations
            R = I;
            D = rot90(I, 1);
            L = rot90(I, 2);
            U = rot90(I, 3);
        end

        function I = DrawDot(n, s)
            % DRAWDOT creates the image recentering icon
            if nargin < 2
                % Size of dot
                s  = 2;
            end
            if nargin < 1
                % Size of icon
                n  = 40;
            end
            I  = zeros(n);
            % Setting pixels for dot
            p1 = n/2-s;
            p2 = n/2+s+1;
            I(p1:p2, p1:p2) = 1;
            % Blurring/smoothing and converting to color
            I = double(I);
            I = I.*0.6;
            I = imgaussfilt(I, 1);
            I = 0.94-I;
            I = repmat(I, [1 1 3]);
        end

        function I = DrawColormap
            I             = zeros(99, 99, 3, 'uint8');
            I(:, 1:33,1)  = 255;
            I(:, 34:66,2) = 255;
            I(:, 67:99,3) = 255;
            I(:,30:36,:)  = 240;
            I(:,63:69,:)  = 240;
        end

        function I = DrawMaskColor
            I             = zeros(99, 99, 3, 'uint8');
            I(:, 1:33,1)  = 255;
            I(:, 34:66,2) = 255;
            I(:, 67:99,3) = 255;
            I             = imgaussfilt(I, 20);
        end
    end


    methods (Static)
        function Im = ImageFileLoad(fid, M, N, D)
            % IMAGEFILELOAD will load an RGB image or compute a maximum
            % intensity projection from a tiff image stack in a memory
            % efficient manner. Otherwise, if just a 2D image file, the 
            % image is loaded regularly
            if nargin < 4 
                if ischar(fid) || isstring(fid)
                    im_info = imfinfo(fid);
                    M       = im_info(1).Height;
                    N       = im_info(1).Width;
                    D       = numel(im_info);
                end
            end
        
            dtype = im_info(1).BitDepth;
            if dtype > 24
                % Flag for rescaling to uint8 when RGB
                rescale = true;
            else
                rescale = false;
            end
            if mod(dtype, 3) == 0 && dtype >= 24
                % In case the bitdepth is 24 or 48, the image will be RGB
                D     = 3;
            else
                dtype = ['uint' num2str(dtype)];
                Im   = zeros([M, N], dtype);
            end
            
            if D > 3
                wb    = waitbar(0, 'Computing maximum projection');
                for i = 1:D
                    im  = tiffreadVolume(fid, 'PixelRegion', {[1 inf], [1 inf], [i i]});
                    Im  = max(im, Im);
                    wb  = waitbar(i/D);
                    pause(0.001)
                end
                wb.delete
            else
                if contains(fid, {'.ome', '.czi'})
                    % Will need to use bioformats - NOTE: cannot use imfinfo for
                    % these files
                else
                    % In case a different format / an RGB image
                    Im = imread(fid);
                    if rescale
                        % Will reduce the image to 8 bits
                        Im = double(Im);
                        lb  = min(Im(:));
                        ub  = max(Im(:));
                        Im = (Im-lb)*(255/(ub-lb));
                        Im = uint8(Im);
                    end
                end
            end
            
        end

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
% COLORMAP FUNCTIONS
        function cmap = DarkParula(N)
            n    = round(2*N/3);
            cmap = parula(n);
            cmap = [linspace(0,1,round(N/3))'.*cmap(1,:);cmap]; 
        end
        
        function cmap = Gray(N)
            cmap = gray(N);
        end
        
        function cmap = Fire(N)
        
            n1    = round(N/3); n2 = round(2*N/3); n3 = N;
            Blue  = [zeros(1,n2) linspace(0,1,n3-n2)];
            Green = [zeros(1,n1) linspace(0,1,n2-n1) ones(1,n3-n2)];
            Red   = [linspace(0,1,n1) ones(1,n3-n1)];
            cmap  = [Red(:) Green(:) Blue(:)];
        
        end
        
        function cmap = Ice(N)
        
            n1    = round(N/3); n2 = round(2*N/3); n3 = N;
            Red   = [zeros(1,n2) linspace(0,1,n3-n2)];
            Green = [zeros(1,n1) linspace(0,1,n2-n1) ones(1,n3-n2)];
            Blue  = [linspace(0,1,n1) ones(1,n3-n1)];
            cmap  = [Red(:) Green(:) Blue(:)];
        
        end
        
        function cmap = Emerald(N)
        
            n1    = round(N/3); n2 = round(2*N/3); n3 = N;
            Blue  = [zeros(1,n2) linspace(0,1,n3-n2)];
            Red   = [zeros(1,n1) linspace(0,1,n2-n1) ones(1,n3-n2)];
            Green = [linspace(0,1,n1) ones(1,n3-n1)];
            cmap  = [Red(:) Green(:) Blue(:)];
        
        end
        
        function cmap = Bone(N)
            levels = linspace(0,1,N);
            cmap   = [levels(:) levels(:) levels(:)];
        end
        
        function cmap = Solar(N)
            n1    = round(N/3); n2 = round(2*N/3); n3 = N;
            Blue  = [zeros(1,n2) linspace(0,1,n3-n2)];
            Green = [linspace(0,1,n2) ones(1,n3-n2)];
            Red   = [linspace(0,1,n1) ones(1,n3-n1)];
            cmap  = [Red(:) Green(:) Blue(:)];
        end
        
        function cmap = Thermal(N)
            if mod(N,2)==0
                N=N+1;
            end
        
            n = floor(N/2);
        
            n1      = round(n/3); 
            n2      = round(2*n/3); 
            n3      = n;
            Red     = [zeros(1,n2) linspace(0,1,n3-n2)];
            Green   = [zeros(1,n1) linspace(0,1,n2-n1) ones(1,n3-n2)];
            Blue    = [linspace(0,1,n1) ones(1,n3-n1)];
            IceMap  = [Red(:) Green(:) Blue(:)];
        
            n1      = round(n/3); n2 = round(2*n/3); n3 = n;
            Blue    = [zeros(1,n2) linspace(0,1,n3-n2)];
            Green   = [zeros(1,n1) linspace(0,1,n2-n1) ones(1,n3-n2)];
            Red     = [linspace(0,1,n1) ones(1,n3-n1)];
            FireMap = [Red(:) Green(:) Blue(:)];
        
            cmap    = [flipud(IceMap);0 0 0;FireMap];
        end
        
        function cmap = Ultra(N)
            n1    = round(N/3); n2 = round(2*N/3); n3 = N;
            Green = [zeros(1,n2) linspace(0,1,n3-n2)];
            Blue  = [linspace(0,1,n2) ones(1,n3-n2)];
            Red   = [linspace(0,1,n1) ones(1,n3-n1)];
            cmap  = [Red(:) Green(:) Blue(:)];
        end
        
        function cmap = Ocean(N)
            n2    = round(2*N/3); n3 = N;
            Blue  = [zeros(1,n2) linspace(0,1,n3-n2)];
            Green = [linspace(0,1,n2) fliplr(linspace(0,1,n3-n2))];
            Red   = zeros(1,N);
            cmap  = [Red(:) Green(:) Blue(:)];
        end
        
        function cmap = Haline(N)
          colors = [...
              0 0 0;...
              0 0 1;...
              0 0.5 1;...
              0 1 0.5;...
              1 1 0;...
              1 1 1];
          [x,y]   = meshgrid(1:3,1:size(colors,1));
          [xf,yf] = meshgrid(1:3,linspace(1,size(colors,1),N));
          cmap    = interp2(x,y,colors,xf,yf);
        end
        
        function cmap = Polar(N)
          colors = [...
              0 0 1;...
              1 1 1;...
              1 0 0];
          [x,y]   = meshgrid(1:3,1:size(colors,1));
          [xf,yf] = meshgrid(1:3,linspace(1,size(colors,1),N));
          cmap    = interp2(x,y,colors,xf,yf);
        end
        
        function cmap = DarkPolar(N)
          colors = [...
              0 0 1;...
              0 0 0;...
              1 0 0];
          [x,y]   = meshgrid(1:3,1:size(colors,1));
          [xf,yf] = meshgrid(1:3,linspace(1,size(colors,1),N));
          cmap    = interp2(x,y,colors,xf,yf);
        end
        
        function cmap = Red(N)
          colors = [...
              1 1 1;...
              1 0 0];
          [x,y]   = meshgrid(1:3,1:size(colors,1));
          [xf,yf] = meshgrid(1:3,linspace(1,size(colors,1),N));
          cmap    = interp2(x,y,colors,xf,yf);
        end
        
        function cmap = Green(N)
          colors = [...
              1 1 1;...
              0 1 0];
          [x,y]   = meshgrid(1:3,1:size(colors,1));
          [xf,yf] = meshgrid(1:3,linspace(1,size(colors,1),N));
          cmap    = interp2(x,y,colors,xf,yf);
        end
        
        function cmap = Blue(N)
          colors = [...
              1 1 1;...
              0 0 1];
          [x,y]   = meshgrid(1:3,1:size(colors,1));
          [xf,yf] = meshgrid(1:3,linspace(1,size(colors,1),N));
          cmap    = interp2(x,y,colors,xf,yf);
        end
        
        function cmap = Yellow(N)
          colors = [...
              0 0 0;...
              1 1 0];
          [x,y]   = meshgrid(1:3,1:size(colors,1));
          [xf,yf] = meshgrid(1:3,linspace(1,size(colors,1),N));
          cmap    = interp2(x,y,colors,xf,yf);
        end
        
        function cmap = Inverted(N)
             colors = [...
              1 1 1;...
              0 0 0];
          [x,y]   = meshgrid(1:3,1:size(colors,1));
          [xf,yf] = meshgrid(1:3,linspace(1,size(colors,1),N));
          cmap    = interp2(x,y,colors,xf,yf);
        end
        
        function cmap = Spectrum(N)
            colors = [...
              0.5 1 0;...
              1 0 0;...
              0.1 0.5 0;...
              1 1 0;...
              0 1 1;...
              0 0 1;...
              0.5 0 1;...
              1 0.2 1];
          [x,y]   = meshgrid(1:3,1:size(colors,1));
          [xf,yf] = meshgrid(1:3,linspace(1,size(colors,1),N));
          cmap    = interp2(x,y,colors,xf,yf);
        end
        
        function cmap = Parula(N)
            cmap = parula(N);
        end
        
        function cmap = Scatter(N)
            cmap      = parula(N);
            cmap(1,:) =[1 1 1];
            cmap      = imfilter(cmap,ones(5,1)/5,'replicate');
            cmap(1,:) =[1 1 1];
        end
        
        function cmap = CoolMagma(N)
            colors = [...
                  0 0 0;...
                  0 0 1;...
                  0 0.5 1;...
                  0 1 0.5;...
                  1 1 0;...
                  1 1 1];
            [x,y]   = meshgrid(1:3,1:size(colors,1));
            [xf,yf] = meshgrid(1:3,linspace(1,size(colors,1),N));
            cmap    = interp2(x,y,colors,xf,yf);
            cmap    = 1 - flipud(cmap);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, I, mask, cmap, clims, MaskCMAP)
            app.UIFigure.Visible = 'off';
            % Initializes the monitor/screen size
            app.monitorsize = get(0, 'ScreenSize');
            app.monitorsize = app.monitorsize(3:4);
            % Gets parent app that is calling the masking tool to ensure
            % proper refocusing of the windows when dialog windows appear
            f = gcbf;
            if ~isempty(f)
                app.Parent = f;
            else
                % When running on its own, needs path added to properly run
                fp        = mfilename('fullpath');
                [path, ~] = fileparts(fp);
                addpath(path);
            end

            % Setting the toolbar spacer to be a blank icon
            OffWhite                 = [0.94 0.94 0.94];
            BlankIcon                = reshape(OffWhite, [1 1 3]);
            BlankIcon                = repmat(BlankIcon, [40 40]);
            app.ToolbarSpacer.Icon   = BlankIcon;            

            % Recentering the GUI
            if ~isempty(app.Parent)
                centerfig(app.UIFigure, app.Parent)
            else
                centerfig(app.UIFigure)
            end
            drawnow

            % Mask color
            if nargin < 6
                app.cmask = [0 0 0; 1 1 1];
            else
                app.cmask = [0 0 0; MaskCMAP];
            end
            
            % Image loading and plotting
            if nargin < 5
                clims = [];
            end

            % Colormap setting
            if nargin < 4
                app.cmap = bone;
            else
                app.cmap  = cmap;
            end

            % Mask loading and plotting
            if nargin < 3
                mask = [];
            end
            
            if nargin < 2 || isempty(I)
                % Loads image from a dialog box
                filt = {'*.jpg; *.jpeg; *.png; *.tif; *.tiff'};
                [fname, pname]  = uigetfile(filt, 'Select image file', '', 'MultiSelect','off');
                % If user clicks cancel, the app will just close
                if isnumeric(fname)
                    app.delete
                    return
                end
                fid = fullfile(pname, fname);
                I   = fid;
            end

            % In case someone loads in a mask rather than image
            if islogical(I)
                I = uint8(I);
            end

            % Needed to activate zoom and pan modes
            enableLegacyExplorationModes(app.UIFigure)
            % Image plotting with settings passed to app
            app.CLims = clims;
            app.PlotImage(I);
            app.PlotMask(mask);

            % Adjusts the limits on the paint radius
            app.RadiusEditField.Limits    = [1, floor(min(app.mrows,app.ncols)/2)];

            % Enables the callback used for paint brush feature
            set(app.UIFigure, 'WindowButtonMotionFcn', @app.MouseMoving)
            % Will only edit the mask when mouse button is clicked down
            set(app.UIFigure, 'WindowButtonUpFcn', @app.AdjustOff)
            % Enabling scroll wheel zooming without the zoom object
            % functionality
            set(app.UIFigure, 'windowscrollWheelFcn', @app.AdjustZoom)
            set(app.UIAxes.XAxis, 'LimitsChangedFcn', @app.CheckZoom)
            set(app.UIAxes.YAxis, 'LimitsChangedFcn', @app.CheckZoom)

            % Getting the initial size of the GUI
            app.initialguisize = app.UIFigure.Position(3:4);

            % Programmatically making logos to eliminate file dependencies
            app.SetToolbarIcons
            app.MakeAppLogo
            waitfor(app.UIFigure, 'Tag', 'ready')
            pause(0.001)
            app.UIFigure.Visible = 'on';
            app.UIFigure.Tag     = 'redo';

            % Computes an image with edges detected
            app.ComputeEdges

            SetAppInterrupt(app)
            drawnow limitrate
            
        end

        % Value changed function: RadiusEditField
        function RadiusEditFieldValueChanged(app, event)
            % Edit field will either edit the paint brush radius or the
            % shrink factor (alpha) value - depends on editing method
            value = app.RadiusEditField.Value;
            if app.editmode == 1
                app.brushradius           = value;
                if isa(app.brushroi, 'images.roi.Circle')
                    app.brushroi.Radius = app.brushradius;
                end
            elseif app.editmode == 4
                % Changes the radius of the turbo paint brush
                app.brushradius         = value;
                if isa(app.brushroi, 'images.roi.Circle')
                    app.brushroi.Radius = app.brushradius;
                end
                app.UpdateLineWidth
            elseif app.editmode == 5 || app.editmode == 6
                % Seed grow mode
                % Percentage of the range of pixel intensity values
                value             = value/100;
                scaling           = app.imagerange(2) - app.imagerange(1);
                value             = scaling*value;
                app.seedtol = value + app.imagerange(1);
            end
            app.InteractivityOff
            focus(app.UIFigure)
        end

        % Value changed function: ModeDropDown
        function ModeDropDownValueChanged(app, event)
            % Takes the focus off of the button so the button doesn't
            % appear clicked
            focus(app.UIFigure)
            % Forces the mouse to be off so that no edits are made til the
            % user clicks again after having changed the mode
            app.appendordelete   = [];
            app.clickisdown = false;
            app.ClearROI
            % Sets the edit mode according to selection
            value         = app.ModeDropDown.Value;
            app.ChangeMode(value)
            % Toolbar buttons turned off, assuming user is now planning on
            % editing the mask
            app.TurnOffZoom
            app.TurnOffPan
            switch app.editmode
                case 1
                    % Touch up mode
                    app.RadiusEditField.Enable    = true;
                    app.RadiusEditFieldLabel.Text = 'Radius';
                    app.RadiusEditField.Limits    = [1, floor(min(app.mrows,app.ncols)/2)];
                    app.RadiusEditField.Value     = app.brushradius;
                    app.CloseAllButton.Enable     = 'off';
                    app.CloseInterior.Enable      = 'off';
                case 2
                    % Point Mode
                    app.RadiusEditField.Limits    = [0, 1];
                    app.RadiusEditFieldLabel.Text = '';
                    app.RadiusEditField.Value     = 0;
                    app.RadiusEditField.Enable    = false;
                    app.CloseAllButton.Enable     = 'off';
                    app.CloseInterior.Enable      = 'off';
                case 3
                    % Bounding curve mode
                    app.RadiusEditField.Enable    = false;
                    app.RadiusEditField.Limits    = [0, 1];
                    app.RadiusEditField.Value     = 0;
                    app.RadiusEditFieldLabel.Text = '';
                    app.CloseAllButton.Enable     = 'off';
                    app.CloseInterior.Enable      = 'off';
                    % Init set mode
                    app.appendordelete                   = 1;
                case 4
                    % Turbo Paint mode
                    app.RadiusEditField.Enable    = true;
                    app.RadiusEditFieldLabel.Text = 'Radius';
                    app.RadiusEditField.Limits    = [1, floor(min(app.mrows,app.ncols)/2)];
                    app.RadiusEditField.Value     = app.brushradius;
                    app.CloseAllButton.Enable     = 'on';
                    app.CloseInterior.Enable      = 'on';
                case {5, 6}
                    % Seed Region or Smart cut
                    app.RadiusEditField.Enable    = true;
                    app.RadiusEditFieldLabel.Text = 'Tolerance';
                    app.RadiusEditField.Limits    = [0, 100];
                    % Percentage of the range of pixel intensity values
                    % Scaling the tolerance value back
                    if ~isempty(app.seedtol)
                        value                     = app.seedtol - app.imagerange(1);
                        scaling                   = app.imagerange(2) - app.imagerange(1);
                        value                     = value/scaling;
                        value                     = value*100;
                        app.RadiusEditField.Value = value;
                    else
                        value                     = 1;
                        app.RadiusEditField.Value = value;
                        % Initialize the seed region tolerance value
                        app.RadiusEditFieldValueChanged
                    end
                    app.CloseAllButton.Enable     = 'off';
                    app.CloseInterior.Enable      = 'off';
            end
            app.UpdateCursor
            app.UpdateLineWidth
            uiresume(app.UIFigure)
        end

        % Callback function: Colormap
        function ColormapButtonPushed(app, event)
            % Will allow user to select a colormap and change the plot
            % color
            app.ColormapSelector;
            if ~isempty(app.Parent)
                figure(app.Parent)
            end
            figure(app.UIFigure)
            app.UpdateCursorColor
        end

        % Callback function: MaskColor
        function MaskColorButtonPushed(app, event)
            % Constructs a two color colormap for the mask
            c         = uisetcolor([1 1 1], 'Mask color');
            if ~isempty(app.Parent)
                figure(app.Parent)
            end
            figure(app.UIFigure)
            focus(app.UIFigure)
            app.cmask = [0 0 0 ; c];
            colormap(app.ax2, app.cmask)
            app.UpdateCursorColor
        end

        % Value changing function: OpaquenessSlider
        function OpaquenessSliderValueChanging(app, event)
            % Adjusts how transparent the mask is atop the image
            changingValue            = event.Value;
            app.transparency            = changingValue;
            idx                      = app.maskH.AlphaData(:) > 0;
            app.maskH.AlphaData(idx) = app.transparency;
            pause(0)
        end

        % Callback function: Reset
        function RestartButtonPushed(app, event)
            app.ResetMask
            focus(app.UIFigure)
        end

        % Callback function: Finished
        function FinishedButtonPushed(app, event)
            % Updates the Mask property and sets the done flag as true
            app.appendordelete  = [];
            app.Mask2     = app.maskH.AlphaData>0;
            if ~isempty(app.Parent)
                setappdata(app.Parent, 'MaskData', app.Mask2)
            end
            app.delete
        end

        % Callback function: ManualContrast
        function ManualContrastButtonPushed(app, event)
            % Launches manual contrast tool - differs if RGB image
            if ~app.isrgb
                app.imcontrastH = imcontrast(app.imH);
            else
                app.ManualContrastRGB
            end
        end

        % Callback function: Cancel, UIFigure
        function UIFigureCloseRequest(app, event)
            % Will immediately close window if done button was clicked but
            % will ask user if they would like to save mask if close button
            % pressed

            % If closed via the window's close button rather than the
            % close button in the GUI
            h = gcbo;
            if ~isa(h, 'matlab.ui.control.Button')
                qans = questdlg('Keep mask changes?', 'Mask Update', 'Yes', 'No', 'Cancel', 'Yes');
                if strcmp(qans, 'Yes')
                    app.FinishedButtonPushed
                elseif strcmp(qans, 'No')
                    if ~isempty(app.Parent)
                        setappdata(app.Parent, 'MaskData', NaN)
                    end
                    app.delete
                else
                    % Figure remains
                end
            end
        end

        % Button pushed function: AutocontrastButton
        function AutocontrastButtonPushed(app, event)
            % Takes the focus off of the button so the button doesn't
            % appear clicked
            focus(app.UIFigure)
           if ~isempty(app.imcontrastH) && isvalid(app.imcontrastH)
                app.imcontrastH.delete
            end
            app.AutoContrast 
        end

        % Callback function: Up
        function UpClicked(app, event)
            % Takes the focus off of the button so the button doesn't
            % appear clicked
            focus(app.UIFigure)
            % Will pan up
            KeyData.Modifier = 'shift';
            KeyData.Key      = 's';
            app.PanAxes([], KeyData)
        end

        % Callback function: Down
        function DownClicked(app, event)
            % Takes the focus off of the button so the button doesn't
            % appear clicked
            focus(app.UIFigure)
            % Will pan down
            KeyData.Modifier = 'shift';
            KeyData.Key      = 'w';
            app.PanAxes([], KeyData)
        end

        % Callback function: Left
        function LeftClicked(app, event)
            % Takes the focus off of the button so the button doesn't
            % appear clicked
            focus(app.UIFigure)
            % Will pan left
            KeyData.Modifier = 'shift';
            KeyData.Key      = 'a';
            app.PanAxes([], KeyData)
        end

        % Callback function: Right
        function RightClicked(app, event)
            % Takes the focus off of the button so the button doesn't
            % appear clicked
            focus(app.UIFigure)
            % Will pan right
            KeyData.Modifier = 'shift';
            KeyData.Key      = 'd';
            app.PanAxes([], KeyData)
        end

        % Callback function: ResetView
        function ResetViewClicked(app, event)
            % Takes the focus off of the button so the button doesn't
            % appear clicked
            focus(app.UIFigure)
            % Recenter the image and resets the zoom
            app.UIAxes.XLim = [1 app.ncols];
            app.UIAxes.YLim = [1 app.mrows];
%             drawnow
            pause(0.001)
        end

        % Callback function: Pan
        function PanClicked(app, event)
            % Takes the focus off of the button so the button doesn't
            % appear clicked
            focus(app.UIFigure)
            % Zoom turned off so pan mode can be used
            app.TurnOffZoom
            if app.Pan.State
                % Ensures editing is off while navigating image
                app.PauseMasking
                pan(app.UIAxes, 'on');
%                 drawnow
                pause(0.001)
            else
                % Go back to triggering the ROI drawing if in that mode
                uiresume(app.UIFigure)
                pan(app.UIAxes, 'off');
                % Forces stack to cleat so the pointer can return to normal
%                 drawnow
                pause(0.001)
                app.UIFigure.Pointer = 'arrow';
            end
        end

        % Callback function: ZoomIn
        function ZoomInClicked(app, event)
            % Takes the focus off of the button so the button doesn't
            % appear clicked
            focus(app.UIFigure)
            if app.Pan.State
                pan(app.UIAxes, 'off');
                app.Pan.State = 'off';
            end
            if app.ZoomIn.State
                app.PauseMasking
                app.ZoomOut.State = 'off';
                if isempty(app.zoomObj)
                    app.zoomObj = zoom(app.UIFigure);
                end
                app.zoomObj.Enable    = 'on';
                app.zoomObj.Direction = 'in';
            else
                app.zoomObj.Enable    = 'off';
            end
        end

        % Callback function: ZoomOut
        function ZoomOutClicked(app, event)
            % Takes the focus off of the button so the button doesn't
            % appear clicked
            focus(app.UIFigure)
            if app.Pan.State
                pan(app.UIAxes, 'off');
                app.Pan.State = 'off';
            end
            if app.ZoomOut.State
                app.PauseMasking
                app.ZoomIn.State = 'off';
                if isempty(app.zoomObj)
                    app.zoomObj = zoom(app.UIFigure);
                end
                app.zoomObj.Enable    = 'on';
                app.zoomObj.Direction = 'out';
            else
                app.zoomObj.Enable    = 'off';
            end
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            if ~isempty(event.Modifier)
                Modifier = event.Modifier;
                Modifier = Modifier{:};
                % A modifier is needed for the axes pan shortcut
                switch Modifier
                    case 'shift'
                        app.PanAxes([], event)
                    case 'control'
                        switch key
                            case 'z'
                                app.UndoOrRedo(true)
                            case 'y'
                                app.UndoOrRedo(false)
                        end
                end
            else
                % The +/- buttons will allow the user to zoom into
                % wherever their mouse is currently
                switch key
                    case 'subtract'
                        scroll.VerticalScrollCount  = 1;
                        scroll.VerticalScrollAmount = 5;
                        scroll.Button               = true;
                        app.AdjustZoom([], scroll)
                    case 'add'
                        scroll.VerticalScrollCount  = -1;
                        scroll.VerticalScrollAmount = 5;
                        scroll.Button               = true;
                        app.AdjustZoom([], scroll)
                end
            end
        end

        % Button pushed function: CloseInterior
        function CloseInteriorButtonPushed(app, event)
            % Takes the focus off of the button so the button doesn't
            % appear clicked
            focus(app.UIFigure)
            if app.appendordelete == -1
                return
            end
            app.ClosePaintInterior(false)
        end

        % Button pushed function: CloseAllButton
        function CloseAllButtonPushed(app, event)
            % Takes the focus off of the button so the button doesn't
            % appear clicked
            focus(app.UIFigure)
            app.ClosePaintInterior(true)
        end

        % Value changed function: AutoCMethods
        function AutoCMethodsValueChanged(app, event)
            % Takes the focus off of the button so the button doesn't
            % appear clicked
            focus(app.UIFigure)
            % User may change the way auto contrasted image is computed
            value = app.AutoCMethods.Value;
            switch value
                case 'Match Intensity Range'
                    app.autocontrastmethod = 1;
                case 'Match Median Filtered Image Intensity Range'
                    app.autocontrastmethod = 2;
                case '1% Quartiles'
                    app.autocontrastmethod = 3;
            end
            app.AutoContrast
        end

        % Button pushed function: MaskAllButton
        function MaskAllButtonPushed(app, event)
            % Takes the focus off of the button so the button doesn't
            % appear clicked
            focus(app.UIFigure)
            app.MaskAll
        end

        % Button pushed function: InvertMaskButton
        function InvertMaskButtonPushed(app, event)
            % Takes the focus off of the button so the button doesn't
            % appear clicked
            focus(app.UIFigure)
            app.InvertMask
        end

        % Button pushed function: Unmask
        function UnmaskButtonPushed(app, event)
            % Takes the focus off of the button so the button doesn't
            % appear clicked
            focus(app.UIFigure)
            app.UpdateUndoStack
            app.maskH.AlphaData = zeros(app.mrows, app.ncols, 'double');
        end

        % Callback function: Help
        function HelpClicked(app, event)
            % Takes the focus off of the button so the button doesn't
            % appear clicked
            focus(app.UIFigure)
            app.HelpInstruction
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Masking Tool';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.Toolbar = uitoolbar(app.UIFigure);
            app.ManualContrast = uipushtool(app.Toolbar);
            app.ManualContrast.Tooltip = {'Manual Contrast'};
            app.ManualContrast.ClickedCallback = createCallbackFcn(app, @ManualContrastButtonPushed, true);
            app.Up = uipushtool(app.Toolbar);
            app.Up.Tooltip = {'Pan Up'};
            app.Up.ClickedCallback = createCallbackFcn(app, @UpClicked, true);
            app.Down = uipushtool(app.Toolbar);
            app.Down.Tooltip = {'Pan Down'};
            app.Down.ClickedCallback = createCallbackFcn(app, @DownClicked, true);
            app.Left = uipushtool(app.Toolbar);
            app.Left.Tooltip = {'Pan Left'};
            app.Left.ClickedCallback = createCallbackFcn(app, @LeftClicked, true);
            app.Right = uipushtool(app.Toolbar);
            app.Right.Tooltip = {'Pan Right'};
            app.Right.ClickedCallback = createCallbackFcn(app, @RightClicked, true);
            app.ResetView = uipushtool(app.Toolbar);
            app.ResetView.Tooltip = {'Maximize View'};
            app.ResetView.ClickedCallback = createCallbackFcn(app, @ResetViewClicked, true);
            app.Pan = uitoggletool(app.Toolbar);
            app.Pan.Tooltip = {'Pan Mode'};
            app.Pan.ClickedCallback = createCallbackFcn(app, @PanClicked, true);
            app.ZoomIn = uitoggletool(app.Toolbar);
            app.ZoomIn.Tooltip = {'Zoom In'};
            app.ZoomIn.ClickedCallback = createCallbackFcn(app, @ZoomInClicked, true);
            app.ZoomOut = uitoggletool(app.Toolbar);
            app.ZoomOut.Tooltip = {'Zoom Out'};
            app.ZoomOut.ClickedCallback = createCallbackFcn(app, @ZoomOutClicked, true);
            app.Reset = uipushtool(app.Toolbar);
            app.Reset.Tooltip = {'Reset Mask'};
            app.Reset.ClickedCallback = createCallbackFcn(app, @RestartButtonPushed, true);
            app.Colormap = uipushtool(app.Toolbar);
            app.Colormap.Tooltip = {'Colormap'};
            app.Colormap.ClickedCallback = createCallbackFcn(app, @ColormapButtonPushed, true);
            app.MaskColor = uipushtool(app.Toolbar);
            app.MaskColor.Tooltip = {'Mask Color'};
            app.MaskColor.ClickedCallback = createCallbackFcn(app, @MaskColorButtonPushed, true);
            app.Help = uipushtool(app.Toolbar);
            app.Help.Tooltip = {'Help'};
            app.Help.ClickedCallback = createCallbackFcn(app, @HelpClicked, true);
            app.Finished = uipushtool(app.Toolbar);
            app.Finished.Tooltip = {'Finished'};
            app.Finished.ClickedCallback = createCallbackFcn(app, @FinishedButtonPushed, true);
            app.ToolbarSpacer = uipushtool(app.Toolbar);
            app.ToolbarSpacer.Enable = 'off';
            app.Cancel = uipushtool(app.Toolbar);
            app.Cancel.Tooltip = {'Clear Mask'};
            app.Cancel.ClickedCallback = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Panel = uipanel(app.UIFigure);
            app.UIAxes = uiaxes(app.Panel);
            app.UIAxes.ALim = [0 1];
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.Visible = 'off';
            app.Panel_2 = uipanel(app.UIFigure);
            app.AutocontrastButton = uibutton(app.Panel_2, 'push');
            app.AutocontrastButton.ButtonPushedFcn = createCallbackFcn(app, @AutocontrastButtonPushed, true);
            app.AutocontrastButton.Text = 'Autocontrast';
            app.ModeDropDownLabel = uilabel(app.Panel_2);
            app.ModeDropDownLabel.Text = 'Mode';
            app.ModeDropDown = uidropdown(app.Panel_2);
            app.ModeDropDown.Items = {'Touch Up', 'Point', 'Bounding Curves', 'Turbo Paint Brush', 'Seed Region', 'Edge Based Cut'};
            app.ModeDropDown.ValueChangedFcn = createCallbackFcn(app, @ModeDropDownValueChanged, true);
            app.ModeDropDown.Value = 'Turbo Paint Brush';
            app.RadiusEditFieldLabel = uilabel(app.Panel_2);
            app.RadiusEditFieldLabel.Text = 'Radius';
            app.RadiusEditField = uieditfield(app.Panel_2, 'numeric');
            app.RadiusEditField.Limits = [0 256];
            app.RadiusEditField.ValueChangedFcn = createCallbackFcn(app, @RadiusEditFieldValueChanged, true);
            app.RadiusEditField.Value = 3;
            app.OpaquenessSliderLabel = uilabel(app.Panel_2);
            app.OpaquenessSliderLabel.Text = 'Opaqueness';
            app.OpaquenessSlider = uislider(app.Panel_2);
            app.OpaquenessSlider.Limits = [2.22044604925031e-16 1];
            app.OpaquenessSlider.MajorTicks = [];
            app.OpaquenessSlider.ValueChangingFcn = createCallbackFcn(app, @OpaquenessSliderValueChanging, true);
            app.OpaquenessSlider.MinorTicks = [];
            app.OpaquenessSlider.Value = 0.4;
            app.CloseAllButton = uibutton(app.Panel_2, 'push');
            app.CloseAllButton.ButtonPushedFcn = createCallbackFcn(app, @CloseAllButtonPushed, true);
            app.CloseAllButton.Text = 'Close All';
            app.AutoCMethods = uidropdown(app.Panel_2);
            app.AutoCMethods.Items = {'Match Intensity Range', 'Match Median Filtered Image Intensity Range', '1% Quantiles'};
            app.AutoCMethods.ValueChangedFcn = createCallbackFcn(app, @AutoCMethodsValueChanged, true);
            app.AutoCMethods.Value = 'Match Intensity Range';
            app.CloseInterior = uibutton(app.Panel_2, 'push');
            app.CloseInterior.ButtonPushedFcn = createCallbackFcn(app, @CloseInteriorButtonPushed, true);
            app.CloseInterior.Text = 'Close Interior';
            app.InvertMaskButton = uibutton(app.Panel_2, 'push');
            app.InvertMaskButton.ButtonPushedFcn = createCallbackFcn(app, @InvertMaskButtonPushed, true);
            app.InvertMaskButton.Text = 'Invert Mask';
            app.MaskAllButton = uibutton(app.Panel_2, 'push');
            app.MaskAllButton.ButtonPushedFcn = createCallbackFcn(app, @MaskAllButtonPushed, true);
            app.MaskAllButton.Text = 'Mask All';
            app.Unmask = uibutton(app.Panel_2, 'push');
            app.Unmask.ButtonPushedFcn = createCallbackFcn(app, @UnmaskButtonPushed, true);
            app.Unmask.Text = 'Unmask';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MaskingTool(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end