classdef ROISegmentationGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Toolbar                        matlab.ui.container.Toolbar
        Load                           matlab.ui.container.toolbar.PushTool
        LoadMasks                      matlab.ui.container.toolbar.PushTool
        InstantNav                     matlab.ui.container.toolbar.PushTool
        LeftArrow                      matlab.ui.container.toolbar.PushTool
        RightArrow                     matlab.ui.container.toolbar.PushTool
        UpArrow                        matlab.ui.container.toolbar.PushTool
        DownArrow                      matlab.ui.container.toolbar.PushTool
        Batch                          matlab.ui.container.toolbar.ToggleTool
        AutoContrast                   matlab.ui.container.toolbar.ToggleTool
        ManualContrast                 matlab.ui.container.toolbar.ToggleTool
        CropButton                     matlab.ui.container.toolbar.PushTool
        TargetColor                    matlab.ui.container.toolbar.PushTool
        Square                         matlab.ui.container.toolbar.ToggleTool
        LogScaleButton                 matlab.ui.container.toolbar.ToggleTool
        Inversion                      matlab.ui.container.toolbar.ToggleTool
        ChangeDenoise                  matlab.ui.container.toolbar.PushTool
        DenoiseButton                  matlab.ui.container.toolbar.ToggleTool
        ChangeNetwork                  matlab.ui.container.toolbar.PushTool
        AIButton                       matlab.ui.container.toolbar.ToggleTool
        BoundaryTaper                  matlab.ui.container.toolbar.ToggleTool
        ChangeMethod                   matlab.ui.container.toolbar.PushTool
        AutoParamsButton               matlab.ui.container.toolbar.PushTool
        ActiveContours                 matlab.ui.container.toolbar.ToggleTool
        EditResults                    matlab.ui.container.toolbar.ToggleTool
        Colormap                       matlab.ui.container.toolbar.PushTool
        ContourColor                   matlab.ui.container.toolbar.PushTool
        ContourButton                  matlab.ui.container.toolbar.ToggleTool
        TransposeButton                matlab.ui.container.toolbar.ToggleTool
        PlotMajMin                     matlab.ui.container.toolbar.ToggleTool
        ColorBar                       matlab.ui.container.toolbar.ToggleTool
        AuxiliaryView                  matlab.ui.container.toolbar.ToggleTool
        ViewNetResults                 matlab.ui.container.toolbar.PushTool
        Overwrite                      matlab.ui.container.toolbar.ToggleTool
        DarkMode                       matlab.ui.container.toolbar.ToggleTool
        ResetButton                    matlab.ui.container.toolbar.PushTool
        InfoTool                       matlab.ui.container.toolbar.PushTool
        TabGroup                       matlab.ui.container.TabGroup
        AxesTab                        matlab.ui.container.Tab
        Panel                          matlab.ui.container.Panel
        AxesPanel                      matlab.ui.container.Panel
        UIAxes                         matlab.ui.control.UIAxes
        ViewTypeSwitch                 matlab.ui.control.Switch
        ViewTypeSwitchLabel            matlab.ui.control.Label
        SliderLabel_3                  matlab.ui.control.Label
        SliderLabel_2                  matlab.ui.control.Label
        SliderLabel_1                  matlab.ui.control.Label
        BottomSliderUnits              matlab.ui.control.Label
        MiddleSliderUnits              matlab.ui.control.Label
        TopSliderUnits                 matlab.ui.control.Label
        MinLabel                       matlab.ui.control.Label
        TopEditFieldMin                matlab.ui.control.EditField
        MiddleEditFieldMin             matlab.ui.control.EditField
        BottomEditFieldMin             matlab.ui.control.EditField
        MaxLabel                       matlab.ui.control.Label
        TopEditFieldMax                matlab.ui.control.EditField
        MiddleEditFieldMax             matlab.ui.control.EditField
        BottomEditFieldMax             matlab.ui.control.EditField
        TopSliderValueLabel            matlab.ui.control.Label
        MiddleSliderValueLabel         matlab.ui.control.Label
        BottomSliderValueLabel         matlab.ui.control.Label
        BottomSlider                   matlab.ui.control.Slider
        MiddleSlider                   matlab.ui.control.Slider
        TopSlider                      matlab.ui.control.Slider
        SettingsTab                    matlab.ui.container.Tab
        Panel_2                        matlab.ui.container.Panel
        GridLayout4                    matlab.ui.container.GridLayout
        Visualization                  matlab.ui.container.Panel
        GridLayout2_2                  matlab.ui.container.GridLayout
        VisualsforBatchingLabel        matlab.ui.control.Label
        VisualFeedback                 matlab.ui.control.CheckBox
        LineWidthEditField             matlab.ui.control.NumericEditField
        LineWidthEditFieldLabel        matlab.ui.control.Label
        MarkerWidthEditField           matlab.ui.control.NumericEditField
        MarkerWidthEditFieldLabel      matlab.ui.control.Label
        PointMarkerDropDown            matlab.ui.control.DropDown
        PointMarkerDropDownLabel       matlab.ui.control.Label
        LazyLoadingLabel               matlab.ui.control.Label
        LazyLoadingCheckbox            matlab.ui.control.CheckBox
        ComputeStopStepEditField       matlab.ui.control.NumericEditField
        ComputeStopStepEditFieldLabel  matlab.ui.control.Label
        MaskOpacitySlider              matlab.ui.control.Slider
        MaskOpacitySliderLabel         matlab.ui.control.Label
        ActiveContoursConfigurationPanel  matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        ContractionBiasEditField       matlab.ui.control.NumericEditField
        ContractionBiasEditFieldLabel  matlab.ui.control.Label
        DenoiseFactorEditField         matlab.ui.control.NumericEditField
        DenoiseFactorEditFieldLabel    matlab.ui.control.Label
        SmoothnessEditField            matlab.ui.control.NumericEditField
        SmoothnessEditFieldLabel       matlab.ui.control.Label
        NumberofiterationsEditField    matlab.ui.control.NumericEditField
        NumberofiterationsEditFieldLabel  matlab.ui.control.Label
        ExportTab                      matlab.ui.container.Tab
        Panel_3                        matlab.ui.container.Panel
        GridLayout5                    matlab.ui.container.GridLayout
        ExportOptionsPanel             matlab.ui.container.Panel
        GridLayout3                    matlab.ui.container.GridLayout
        SavemasksCheckBox              matlab.ui.control.CheckBox
        ResultsformatListBox           matlab.ui.control.ListBox
        ResultsformatListBoxLabel      matlab.ui.control.Label
        MaskformatListBox              matlab.ui.control.ListBox
        MaskformatListBoxLabel         matlab.ui.control.Label
        FileNameEditField              matlab.ui.control.EditField
        FileNameEditFieldLabel         matlab.ui.control.Label
        SaveFolderEditField            matlab.ui.control.EditField
        SaveFolderEditFieldLabel       matlab.ui.control.Label
        ExportButton                   matlab.ui.control.Button
        PropSettings                   matlab.ui.container.CheckBoxTree
        MaskPropertiesNode             matlab.ui.container.TreeNode
        AreaNode                       matlab.ui.container.TreeNode
        BoundingBoxNode                matlab.ui.container.TreeNode
        AspectRatioNode                matlab.ui.container.TreeNode
        CentroidNode                   matlab.ui.container.TreeNode
        MajorAxisLengthNode            matlab.ui.container.TreeNode
        MinorAxisLengthNode            matlab.ui.container.TreeNode
        OrientationNode                matlab.ui.container.TreeNode
        PerimeterNode                  matlab.ui.container.TreeNode
        BrowseButton                   matlab.ui.control.Button
    end


    properties (Access = public)
        % Image Loader
        Server                        % Class holding the image/stack - has methods to load new image/stack and push the next slice

        % Processing engine
        Engine                        % The computational engine conducting the algorithmic processing of the image
        defaults                      % Will grab default param vector values upon initialization of the engine to allow proper GUI reset

        % Visualization classes
        Visengine                     % Visualization engine looks at data and results to determine appropriate visualization
        Enginevisualizer

        % Data related
        Exportmodule                  % Class for handling data export
        Exportsettings                % Configuration of export as set in the settings tab
        Segsettings                   % Struct holds information regarding segmentation settings used to produce results
        loading = false               % logical indicates whether previous settings and/or results are loading into the GUI

        % Slider limits/values
        smax                          % Sliders upper bound
        smin                          % Sliders lower bound
        params                        % Vector containing new parameter values to compare and determine what need to be recomputed
        
        % Graphics Settings
        % CoverImage                  % Image to be shown along with the cover text / title in the middle upon launch
        Cover                         % Cover title/image as .svg file to be loaded in the center upon launch
        fontname = 'Century Gothic'   % Font used for all labels and text in the GUI
        cbar                          % Colorbar handle

        % Other
        helpWindow                    % Window holding the help page / documentation
        progDlg                       % Progress dialog window for processing steps. Allows other classes to grab handle to alter text
    end


    methods (Access = private)

        function InstantiateClasses(app)
            % INSTANTIATECLASSES will instanatiate all interacting classes
            % needed for the GUI to function. It will also call the GUI
            % figure handle setting methods from classes that have them to
            % allow for additional graphical interactivity.
            app.Server       = ImageServer;                % Data input / loading
            app.Engine       = SegmentationEngine;         % Computation on data
            app.Exportmodule = ExportModule(app.UIFigure); % Data organization and export of results
            app.Visengine    = VisualizationEngine(app);   % Visualization of data and results

            % Setting the GUI uifigure handle
            app.Server.SetGUIHandle(app.UIFigure)
        end


        function GraphicalSettings(app)
            % GRAPHICALSETTINGS will set the icons, cover image, etc.
            % Folder that the app is running in - to load icons
            IconFolder                      = ['Visuals' filesep 'SVGIcons' filesep];
            
            % Toolbar button icons
            app.Load.Icon                   = [IconFolder 'upload.svg'];
            app.LoadMasks.Icon              = [IconFolder 'upload-square.svg'];
            app.InstantNav.Icon             = [IconFolder 'compass.svg'];
            app.LeftArrow.Icon              = [IconFolder 'move-left.svg'];
            app.RightArrow.Icon             = [IconFolder 'move-right.svg'];
            app.UpArrow.Icon                = [IconFolder 'move-up.svg'];
            app.DownArrow.Icon              = [IconFolder 'move-down.svg'];
            app.Batch.Icon                  = [IconFolder 'stack-3.svg'];
            app.AutoContrast.Icon           = [IconFolder 'brightness.svg'];
            app.ManualContrast.Icon         = [IconFolder 'brightness-window.svg'];
            app.TargetColor.Icon            = [IconFolder 'color-picker.svg'];
            app.Square.Icon                 = [IconFolder 'superscript.svg'];
            app.LogScaleButton.Icon         = [IconFolder 'log.svg'];
            app.Inversion.Icon              = [IconFolder 'data-transfer-both.svg'];
            app.ChangeDenoise.Icon          = [IconFolder 'grain-cog.svg'];
            app.DenoiseButton.Icon          = [IconFolder 'noise-toggle.svg'];
            app.ChangeNetwork.Icon          = [IconFolder 'brain-research.svg'];
            app.AIButton.Icon               = [IconFolder 'brain-bulb.svg'];
            app.CropButton.Icon             = [IconFolder 'crop.svg'];
            app.BoundaryTaper.Icon          = [IconFolder 'style-border.svg'];
            app.ChangeMethod.Icon           = [IconFolder 'tools.svg'];
            app.AutoParamsButton.Icon       = [IconFolder 'type.svg'];
            app.ActiveContours.Icon         = [IconFolder 'activity.svg'];
            app.EditResults.Icon            = [IconFolder 'mask-square.svg'];
            app.Colormap.Icon               = [IconFolder 'palette.svg'];
            app.ContourColor.Icon           = [IconFolder 'color-filter.svg'];
            app.ContourButton.Icon          = [IconFolder 'circle-dashed.svg'];
            app.TransposeButton.Icon        = [IconFolder 'transpose.svg'];
            app.PlotMajMin.Icon             = [IconFolder 'border-inner.svg'];
            app.ColorBar.Icon               = [IconFolder 'ruler.svg'];
            app.ViewNetResults.Icon         = [IconFolder 'view-grid.svg'];
            app.AuxiliaryView.Icon          = [IconFolder 'photo.svg'];
            app.Overwrite.Icon              = [IconFolder 'remove-square.svg'];
            app.DarkMode.Icon               = [IconFolder 'half-moon.svg'];
            app.ResetButton.Icon            = [IconFolder 'restart.svg'];
            app.InfoTool.Icon               = [IconFolder 'question-mark.svg'];
            app.BrowseButton.Icon           = [IconFolder 'folder.svg'];
            app.ExportButton.Icon           = [IconFolder 'save-floppy-disk.svg'];
        
            % Toolbar button tooltips
            app.Load.Tooltip                = 'Load Image';
            app.LoadMasks.Tooltip           = 'Load Masks';
            app.Batch.Tooltip               = 'Batch Process';
            app.InstantNav.Tooltip          = 'Instant Navigation';
            app.LeftArrow.Tooltip           = 'Previous Stack/File';
            app.RightArrow.Tooltip          = 'Next Stack/File';
            app.UpArrow.Tooltip             = 'Previous Slice';
            app.DownArrow.Tooltip           = 'Next Slice';
            app.AutoContrast.Tooltip        = 'Auto Contrast';
            app.ManualContrast.Tooltip      = 'Manual Contrast';
            app.TargetColor.Tooltip         = 'Target Color Transform';
            app.Square.Tooltip              = 'Square Image';
            app.LogScaleButton.Tooltip      = 'Log Scale';
            app.Inversion.Tooltip           = 'Image Inversion';
            app.DenoiseButton.Tooltip       = 'Denoise Image';
            app.ChangeDenoise.Tooltip       = 'Change Denoising Method';
            app.AIButton.Tooltip            = 'CNN Preprocessing';
            app.ChangeNetwork.Tooltip       = 'Change Network';
            app.CropButton.Tooltip          = 'Crop';
            app.BoundaryTaper.Tooltip       = 'Boundary Taper';
            app.ChangeMethod.Tooltip        = 'Change Segmentation Method';
            app.AutoParamsButton.Tooltip    = 'Auto Params';
            app.ActiveContours.Tooltip      = 'Active Contour';
            app.EditResults.Tooltip         = 'Adjust Mask';
            app.Colormap.Tooltip            = 'Colormap';
            app.ContourColor.Tooltip        = 'Contour Color';
            app.ContourButton.Tooltip       = 'Contour';
            app.TransposeButton.Tooltip     = 'Transpose';
            app.PlotMajMin.Tooltip          = 'Plot Major/Minor Axes';
            app.ColorBar.Tooltip            = 'Color Bar';
            app.ViewNetResults.Tooltip      = 'View Processing Steps';
            app.AuxiliaryView.Tooltip       = 'Auxiliary/Raw Image View';
            app.Overwrite.Tooltip           = 'Overwrite Mode';
            app.DarkMode.Tooltip            = 'Dark Mode';
            app.ResetButton.Tooltip         = 'Reset';
            app.InfoTool.Tooltip            = 'Help';
            app.BrowseButton.Tooltip        = 'Browse Save Location';
            app.ExportButton.Tooltip        = 'Export Data';
        
            % GUI Title page sizing and position adjustment - Source svg is 100 x 512
            W = 512;
            H = 100;
            % Cover image is segmentation example
            app.UIAxes.Position(4) = 0.8*app.UIAxes.Position(4);
            app.UIFigure.Icon      = [IconFolder 'guilogo.png']; 
            CoverImage             = imread("SegmentationSnapshot.png");
            imagesc(app.UIAxes, CoverImage)
            axis(app.UIAxes, 'image')
            
            % Creating the cover as a vector graphic html object and setting icon
            app.Cover                  = uihtml(app.AxesPanel, 'Position', [330 420 W H]);
            app.Cover.HTMLSource       = 'GUIRelated\SVGIcons\title.svg';
        end


        function SetMethodConfiguration(app)
            % SETMETHODCONFIGURATION will parse out the settings from an
            % engine plugin whenever the compute method/plugin changes.

            % Check fields of struct to enable flexible configurations
            ConfigFields = {'Name', 'Units', 'Min', 'Max', 'Value'};
            FieldCheck   = isfield(app.Engine.Plugin.controls, ConfigFields);

            % Setting the limits and compute stop step
            app.ComputeStopStepEditField.Limits = [1 app.Engine.nlayers];
            app.ComputeStopStepEditField.Value  = app.Engine.laststep;
        
            % Name of parameter
            if FieldCheck(1)
                app.SliderLabel_1.Text       = app.Engine.Plugin.controls(1).Name;
                app.SliderLabel_2.Text       = app.Engine.Plugin.controls(2).Name;
                app.SliderLabel_3.Text       = app.Engine.Plugin.controls(3).Name;
            end
        
            % Units to be used by the plugin
            if FieldCheck(2)
                app.TopSliderUnits.Text      = app.Engine.Plugin.controls(1).Units;
                app.MiddleSliderUnits.Text   = app.Engine.Plugin.controls(2).Units;
                app.BottomSliderUnits.Text   = app.Engine.Plugin.controls(3).Units;
            end
        
            % Setting lower bound of parameters' ranges
            if FieldCheck(3)
                app.smin(1)                  = app.Engine.Plugin.controls(1).Min;
                app.smin(2)                  = app.Engine.Plugin.controls(2).Min;
                app.smin(3)                  = app.Engine.Plugin.controls(3).Min;
                app.TopEditFieldMin.Value    = num2str(app.Engine.Plugin.controls(1).Min);
                app.MiddleEditFieldMin.Value = num2str(app.Engine.Plugin.controls(2).Min);
                app.BottomEditFieldMin.Value = num2str(app.Engine.Plugin.controls(3).Min);
            end
        
            % Setting upper bound of parameters' ranges
            if FieldCheck(4)
                app.smax(1)                  = app.Engine.Plugin.controls(1).Max;
                app.smax(2)                  = app.Engine.Plugin.controls(2).Max;
                app.smax(3)                  = app.Engine.Plugin.controls(3).Max;
                app.TopEditFieldMax.Value    = num2str(app.Engine.Plugin.controls(1).Max);
                app.MiddleEditFieldMax.Value = num2str(app.Engine.Plugin.controls(2).Max);
                app.BottomEditFieldMax.Value = num2str(app.Engine.Plugin.controls(3).Max);
            end
        
            % Labels for sliders
            if FieldCheck(5)
                if isempty(app.Segsettings) || isempty(app.Segsettings(app.Server.slice, app.Server.dim4idx))
                    % Loads default values if novel index
                    PVec = [app.Engine.Plugin.controls.Value];
                else
                    % Otherwise, previously used values loaded in
                    PVec = app.Segsettings(app.Server.slice, app.Server.dim4idx).Parameters;
                end

                % Slider Value labels
                app.TopSliderValueLabel.Text    = num2str(PVec(1));
                app.MiddleSliderValueLabel.Text = num2str(PVec(2));
                app.BottomSliderValueLabel.Text = num2str(PVec(3));
                
                % Slider values
                app.TopSlider.Value             = app.UpdateSlider(PVec(1), app.smin(1), app.smax(1));
                app.MiddleSlider.Value          = app.UpdateSlider(PVec(2), app.smin(2), app.smax(2));
                app.BottomSlider.Value          = app.UpdateSlider(PVec(3), app.smin(3), app.smax(3));
        
                for i = 1:(app.Engine.nlayers)
                    % Holds engine's default settings for reset as needed
                    try
                        app.defaults(i) = app.Engine.Plugin.controls(i).Value;
                    catch
                    end
                end
            end
        
            % Recursively loops through all graphical elements that have text to
            % ensure the appropriate interpreter and consistent styling is applied
            Check4LaTeX(app.UIFigure)
        
            % Sets the parameter values to defaults - ensures not passing by ref.
            app.params = app.defaults(:);

            % Checks for methods' auto params and whether it can be used
            app.AutoParamsButton.Enable = ~isfield(app.Engine.Plugin, 'AutoParams');
        end


        function SetExportSettings(app)
            % Getting export settings from edit field and selected formats
            Path = app.SaveFolderEditField.Value;
            
            % Masks and info saved to same folder
            app.Exportsettings.MaskPath = Path;
            app.Exportsettings.InfoPath = Path;

            % Mask file name and format
            app.Exportsettings.MaskName = app.FileNameEditField.Value;
            app.Exportsettings.MaskExt  = app.GetFormat(app.MaskformatListBox.Value);

            % Segmentation Info file name based off of mask file(s) name
            if contains(app.FileNameEditField.Value, 'Segmentation')
                app.Exportsettings.InfoName = [app.FileNameEditField.Value 'Info'];
            else
                app.Exportsettings.InfoName = [app.FileNameEditField.Value 'SegmentationInfo'];
            end

            % Segmentation Info file format 
            app.Exportsettings.InfoExt = app.GetFormat(app.ResultsformatListBox.Value);

            % Passing off export settings to the export module
            app.Exportmodule.SetExportSettings(app.Exportsettings, app.PropSettings)
        end


        function InitResultsStruct(app)
            % Initializes the struct object that holds segmentation info
            N                = app.Server.numslices;
            T                = app.Server.dim4;
            blank            = cell(N, T);
            app.Segsettings  = struct('FilePath', blank,...
                                      'Parameters', blank,...
                                      'method', blank,...
                                      'laststep', blank,...
                                      'inversion', false,...
                                      'square', false,...
                                      'logscale', false,...
                                      'denoisemethod', blank,...
                                      'ai', false, ...
                                      'networkPath', blank,...
                                      'snakes', false,...
                                      'snakesconfig', blank, ...
                                      'refinedmanually', false, ...
                                      'denoise', false, ...
                                      'resulttype', '');
        end


        function AdjustLoadedStruct(app)
            % ADJUSTLOADEDSTRUCT will initialize a struct to have loaded
            % results set into it
            N     = app.Server.numslices;
            T     = app.Server.dim4;
            blank = cell(N, T);
            S     = struct('FilePath', blank,...
                           'Parameters', blank,...
                           'method', blank,...
                           'laststep', blank,...
                           'inversion', false,...
                           'square', false,...
                           'logscale', false,...
                           'denoisemethod', blank,...
                           'ai', false, ...
                           'networkPath', blank,...
                           'snakes', false,...
                           'snakesconfig', blank, ...
                           'refinedmanually', false, ...
                           'denoise', false, ...
                           'resulttype', '');
            [nz, nt]        = size(app.Segsettings);
            S(1:nz, 1:nt)   = app.Segsettings;
            app.Segsettings = S;
        end


        function value = NewsSliderBound(app, value, parN)
            % Takes a new value to replace the current min or max value 
            % held in the edit field which will then be assigned as a
            % parameter bound
            % parN is index of the parameter/slider being referenced
            lb    = app.Engine.Plugin.controls(parN).Min;
            ub    = app.Engine.Plugin.controls(parN).Max;
            value = min(max(value, lb), ub);
        end


        function AutoSetParams(app)
            % AUTOSETPARAMS will have the engine estimate parameters to
            % segment the image. Sliders will then have their values
            % updated and segmentation as well as view update
            if ~isempty(app.Engine.Outputs) && any(app.Engine.Outputs{1}(:))
                % Prevents user from queuing changes as parameters are
                % approximated
                pb = uiprogressdlg(app.UIFigure, 'Title', 'Computing', ...
                                   'Indeterminate', 'on'); 
                drawnow
                app.Engine.AutoParams
                app.params = app.Engine.Params2Vec;
                app.UpdateAllSliders
                app.UpdateSegmentation(true)
                close(pb);
            end
        end
        

        function ResetFunction(app, newMethod)
            if nargin < 2
                % New Method flag true when changing methods
                newMethod = false;
            end

            % Running the config file without changing the graphical
            % settings ensures engine properties and axes settings are set
            % properly
            % Getting transpose and autocontrast button values to ensure
            % that the image appearance does not change when axes are
            % updated
            tFlag = logical(app.TransposeButton.State);
            cFlag = logical(app.AutoContrast.State);

            % Defaults are set to new values when another method is loaded, 
            % otherwise, grabs defaults and pushes them to engine
            if ~newMethod
                app.params = app.defaults;
                app.UpdateAllSliders
                app.GiveParam2Engine(1)
                app.GiveParam2Engine(2)
                app.GiveParam2Engine(3)
            end

            % When segmentation method is changed, values reset
            app.SetMethodConfiguration

            % Transpose and autocontrast maintained if they were on
            app.TransposeButton.State = tFlag;
            app.AutoContrast.State    = cFlag;
            
            % Redoes the segmentation with default values
            app.EngineSettings2SegData
            app.UpdateSegmentation(true)
        end

        function SliderMoved(app, idx)
            % SLIDERMOVED executes the series of function calls for the
            % sliders that are needed when the slider callback triggers.
            % This function also checks to see that the slider corresponds
            % to a parameter that is actively being used. If this is not
            % the case, the function exits to prevent uselesss latency.
            

            app.GiveParam2Engine(idx)

            % Will only perform updates when there is data
            if ~isempty(app.Engine.Outputs)
                app.UpdateSegmentation(true)
                app.EngineSettings2SegData
            end

            % Parameter changes overwrite any manual segmentation
            app.NoLongerManuallyRefined
        end


        function UpdateAllSliders(app)
            % Updates sliders and labels displaying the parameter values
            nParam = numel(app.params) >= [1 2 3];
            if nParam(1)
                app.TopSlider.Value             = app.UpdateSlider(app.params(1), app.smin(1), app.smax(1));
                app.TopSliderValueLabel.Text    = num2str(app.params(1), '%.1f');
            end
            if nParam(2)
                app.MiddleSlider.Value          = app.UpdateSlider(app.params(2), app.smin(2), app.smax(2));
                app.MiddleSliderValueLabel.Text = num2str(app.params(2), '%.1f');
            end
            if nParam(3)
                app.BottomSlider.Value          = app.UpdateSlider(app.params(3), app.smin(3), app.smax(3));
                app.BottomSliderValueLabel.Text = num2str(app.params(3), '%.1f');
            end
        end


        function UpdateSegmentation(app, new)
            % UPDATESEGMENTATION will either compute a new segmentation or load 
            % up a previous segmentation
            if nargin < 2
                new = false;
            end

            % Cannot segment when there is no image
            if ~app.Server.dataloaded
                return
            end

            % Getting appropriate indices
            sliceIdx = app.Server.slice;
            timeIdx  = app.Server.dim4idx;

            if new
                % New computation needed
                app.Engine.Compute
                app.UpdateResults
                
            elseif ~new && ~isempty(app.Engine.EngineVisualizer) && isvalid(app.Engine.EngineVisualizer(1).Figure)
                % Ensures visualizer updates properly
                app.Engine.Compute
                app.UpdateResults

            end

            % Always grabs latest results that are in original coordinates
            app.Visengine.Data = app.Exportmodule.Results{sliceIdx, timeIdx};

            % Updates visual components of GUI 
            app.UpdateAxes
            app.UpdateAllSliders
            app.UpdateWindowTitle
        end


        function ToggleEngineSettingsButtons(app, settingsStruct)
            % TOGGLEBUTTONS will toggle the buttons to reflect what pre and
            % post processing is being done on the image. This function is
            % called whenever the user navigates to a different slice or
            % timepoint and settings are different than what they were
            % previously for that given image. This is also called when the
            % user navigates to a previously segmented slice that has
            % different settings than what was used for the prior image
            app.Square.State                   = settingsStruct.square; 
            app.LogScaleButton.State           = settingsStruct.logscale;
            app.Inversion.State                = settingsStruct.inversion;
            app.DenoiseButton.State            = settingsStruct.denoise;
            app.AIButton.State                 = settingsStruct.ai;
            app.ActiveContours.State           = settingsStruct.snakes;
            app.ComputeStopStepEditField.Value = settingsStruct.laststep;
        end


        function Settings = QueryCurrentSettings(app)
            % QUERYCURRENTSETTINGS will check the settings used to produce
            % the current segmentation results.
            idx3     = app.Server.slice;
            idx4     = app.Server.dim4idx;
            Settings = app.Segsettings(idx3, idx4);
        end


        function CompareSettings(app, PreviousSlice, PreviousTime, forceChange)
            % COMPARESETTINGS will compare settings between the image the
            % user has navigated from to the image they have just navigated
            % to. If overwrite is off, this method will search through the
            % organized settings to see if the current image has been
            % segmented before and apply the settings that were saved for
            % the current index. Otherwise, if overwrite is on, the
            % ssettings that were used to segment the previously viewed
            % image will be applied to the currently indexed image.
            % This method provides the user flexibility to either have
            % completely different settings for segmentations across
            % indices or to always just use the latest settings.
            if nargin < 4
                forceChange = false;
            end

            % Getting slice and time indices for current image being viewed
            CurrentSlice  = app.Server.slice;
            CurrentTime   = app.Server.dim4idx;

            if app.Overwrite.State || forceChange
                % Fields to remove - will avoid overwriting manually edited
                % images and those with different file names (in case
                % multiple files are loaded in)
                fields   = {'FilePath', 'refinedmanually'};
    
                % Settings for newly loaded up image
                Settings = app.QueryCurrentSettings;
                Settings = rmfield(Settings, fields);
    
                % Settings for previously viewed image
                PrevSettings = app.Segsettings(PreviousSlice, PreviousTime);
                PrevSettings = rmfield(PrevSettings, fields);

                % Comparison of settings
                fn         = fieldnames(PrevSettings);
                changeFlag = false; 
                nNames     = numel(fn);
                for i = 1:nNames
                    fname  = fn{i};
                    Prev   = PrevSettings.(fname); 
                    Curr   = Settings.(fname);
                    if xor(isempty(Curr), isempty(Prev)) || forceChange
                        % Setting change occurs when something is enabled
                        % or disabled
                        changeFlag = true;
                        break
                    elseif isempty(Curr) && isempty(Prev)
                        % When both are empty, it indicates a setting is
                        % not enabled. Next setting can be observed
                        continue
                    end
                    
                    if ischar(Prev) || isstring(Prev) || ischar(Curr) || isstring(Curr) 
                        % Comparing plugins and denoise methods
                        changeFlag   = changeFlag | ~strcmp(Prev,Curr);
                    elseif ~isempty(Prev) && ~isempty(Curr)
                        % Comparing toggleable options and parameters
                        if isa(Prev, 'function_handle')
                            changeFlag = changeFlag | ~strcmp(char(Prev), char(Curr));
                        else
                            changeFlag = changeFlag | any(Prev~=Curr);
                        end
                    end
                end

                % Settings are loaded to be prepared for a segmentation
                % with the settings used by the previous image
                app.LoadSettingsIntoEngine(PreviousSlice, PreviousTime)
                app.EngineSettings2SegData

                % Will toggle buttons if needed
                app.ToggleEngineSettingsButtons(PrevSettings)
    
                % Ensures most up to date image is set in the engine
                app.Engine.newimage = true;
                app.Engine.SetImage(app.Server.Slice);
    
                % In case user really needs a recomputation (i.e. same image
                % but other settings changed)
                changeFlag = changeFlag | forceChange;
    
                % Will prepare the engine with the newly loaded up image
                app.UpdateSegmentation(changeFlag)
            else
                % Ensures most up to date image is set in the engine
                app.Engine.newimage = true;
                
                try
                    ResultsData = app.Exportmodule.Results{CurrentSlice, CurrentTime};
                catch
                    app.Exportmodule.Results{CurrentSlice, CurrentTime} = [];
                    ResultsData = [];
                end

                if ~isempty(ResultsData)
                    % Indicates successful segmentation and results can be
                    % loaded in
                    % Set current index settings as current settings
                    app.LoadSettingsIntoEngine(CurrentSlice, CurrentTime)
                    app.params = app.Engine.Params2Vec;

                    % Ensures proper GUI configuration for segmentation method 
                    Settings     = app.Segsettings(CurrentSlice, CurrentTime);
                    PrevSettings = app.Segsettings(PreviousSlice, PreviousTime);
                    MethodChange = ~strcmp(PrevSettings.method, Settings.method);
                    if MethodChange || app.loading
                        app.SetMethodConfiguration
                    end

                    app.ToggleEngineSettingsButtons(Settings)
                    % Set image after settings are acquired to ensure
                    % preprocessing
                    app.Engine.SetImage(app.Server.Slice);
                    app.UpdateSegmentation(false)
                else
                    % Set previous index settings as current settings
                    app.LoadSettingsIntoEngine(PreviousSlice, PreviousTime)
                    Settings = app.Segsettings(PreviousSlice, PreviousTime);
                    app.ToggleEngineSettingsButtons(Settings)
                    % Set image after settings are acquired to ensure
                    % preprocessing
                    app.Engine.SetImage(app.Server.Slice);
                    app.UpdateSegmentation(true)
                end
            end
        end


        function CheckEngineButtons(app)
            % CHECKENGINEBUTTONS will check the GUI for any buttons that
            % have been toggled on. These settings are then set in a struct
            % so that the engine can either update its settings or the
            % export module can have its data struct updated
            idx3 = app.Server.slice;
            idx4 = app.Server.dim4idx;
            app.Segsettings(idx3, idx4).square    = logical(app.Square.State);
            app.Segsettings(idx3, idx4).logscale  = logical(app.LogScaleButton.State);
            app.Segsettings(idx3, idx4).inversion = logical(app.Inversion.State);
            app.Segsettings(idx3, idx4).denoise   = logical(app.DenoiseButton.State);
            app.Segsettings(idx3, idx4).ai        = logical(app.AIButton.State);
            app.Segsettings(idx3, idx4).snakes    = logical(app.ActiveContours.State);
            app.Segsettings(idx3, idx4).laststep  = app.ComputeStopStepEditField.Value;
            % Ideally, would be nice to change toggle button colors if
            % active, but MATLAB does not currently have a way for us to do
            % this......
        end


        function LoadSettingsIntoEngine(app, sidx, tidx)
            % SETENGINESETTINGS will update settings in the engine to
            % reflect what the data struct of the settings shows in the
            % export module
            if ~app.Server.dataloaded
                return
            end

            % Autograbs indices
            if nargin < 2
                sidx = app.Server.slice;
            end
            if nargin < 3
                tidx = app.Server.dim4idx;
            end


            % Segmentation method update if loaded one differs
            methodName = app.Segsettings(sidx, tidx).method;
            if ~strcmp(methodName, app.Engine.method)
                app.Engine.SetComputeMethod(methodName)
            end

            % Parameter updates
            app.Engine.Vec2Params(app.Segsettings(sidx, tidx).Parameters);
            
            % Settings update
            app.Engine.square         = app.Segsettings(sidx, tidx).square;
            app.Engine.logscale       = app.Segsettings(sidx, tidx).logscale;
            app.Engine.inversion      = app.Segsettings(sidx, tidx).inversion;
            app.Engine.denoise        = app.Segsettings(sidx, tidx).denoise;
            app.Engine.ai             = app.Segsettings(sidx, tidx).ai;
            app.Engine.snakes         = app.Segsettings(sidx, tidx).snakes;
            app.Engine.laststep       = app.Segsettings(sidx, tidx).laststep;
            app.Engine.resulttype     = app.Segsettings(sidx, tidx).resulttype;
            if app.Segsettings(sidx, tidx).snakes && ~isempty(app.Segsettings(sidx, tidx).snakesconfig)
                app.Engine.snakesconfig = app.Segsettings(sidx, tidx).snakesconfig;
            end

            % Loading active contours settings if used
            if app.Segsettings(sidx, tidx).snakes
                if isempty(app.Segsettings(sidx, tidx).snakesconfig)
                    % Update the settings struct to have GUI config
                    app.InitActiveContours(sidx, tidx)
                else
                    % Update the edit fields for active contours
                    app.NumberofiterationsEditField.Value = app.Segsettings(sidx, tidx).snakesconfig.iterations;
                    app.SmoothnessEditField.Value         = app.Segsettings(sidx, tidx).snakesconfig.smoothness;
                    app.DenoiseFactorEditField.Value      = app.Segsettings(sidx, tidx).snakesconfig.factor;
                    app.ContractionBiasEditField.Value    = app.Segsettings(sidx, tidx).snakesconfig.contractionBias;
                    app.Engine.snakesconfig               = app.Segsettings(sidx, tidx).snakesconfig;
                end
            end

            % Enables active contours adjustments by forcing compute
            app.Engine.newimage = true;

            % In case visualization type changed per data format
            app.Visengine.Data = app.Exportmodule.Results{sidx, tidx};
            app.Visengine.AdjustVisualizationType

            % Adjust to hold current plot width value
            app.AdjustLineMarkerField
        end


        function AdjustLineMarkerField(app)
            % If the visualization type has changed, then field needs
            % an update
            app.MarkerWidthEditField.Value = app.Visengine.markerwidth;
            app.LineWidthEditField.Value   = app.Visengine.linewidth;
        end


        function InitActiveContours(app, sliceIdx, timeIdx)
            app.Segsettings(sliceIdx, timeIdx).snakesconfig.iterations      = app.NumberofiterationsEditField.Value;
            app.Segsettings(sliceIdx, timeIdx).snakesconfig.smoothness      = app.SmoothnessEditField.Value;
            app.Segsettings(sliceIdx, timeIdx).snakesconfig.factor          = app.DenoiseFactorEditField.Value;
            app.Segsettings(sliceIdx, timeIdx).snakesconfig.contractionBias = app.ContractionBiasEditField.Value;
        end


        function EngineSettings2SegData(app)
            % ENGINESETTINGS2SEGDATA will take the settings from the engine
            % and pass them to the segmentation data/info struct to be
            % recorded.

            % Gets current indices
            idx3 = app.Server.slice;
            idx4 = app.Server.dim4idx;

            % Extracting information from server and engine to pass to the
            % struct for the data export
            app.Segsettings(idx3, idx4).FilePath       = [app.Server.filefolder filesep app.Server.filename app.Server.fileext];
            app.Segsettings(idx3, idx4).method         = app.Engine.method;
            app.Segsettings(idx3, idx4).Parameters     = app.params;
            app.Segsettings(idx3, idx4).logscale       = app.Engine.logscale;
            app.Segsettings(idx3, idx4).inversion      = app.Engine.inversion;
            app.Segsettings(idx3, idx4).denoise        = app.Engine.denoise;
            app.Segsettings(idx3, idx4).denoisemethod  = char(app.Engine.denoisemethod);
            app.Segsettings(idx3, idx4).ai             = app.Engine.ai;
            app.Segsettings(idx3, idx4).snakes         = app.Engine.snakes;
            app.Segsettings(idx3, idx4).laststep       = app.Engine.laststep;
            app.Segsettings(idx3, idx4).resulttype     = app.Visengine.vistype;

            % Active contours configuration comes from the settings
            % tab if Active contours used
            if app.ActiveContours.State
                app.Segsettings(idx3, idx4).snakesconfig = app.Engine.snakesconfig;
            else
                app.Segsettings(idx3, idx4).snakesconfig = [];
            end

            % Grabs AI settings if enabled
            app.Segsettings(idx3, idx4).ai = app.Engine.ai;
            if app.AIButton.State && ~isempty(app.Engine.Network)
                app.Segsettings(idx3, idx4).networkPath = app.Engine.networkPath;
            else
                app.Segsettings(idx3, idx4).networkPath = [];
            end
        end


        function UpdateResults(app)
            % UPDATERESULTS will update the mask and points kept in the
            % export module whenever a new segmentation is conducted
            % Computes mask result from the contour if there is a
            % contour, otherwise the mask remains blank
            idx3 = app.Server.slice;
            idx4 = app.Server.dim4idx;

            % Queries data from engine and adjusts it to be in original
            % coordinate system
            Results = app.Engine.Outputs{app.Engine.laststep+1};
            app.Engine.DetermineResultType
            if ~isempty(Results)
                if any(strcmp(app.Engine.resulttype, {'pointcloud', 'contour'}))
                    xc           = app.Server.cropcols(1);
                    yc           = app.Server.croprows(1);
                    Results(:,1) = Results(:,1) + xc - 1;
                    Results(:,2) = Results(:,2) + yc - 1;
                elseif any(strcmp(app.Engine.resulttype, {'mask', 'label'}))
                    dtype   = underlyingType(Results);
                    sz      = [app.Server.mrows app.Server.ncols];
                    Z       = zeros(sz, dtype);
                    y       = app.Server.croprows(1):app.Server.croprows(2);
                    x       = app.Server.cropcols(1):app.Server.cropcols(2);
                    Z(y, x) = Results;
                    Results = Z;
                else
                    error(['Pipeline result output is not compatible with' ...
                        [' this GUI, please alter your algorithm to produce' ...
                        'any of the following: '] newline...
                        'pointclouds' newline ...
                        'contours' newline ...
                        'masks' newline...
                        'label matrices'])
                end
            end
            app.Exportmodule.UpdateData(Results, idx3, idx4)
            
            % Passes settings over to the Segdata property
            app.EngineSettings2SegData
        end


        function SegMethod = GetParametricModeName(app)
            % GETPARAMETRICMODENAME will construct a string with the name
            % of the computational method being used
            if app.ActiveContours.State
                suffix = ' + Active Contours';
            else
                suffix = '';
            end
            % Gives method name as well as whether or not active contours
            % were used
            SegMethod = [app.Engine.method ' Method' suffix];
        end


        function UpdateWindowTitle(app, methodName)
            % UPDATEWINDOWTITLE will update the title of the GUI window to
            % display the full filename and method used for the
            % segmentation
            if nargin < 2
                methodName = app.GetParametricModeName;
            end
            % Setting title of axes
            if ~isempty(app.Server.filename)
                FileName = [app.Server.filename app.Server.fileext ' - '];
            else
                FileName = [];
            end
            app.UIFigure.Name = [FileName methodName];
        end
        

        function InitData4NewImage(app)
            % Initializes the data struct so it can be updated as the user
            % processes slices
            z = app.Server.numslices;
            t = app.Server.dim4;

            % Clear old data
            app.Exportmodule.ResetData
            app.Segsettings = [];

            % Initialize the new data structure
            app.Exportmodule.InitData(z, t)
            app.InitResultsStruct
        end
        

        function AdjustButtonFunctionality(app)
            % Disables contrast options and colorbar but enables the color
            % target transform for RGB images. Opposite for grayscale
            if ~app.Server.dataloaded
                return
            end
            % If visualizing color or grayscale data
            if app.Visengine.isrgb
                app.AutoContrast.Enable   = 'off';
                app.ManualContrast.Enable = 'off';
                app.Colormap.Enable       = 'off';
                app.ColorBar.Enable       = 'off';
                % Turns off colorbar and autocontrast and deletes the
                % colorbar if it exists
                if app.ColorBar.State
                    app.ColorBar.State      = false;
                    app.ColorBarClicked
                    app.AutoContrast.State  = false;
                    app.AutoContrast.Enable = false;
                end
            else
                app.AutoContrast.Enable   = 'on';
                app.ManualContrast.Enable = 'on';
                app.Colormap.Enable       = 'on';
                app.ColorBar.Enable       = 'on';
            end

            % Allows a color distance transform if the image is RGB
            if app.Engine.isRGB
                app.TargetColor.Enable    = 'on';
            else
                app.TargetColor.Enable    = 'off';
            end

            % Turns time navigation on if timepoints are available
            if app.Server.istimenavigable
                app.LeftArrow.Enable  = 'on';
                app.RightArrow.Enable = 'on';
                timeFlag              = true;
            else
                app.LeftArrow.Enable  = 'off';
                app.RightArrow.Enable = 'off';
                timeFlag              = false;
            end

            % Turns stack navigation on if timepoints are available
            if app.Server.isstacknavigable
                app.UpArrow.Enable    = 'on';
                app.DownArrow.Enable  = 'on';
                stackFlag             = true;
            else
                app.UpArrow.Enable    = 'off';
                app.DownArrow.Enable  = 'off';
                stackFlag             = false;
            end

            % Nav. of higher dimensions requires their size to be > 1
            if ~timeFlag && ~stackFlag
                app.InstantNav.Enable = 'off';
                app.Batch.Enable      = 'off';
            else
                app.InstantNav.Enable = 'on';
                app.Batch.Enable      = 'on';
            end
        end        
        

        function ActiveContoursSettingsToggle(app)
            % ACTIVECONTOURSETTINGSTOGGLE will toggle the active contours'
            % edit fields to be enabled/disabled depending on the state of
            % the active contours button
            app.NumberofiterationsEditField.Enable = app.ActiveContours.State;
            app.SmoothnessEditField.Enable         = app.ActiveContours.State;
            app.DenoiseFactorEditField.Enable      = app.ActiveContours.State;
            app.ContractionBiasEditField.Enable    = app.ActiveContours.State;
        end

        
        function ChangeGUIMode(app)
            Obj  = app.UIFigure;
            flag = app.DarkMode.State;
            ChangeObjColor(Obj, flag)
            % The modeflag is whether to have dark mode or light mode
            function ChangeObjColor(obj, flag)
                ChildCheck = isprop(obj, 'Children');
                if flag
                    BColor = [0.15 0.15 0.15];
                    FColor = [1 1 1];
                else
                    BColor = [1 1 1];
                    FColor = [0 0 0];
                end
                if ChildCheck
                    Children     = obj.Children;
                    N_Containers = numel(Children);
                    for i = 1:N_Containers
                        Child = Children(i);
                        if isprop(Child, 'BackgroundColor') && ~isa(Child, 'matlab.ui.container.Toolbar')
                            Child.BackgroundColor = BColor;
                        end
                        if isprop(Child, 'FontColor') && ~isa(Child, 'matlab.ui.container.Tab') && ~isa(Child, 'matlab.ui.container.TabGroup')
                            Child.FontColor = FColor;
                        end
                        if isprop(Child, 'ForegroundColor') && ~isa(Child, 'matlab.ui.container.Tab') && ~isa(Child, 'matlab.ui.container.TabGroup')
                            Child.ForegroundColor = FColor;
                        end
                        ChangeObjColor(Child, flag)
                    end
                end
            end
            IconFolder = ['Visuals' filesep 'SVGIcons' filesep];
            if ~app.DarkMode.State
                app.UIAxes.Title.Color = [0 0 0];
                app.UIAxes.Color       = [1 1 1];
                app.ExportButton.Icon  = [IconFolder 'save-floppy-disk.svg'];
                app.BrowseButton.Icon  = [IconFolder 'folder.svg'];
                if ~isempty(app.cbar) && isvalid(app.cbar)
                    app.cbar.Color = [0 0 0];
                end
                if ~isempty(app.Cover)
                    app.Cover.HTMLSource = [IconFolder 'title.svg'];
                end
            else
                % Icons that are directly atop the dark background need to
                % be changed to white icons
                app.UIAxes.Title.Color = [1 1 1];
                app.UIAxes.Color       = [.15 .15 .15];
                app.ExportButton.Icon  = [IconFolder 'save-floppy-disk-white.svg'];
                app.BrowseButton.Icon  = [IconFolder 'folder-white.svg'];
                if ~isempty(app.cbar) && isvalid(app.cbar)
                    app.cbar.Color = [1 1 1];
                end
                if ~isempty(app.Cover)
                    app.Cover.HTMLSource = [IconFolder 'title_white.svg'];
                end
            end
        end


        function UpdateAxes(app)
            % Updates axes title and which data 
            app.UpdateAxesTitle
            % Will either plot the original image or the preprocessed
            % image
            if app.AuxiliaryView.State
                Img = app.Engine.Outputs{1};
            else
                Img = app.Server.Slice;
            end

            % Will plot the transpose if transpose switch is on
            app.Visengine.transposed = app.TransposeButton.State;
            app.Visengine.UpdateImagePlot(Img)
            app.AdjustButtonFunctionality

            % In case an image needs contrast updated
            if app.AutoContrast.State
                app.AutoContrastClicked
            end

            % Updates engine visualizer if open
            app.Engine.UpdateEngineVisualizer
        end


        function UpdateAxesTitle(app)
            % Will change the title of the window to include the slice
            % number when the slice changes
            name = [];
            if app.Server.numslices > 1 % isnumeric(app.Server.slice) &&
                count = num2str(app.Server.slice);
                name  = [name 'Slice: ' count '/' num2str(app.Server.numslices)];
            end

            if app.Server.dim4 > 1 % isnumeric(app.Server.dim4idx) && 
                TP    = num2str(app.Server.dim4idx);
                name  = [name ' Frame: ' TP '/' num2str(app.Server.dim4)];
            end

            % Rather than using
            app.UIAxes.Title.String      = name;
            app.UIAxes.Title.Interpreter = 'none';
            app.UIAxes.Title.FontName    = app.fontname;
            app.UIAxes.Title.FontWeight  = "normal";
            app.UIAxes.Title.FontSize    = 16;
        end


        function GiveParam2Engine(app, n)
            % Passes parameter values from the GUI to the engine
            if ~isempty(app.params)
                app.Engine.SetParameters(n, 'Value', app.params(n))
            end
        end


        function HelpInstruction(app)
            % HTML Launch with instructions for user
            if isempty(app.helpWindow) || ~isvalid(app.helpWindow)
                pos                       = [128 128 640 640];
                hfig                      = uifigure('Name', 'GUI Instructions', 'Position', pos);
                hfig.Icon                 = ['SVGIcons' filesep 'question-mark.png'];
                app.helpWindow            = uihtml(hfig, 'Position', [32 32 576 576]);
                filepath                  = mfilename("fullpath");
                pathname                  = fileparts(filepath);
                pathname                  = [pathname filesep 'Visuals' filesep];
                app.helpWindow.HTMLSource = [pathname 'Help.html'];
            end
        end

        
        function BatchProcess(app)
            % BATCHPROCESS will process all the data contained within the 
            focus(app.UIFigure)
            % First, need to determine if user has set color target, taper,
            % crop, etc. Otherwise, just prompt them to do so first
            listOfSettings = ['- Log scaling' newline ...
                              '- Inversion' newline...
                              '- Denoising' newline...
                              '- CNN' newline...
                              '- Crop' newline...
                              '- Segmentation Method' newline...
                              '- Parameter values'];
            % Color target is a setting to double check if the image is RGB
            if app.Engine.isRGB
                listOfSettings = ['- Color Target' newline listOfSettings];
            end
            % Prompt for user
            questMsg = ['Have you set the following accordingly prior to batch processing?'...
                              newline listOfSettings];
            answer   = uiconfirm(app.UIFigure, questMsg, 'Check Settings', 'DefaultOption', 'Yes', 'Options', {'Yes', 'No'});
            if ~strcmp(answer, 'Yes')
                app.Batch.State = 'off';
                return
            end

            % Ensures settings from the image currently being viewed are
            % applied to all the images being batch processed
            PrevSlice = app.Server.slice;
            PrevTime  = app.Server.dim4idx;

            % Loops across third and fourth dimensions
            tpts    = app.Server.dim4;
            try
                % Looping over all slices and timepoints
                for t = PrevTime:tpts
                    zslices = app.Server.numslices;
                    for z = PrevSlice:zslices
                        app.Server.Read(z, t);
                        app.CompareSettings(PrevSlice, PrevTime, true)
                        % Drawnow will update the button values to ensure
                        % the user can cancel

                        % Drawing / refreshing the graphics
                        if app.VisualFeedback.Value
                            drawnow
                        else
                            msg = ['Processing images...' ...
                                   newline ...
                                   'Slice: ' num2str(z) '/' ...
                                   num2str(zslices)...
                                   newline ...
                                   'Timepoint: ' num2str(t) '/' ...
                                   num2str(tpts)];
                            disp(msg)
                        end

                        % Will stop batch processing if the user has turned
                        % off the batch processing
                        if ~app.Batch.State
                            break
                        end
                    end
                    if ~app.Batch.State
                        break
                    end
                end
            catch
            end
            
            % Updates user if batch processing stopped or done
            if app.Batch.State
                uialert(app.UIFigure, 'Data done processing', 'Success', 'Icon','success')
                app.Batch.State = 'off';
            else
                uialert(app.UIFigure, 'Batch processing stopped', 'Processing Stopped', 'Icon','warning')
            end
        end


        function ImageCheck(app)
            % IMAGECHECK will turn buttons on/off depending on whether an
            % image has been loaded in. It is called upon startup to turn
            % buttons off since the user has yet to load an image and then
            % checks to see after either the load image button is pressed
            % or the SetImage method is called to update the image data
            % that is loaded in
            dataloaded                  = app.Server.dataloaded;
            if dataloaded && ~isempty(app.Cover)
                app.Cover.delete
                app.Cover = [];
            end
            app.InstantNav.Enable       = dataloaded;
            app.LeftArrow.Enable        = dataloaded;
            app.RightArrow.Enable       = dataloaded;
            app.UpArrow.Enable          = dataloaded;
            app.DownArrow.Enable        = dataloaded;
            app.Batch.Enable            = dataloaded;
            app.AutoContrast.Enable     = dataloaded;
            app.ManualContrast.Enable   = dataloaded;
            app.TargetColor.Enable      = dataloaded;
            app.Square.Enable           = dataloaded;
            app.LogScaleButton.Enable   = dataloaded;
            app.Inversion.Enable        = dataloaded;
            app.DenoiseButton.Enable    = dataloaded;
            app.ChangeDenoise.Enable    = dataloaded;
            app.AIButton.Enable         = dataloaded;
            app.ChangeNetwork.Enable    = dataloaded;
            app.CropButton.Enable       = dataloaded;
            app.BoundaryTaper.Enable    = dataloaded;
            app.AutoParamsButton.Enable = dataloaded;
            app.ActiveContours.Enable   = dataloaded;
            app.EditResults.Enable      = dataloaded;
            app.ColorBar.Enable         = dataloaded;
            app.AuxiliaryView.Enable    = dataloaded;
            app.ViewNetResults.Enable   = dataloaded;
        end

        
        function VisualizationTypeButtonCheck(app)
            % VISUALIZATIONTYPECHECK will check to see what the computed
            % results look like in order to adjust button functionality
            switch app.Visengine.vistype
                case 'contour'
                    app.ActiveContours.Enable = true;
                    app.EditResults.Enable    = true;
                    app.PlotMajMin.Enable     = true;
                case 'pointcloud'
                    app.ActiveContours.Enable = false;
                    app.EditResults.Enable    = true;
                    app.PlotMajMin.Enable     = true;
                case 'mask'
                    app.ActiveContours.Enable = true;
                    app.EditResults.Enable    = true;
                    app.PlotMajMin.Enable     = true;
                case 'label'
                    app.ActiveContours.Enable = false;
                    app.EditResults.Enable    = false;
                    app.PlotMajMin.Enable     = false;
            end
        end


        function NoLongerManuallyRefined(app)
            % NOLONGERMANUALLYREFINED is called upon parameter adjustment
            % since recomputing a segmentation will result in any preceding
            % manual adjustments to be overwritten.
            % Manual edit overwritten
            sliceIdx = app.Server.slice;
            timeIdx  = app.Server.dim4idx;
            app.Segsettings(sliceIdx, timeIdx).refinedmanually = false;
        end
    end


    methods (Access = public)
        function LoadResults(app, maskStruct)
            if nargin < 2
                maskStruct = [];
            end
            pdlg = uiprogressdlg(app.UIFigure, "Indeterminate","on", "Title", 'Selecting Data');

            % Prompts user to select file with previous settings / results
            app.loading         = app.Exportmodule.LoadResults(maskStruct, pdlg);
            if app.loading
                % Prevents overwriting of previous results
                app.Overwrite.State = false;
    
                % Updates visualization and sets the new data
                app.CompareSettings(app.Server.slice, app.Server.dim4idx)
            end
            pdlg.delete
        end


        function SetImage(app, Im)
            % Allows the user to set image directly from command line as
            % well
            if nargin < 2
                Im = [];
            end

            if size(Im, 3) > 3
                app.Server.SetLazyLoading(false)
            end

            % Passes array to the server
            app.Server.LoadImage(Im)
            app.Visengine.SetImageCrop(app.Server.croprows, app.Server.cropcols)
            
            % Sets the new image bounds in the export module
            yxsz = [app.Server.mrowscrop app.Server.ncolscrop];
            app.Exportmodule.SetImageBounds(yxsz)

            app.Engine.SetImage(app.Server.Slice)
            app.InitData4NewImage
            app.UpdateSegmentation(true)
            app.Visengine.UpdateAxesLimits(app.Server.croprows, app.Server.cropcols)
            app.AdjustButtonFunctionality
        end

        function UpdateParameterValue(app, slider, paramidx)
            % UPDATEPARAMETERVALUE will take a ui slider, extract its
            % value, and then compute the desired parameter value for the
            % slider and assign it to a desired index of the parameter 
            % vector of the GUI class
            % Parsing values to be used
            minv = app.smin(paramidx);
            maxv = app.smax(paramidx);
            % Parameter value based off of the slider and edit fields
            beta  = slider.Value/100;
            value = beta*maxv + (1 - beta)*minv;
            % Assignment of update
            app.params(paramidx) = value;
        end
    end


    methods(Static)
        function ext = GetFormat(value)
            % Returns the file extension corresponding to a check box with
            % a given file extension to ensure proper data formatting in
            % export

            % Grabs the name of the extension by assuming the actual
            % file extension is between parentheses
            idx1 = regexp(value, '(') + 1;
            idx2 = regexp(value, ')') - 1;
            ext  = value(idx1:idx2);
        end


        function value = UpdateSlider(paramval, slidermin, slidermax)
            % Updates slider position based on current param value and edit
            % field values - recomputed when the min or max values change
            % Includes bound checking
            value = ((paramval - slidermin) / (slidermax - slidermin))*100;
            value = min(100, value);
            value = max(0, value);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Image)
            % GUI visibility off to allow centering
            app.UIFigure.Visible = "off";

            % Creating a waitbar for user to see launch progress
            wb = waitbar(0, 'Loading GUI');

            % Initializes the paths needed for the GUI to run
            InitPaths

            % GUI Configuration: Icons, graphical settings, compute engine 
            % class, image server class, export module class
            % Custom config can be defined by user as well
            % if exist('GUIConfig_Custom.m', 'file') == 5
            %     GUIConfig_Custom(app)
            % else
            %     GUIConfig_Default(app)
            % end

            % Moved away from above code to instead have the function
            % encapsulated here - might consider changing the class
            % instantiation to occur outside the GUI since the compute
            % class, visualization class, and export module are really the
            % ones that should be modular. GUI graphical settings like
            % icons are highly unlikely to change.

            % Sets up classes required in GUI
            app.InstantiateClasses
            % Sets graphical settings - to be externalized later
            app.GraphicalSettings

            % Sets configuration of sliders, bounds, and text
            app.SetMethodConfiguration

            % Update waitbar message - GUI configured
            waitbar(0.25, wb, 'GUI Configured')
            
            % Centering of the GUI window
            centerfig(app.UIFigure)

            % Ensures user cannot interact with cover image in axes
            disableDefaultInteractivity(app.UIAxes)

            % Launches warning for users that may be missing toolboxes
            Check4ToolBoxes

            % Update waitbar message - GUI configured
            waitbar(0.5, wb, 'Toolboxes checked')

            if nargin == 2
                % In case user passes image from workspace
                app.Server.LoadImage(Image)
                app.Visengine.SetImageCrop(app.Server.croprows, app.Server.cropcols)

                % Sets the new image bounds in the export module
                yxsz = [app.Server.mrowscrop app.Server.ncolscrop];
                app.Exportmodule.SetImageBounds(yxsz)
                
                % Inits the data struct for results
                app.InitData4NewImage

                % Resizing since html above axes is now deleted
                app.UIAxes.Position(4) = 1.25*app.UIAxes.Position(4);

                % Server parsed image slice (reduces dimensionality to 2D)
                app.Engine.SetImage(app.Server.Slice)

                % Ensures computation from the first step
                app.GiveParam2Engine(1)
                % Always starts off with contour on
                app.ContourButton.State = 'On';
                app.UpdateSegmentation(true)

                % Default file path for results
                app.SaveFolderEditField.Value = app.Server.filefolder;
                app.FileNameEditField.Value   = [app.Server.filename '_Segmentation']; 

                enableDefaultInteractivity(app.UIAxes)

                % Ensures appropriate buttons are on/off
                app.AdjustButtonFunctionality
            end

            % Checks to see if an image is loaded and enables/disables
            % buttons as necessary
            app.ImageCheck

            % Update waitbar message - Image check done
            waitbar(0.75, wb, 'Image check done')

            % Sets the callback interrupt and busy action the same for all
            % components
            SetAppInterrupt(app.UIFigure, true, 'queue')

            % Final waitbar message
            waitbar(0.75, wb, 'Launching GUI')
            pause(0.1)
            wb.delete

            % Adjusting alignment for parameter name labels
            app.SliderLabel_1.HorizontalAlignment = 'right';
            app.SliderLabel_2.HorizontalAlignment = 'right';
            app.SliderLabel_3.HorizontalAlignment = 'right';

            % Toggles the active contours buttons off upon startup
            app.ActiveContoursSettingsToggle

            % Init with darkmode
            app.ChangeGUIMode

            % Make GUI visible
            app.UIFigure.Visible = "on";
        end

        % Value changed function: TopEditFieldMin
        function TopEditFieldMinValueChanged(app, event)
            value                        = str2double(app.TopEditFieldMin.Value);
            value                        = app.NewsSliderBound(value, 1);
            app.smin(1)                  = value;
            app.TopEditFieldMin.Value    = num2str(app.smin(1));
            oldVal                       = app.params(1);
            app.params(1)                = max(app.smin(1), ...
                                           min(app.params(1), app.smax(1)));
            app.TopSlider.Value          = app.UpdateSlider(app.params(1), app.smin(1), app.smax(1));
            app.TopSliderValueLabel.Text = num2str(app.params(1), '%.1f');
            if oldVal ~= app.params(1)
                % In case new bounds cause the actual param value to change
                app.TopSliderValueChanged
            end
        end

        % Value changed function: TopEditFieldMax
        function TopEditFieldMaxValueChanged(app, event)
            value                        = str2double(app.TopEditFieldMax.Value);
            value                        = app.NewsSliderBound(value, 1);
            app.smax(1)                  = value;
            app.TopEditFieldMax.Value    = num2str(app.smax(1));
            oldVal                       = app.params(1);
            app.params(1)                = max(app.smin(1), ...
                                           min(app.params(1), app.smax(1)));
            app.TopSlider.Value          = app.UpdateSlider(app.params(1), app.smin(1), app.smax(1));
            app.TopSliderValueLabel.Text = num2str(app.params(1), '%.1f');
            if oldVal ~= app.params(1)
                % In case new bounds cause the actual param value to change
                app.TopSliderValueChanged
            end
        end

        % Value changed function: MiddleEditFieldMin
        function MiddleEditFieldMinValueChanged(app, event)
            value                           = str2double(app.MiddleEditFieldMin.Value);
            value                           = app.NewsSliderBound(value, 2);
            app.smin(2)                     = value;
            app.MiddleEditFieldMin.Value    = num2str(app.smin(2));
            oldVal                          = app.params(2);
            app.params(2)                   = max(app.smin(2), ...
                                              min(app.params(2), app.smax(2)));
            app.MiddleSlider.Value          = app.UpdateSlider(app.params(2), app.smin(2), app.smax(2));
            app.MiddleSliderValueLabel.Text = num2str(app.params(2), '%.1f');
            if oldVal ~= app.params(2)
                % In case new bounds cause the actual param value to change
                app.MiddleSliderValueChanged
            end
        end

        % Value changed function: MiddleEditFieldMax
        function MiddleEditFieldMaxValueChanged(app, event)
            value                           = str2double(app.MiddleEditFieldMax.Value);
            value                           = app.NewsSliderBound(value, 2);
            app.smax(2)                     = value;
            app.MiddleEditFieldMax.Value    = num2str(app.smax(2));
            oldVal                          = app.params(2);
            app.params(2)                   = max(app.smin(2), ...
                                              min(app.params(2), app.smax(2)));
            app.MiddleSlider.Value          = app.UpdateSlider(app.params(2), app.smin(2), app.smax(2));
            app.MiddleSliderValueLabel.Text = num2str(app.params(2), '%.1f');
            if oldVal ~= app.params(2)
                % In case new bounds cause the actual param value to change
                app.MiddleSliderValueChanged
            end
        end

        % Value changed function: BottomEditFieldMin
        function BottomEditFieldMinValueChanged(app, event)
            value                           = str2double(app.BottomEditFieldMin.Value);
            value                           = app.NewsSliderBound(value, 3);
            app.smin(3)                     = value;
            app.BottomEditFieldMin.Value    = num2str(app.smin(3));
            oldVal                          = app.params(3);
            app.params(3)                   = max(app.smin(3), ...
                                              min(app.params(3), app.smax(3)));
            app.BottomSlider.Value          = app.UpdateSlider(app.params(3), app.smin(3), app.smax(3));
            app.BottomSliderValueLabel.Text = num2str(app.params(3), '%.1f');
            if oldVal ~= app.params(3)
                % In case new bounds cause the actual param value to change
                app.BottomSliderValueChanged
            end
        end

        % Value changed function: BottomEditFieldMax
        function BottomEditFieldMaxValueChanged(app, event)
            value                           = str2double(app.BottomEditFieldMax.Value);
            value                           = app.NewsSliderBound(value, 3);
            app.smax(3)                     = value;
            app.BottomEditFieldMax.Value    = num2str(app.smax(3));
            oldVal                          = app.params(3);
            app.params(3)                   = max(app.smin(3), ...
                                              min(app.params(3), app.smax(3)));
            app.BottomSlider.Value          = app.UpdateSlider(app.params(3), app.smin(3), app.smax(3));
            app.BottomSliderValueLabel.Text = num2str(app.params(3), '%.1f');
            if oldVal ~= app.params(3)
                % In case new bounds cause the actual param value to change
                app.BottomSliderValueChanged
            end
        end

        % Value changed function: TopSlider
        function TopSliderValueChanged(app, event)
            % Updates the parameter but won't trigger computation unless an
            % image is loaded
            if app.Engine.StepPastStop(1)
                return
            end
            app.UpdateParameterValue(app.TopSlider, 1);
            app.TopSliderValueLabel.Text = num2str(app.params(1), '%.1f');
            app.SliderMoved(1)
        end

        % Value changed function: MiddleSlider
        function MiddleSliderValueChanged(app, event)
            % Updates the parameter but won't trigger computation unless an
            % image is loaded
            if app.Engine.StepPastStop(2)
                return
            end
            app.UpdateParameterValue(app.MiddleSlider, 2);
            app.MiddleSliderValueLabel.Text = num2str(app.params(2), '%.1f');
            app.SliderMoved(2)
        end

        % Value changed function: BottomSlider
        function BottomSliderValueChanged(app, event)
            % Updates the parameter but won't trigger computation unless an
            % image is loaded
            if app.Engine.StepPastStop(3)
                return
            end
            app.UpdateParameterValue(app.BottomSlider, 3);
            app.BottomSliderValueLabel.Text = num2str(app.params(3), '%.1f');
            app.SliderMoved(3)
        end

        % Callback function: Load
        function LoadClicked(app, event)
            focus(app.UIFigure)
            
            % The first load is when there is no data loaded - this check
            % is done prior to the data loading call from the server in
            % order to get original state. If the user exits from the data
            % loading window, the state remains unchanged.
            firstload = ~app.Server.dataloaded;

            % In case new image is loaded while engine visualizer is open,
            % the engine visualizing engine is brought to foreground before
            % the main GUI gets brought to front
            if ~isempty(app.Engine.EngineVisualizer) && isvalid(app.Engine.EngineVisualizer(1).Figure)
                figure(app.Engine.EngineVisualizer(1).Figure)
            end

            % Compares the old and new file names
            oldfid = app.Server.fullFID;
            app.Server.LoadImage;
            newfid = app.Server.fullFID;

            % Exits function if no new data was loaded in
            if ~app.Server.dataloaded || strcmp(oldfid, newfid)
                return
            end


            % Sets the crop for the visualization engine
            app.Visengine.SetImageCrop(app.Server.croprows, app.Server.cropcols)


            % Initializes the data structures to hold results and settings
            app.InitData4NewImage

            % Sets the new image bounds in the export module
            yxsz = [app.Server.mrowscrop app.Server.ncolscrop];
            app.Exportmodule.SetImageBounds(yxsz)

            % Enables/disables buttons depending on image type (RGB/gs)
            app.ImageCheck

            if firstload
                % Resizing since html above axes is now deleted
                app.UIAxes.Position(4) = 1.25*app.UIAxes.Position(4);
            end

            % Empties results and recomputes from first step
            app.GiveParam2Engine(1)
            
            % Always starts with contour on
            app.ContourButton.State  = 'on';

            % Clearing previous RGB image and setting new image in
            % engine
            app.Engine.RGB = [];
            app.Engine.SetImage(app.Server.Slice)
            app.UpdateSegmentation(true)

            % Plotting image
            app.Visengine.UpdateAxesLimits(app.Server.croprows, app.Server.cropcols)

            % Default file path for results
            app.SaveFolderEditField.Value = app.Server.filefolder;
            app.FileNameEditField.Value   = [app.Server.filename '_Segmentation']; 

            enableDefaultInteractivity(app.UIAxes)
            enableDefaultInteractivity(app.Visengine.UIAxes2)

            % Ensures appropriate buttons are on/off
            app.AdjustButtonFunctionality

            % Initializing the export settings
            app.SetExportSettings

            % Setting title of axes
            app.UpdateWindowTitle

            % Adjusting axes limits and inner position to match
            axis(app.UIAxes, 'image')
            app.Visengine.UIAxes2.InnerPosition = app.Visengine.UIAxes.InnerPosition;
        end

        % Callback function: LoadMasks
        function LoadMasksClicked(app, event)
            % Allows user to load up previous segmentation results
            focus(app.UIFigure)
            app.LoadResults
            figure(app.UIFigure)
        end

        % Callback function: InstantNav
        function InstantNavClicked(app, event)
            % Takes the focus off of the button so the button doesn't
            % appear clicked
            focus(app.UIFigure)
            % Allows for instant navigation of the data that's loaded into
            % the image server. User can specify the desired slice and
            % stack/timepoint/file
            if ~app.Server.dataloaded
                return
            end

            % Old indices prior to navigation - used to compare
            % settings
            oldz = app.Server.slice;
            oldt = app.Server.dim4idx;
            
            % Request userinput for slice and frame number
            idx = app.Server.SetIndicesPrompt;
            if isempty(idx)
                return
            end
            app.Server.Read(idx(1), idx(2))

            % Sets newly requested 2D image, compares segmentation 
            % settings used, then passes the settings to the export
            % module.
            app.Engine.SetImage(app.Server.Slice)
            app.CompareSettings(oldz, oldt)
            app.EngineSettings2SegData
        end

        % Callback function: LeftArrow
        function LeftArrowClicked(app, event)
            if exist('event', 'var')
                % Focus is slow and only needs to occur when the
                % buttonpress event occurs
                focus(app.UIFigure)
            end

            % Return when there is no data or fourth dimension
            if ~app.Server.dataloaded || ~app.Server.istimenavigable
                return
            end

            % Direction is negative (approaches start of imds or time)
            direction = -1;
            newt      = app.Server.dim4idx + direction;
            if app.Server.WithinD4Bounds(newt)
                try
                    app.Server.Read([], newt);
                    app.CompareSettings(app.Server.slice, app.Server.dim4idx - direction)
                catch
                    % There seems to be an issue when the user holds
                    % the arrows down when a video is loaded in...
                end
            end
        end

        % Callback function: RightArrow
        function RightArrowClicked(app, event)
            if exist('event', 'var')
                % Focus is slow and only needs to occur when the
                % buttonpress event occurs
                focus(app.UIFigure)
            end

            % Return when there is no data or fourth dimension
            if ~app.Server.dataloaded || ~app.Server.istimenavigable
                return
            end

            % Direction is negative (approaches start of imds or time)
            direction = 1;
            newt      = app.Server.dim4idx + direction;
            if app.Server.WithinD4Bounds(newt)
                try
                    app.Server.Read([], newt);
                    app.CompareSettings(app.Server.slice, app.Server.dim4idx - direction)
                catch
                    % There seems to be an issue when the user holds
                    % the arrows down when a video is loaded in...
                end
            end
        end

        % Callback function: UpArrow
        function UpArrowClicked(app, event)
            % Takes the focus off of the button so the button doesn't
            % appear clicked
            if exist('event', 'var')
                focus(app.UIFigure)
            end

            % Exits when no data is available
            if ~app.Server.dataloaded || ~app.Server.isstacknavigable
                return
            end

            % Direction is negative (approaches start of stack)
            direction = -1;
            newslice  = app.Server.slice + direction;
            if newslice >= 1 && newslice <= app.Server.numslices
                app.Server.Read(newslice);
                app.CompareSettings(app.Server.slice - direction, app.Server.dim4idx)
            end
        end

        % Callback function: DownArrow
        function DownArrowClicked(app, event)
            % Takes the focus off of the button so the button doesn't
            % appear clicked
            if exist('event', 'var')
                focus(app.UIFigure)
            end

            % Exits when no data is available
            if ~app.Server.dataloaded || ~app.Server.isstacknavigable
                return
            end

            % Direction is positive (approaches end of stack)
            direction = 1;
            newslice  = app.Server.slice + direction;
            if newslice >= 1 && newslice <= app.Server.numslices
                app.Server.Read(newslice);
                app.CompareSettings(app.Server.slice - direction, app.Server.dim4idx)
            end
        end

        % Callback function: AutoContrast
        function AutoContrastClicked(app, event)
            focus(app.UIFigure)
            app.ManualContrast.State = 'off';
            if ~app.Server.dataloaded
                return
            end
            % Will auto contrast the image in UIAxes or reset to include
            % full range
            app.Visengine.autocontrast = app.AutoContrast.State;
            app.Visengine.Autocontrast
        end

        % Callback function: ManualContrast
        function ManualContrastClicked(app, event)
            % Takes the focus off of the button so the button doesn't
            % appear clicked
            focus(app.UIFigure)
            if ~app.Server.dataloaded
                return
            end

            % Flips autocontrast off
            if app.ManualContrast.State
                app.AutoContrast.State = 'off';
            end

            % Setting manual contrast for visualization engine
            app.Visengine.manualcontrast = logical(app.ManualContrast.State);
            app.Visengine.ManualContrast
            app.ManualContrast.State = app.Visengine.manualcontrast;
        end

        % Callback function: TargetColor
        function TargetColorClicked(app, event)
            if ~isempty(app.Engine.RGB)
                % Progress dialog prevents user queuing callbacks
                pdlg    = uiprogressdlg(app.UIFigure, 'Title', 'Color Target Selection');
                drawnow
                f = app.Engine.ChooseRGBTarget;
                waitfor(f)
                app.LoadSettingsIntoEngine
                app.UpdateSegmentation(true)
                app.EngineSettings2SegData
                pdlg.delete
            end
            focus(app.UIFigure)
        end

        % Callback function: Inversion
        function InversionClicked(app, event)
            % Takes the focus off of the button so the button doesn't
            % appear clicked
            focus(app.UIFigure)
            % Will invert the image
            app.CheckEngineButtons
            app.LoadSettingsIntoEngine
            app.Engine.newimage = true;
            app.Engine.SetImage(app.Server.Slice)
            app.UpdateSegmentation(true)

            % Manual edit overwritten
            sliceIdx = app.Server.slice;
            timeIdx  = app.Server.dim4idx;
            app.Segsettings(sliceIdx, timeIdx).refinedmanually = false;
        end

        % Callback function: LogScaleButton
        function LogScaleButtonClicked(app, event)
            % Takes the focus off of the button so the button doesn't
            % appear clicked
            focus(app.UIFigure)

            app.CheckEngineButtons
            app.LoadSettingsIntoEngine
            app.Engine.newimage = true;
            app.Engine.SetImage(app.Server.Slice)
            app.UpdateSegmentation(true)

            % Manual edit overwritten
            sliceIdx = app.Server.slice;
            timeIdx  = app.Server.dim4idx;
            app.Segsettings(sliceIdx, timeIdx).refinedmanually = false;
        end

        % Callback function: Square
        function SquareClicked(app, event)
            % Takes the focus off of the button so the button doesn't
            % appear clicked
            focus(app.UIFigure)

            app.CheckEngineButtons
            app.LoadSettingsIntoEngine
            app.Engine.newimage = true;
            app.Engine.SetImage(app.Server.Slice)
            app.UpdateSegmentation(true)

            % Manual edit overwritten
            sliceIdx = app.Server.slice;
            timeIdx  = app.Server.dim4idx;
            app.Segsettings(sliceIdx, timeIdx).refinedmanually = false;
        end

        % Callback function: DenoiseButton
        function DenoiseButtonClicked(app, event)
            app.DenoiseButton.Enable = false;
            % Takes the focus off of the button so the button doesn't
            % appear clicked
            focus(app.UIFigure)

            % If no denoising method was selected, the user is prompted to
            % select one
            if isempty(app.Engine.denoisemethod) && app.DenoiseButton.State
                app.ChangeDenoiseClicked
            end

            % Ensures the preprocessing is set to happen
            app.CheckEngineButtons
            app.LoadSettingsIntoEngine
            app.EngineSettings2SegData
            

            app.Engine.newimage = true;
            app.Engine.SetImage(app.Server.Slice)
            app.UpdateSegmentation(true)
            

            % Manual edit overwritten
            sliceIdx = app.Server.slice;
            timeIdx  = app.Server.dim4idx;
            app.Segsettings(sliceIdx, timeIdx).refinedmanually = false;
            app.DenoiseButton.Enable = true;
        end

        % Callback function: ChangeDenoise
        function ChangeDenoiseClicked(app, event)
            app.ChangeDenoise.Enable = false;
            % Takes the focus off of the button so the button doesn't
            % appear clicked
            focus(app.UIFigure)

            % Will allow user to select a denoising algorithm to alter
            % input to engin
            app.Engine.SetDenoiseFunc(true)
            
            % Will turn the toggle off if the user selected "None"
            if isempty(app.Engine.denoisemethod)
                app.DenoiseButton.State = false;
            else
                app.DenoiseButton.State = true;
            end

            app.CheckEngineButtons
            app.LoadSettingsIntoEngine
            app.EngineSettings2SegData
            
            app.Engine.newimage = true;
            app.Engine.SetImage(app.Server.Slice)
            app.UpdateSegmentation(true)

            % Manual edit overwritten
            sliceIdx = app.Server.slice;
            timeIdx  = app.Server.dim4idx;
            app.Segsettings(sliceIdx, timeIdx).refinedmanually = false;

            app.ChangeDenoise.Enable = true;
        end

        % Callback function: AIButton
        function AIButtonClicked(app, event)
            % Takes the focus off of the button so the button doesn't
            % appear clicked
            focus(app.UIFigure)
            if ~app.Server.dataloaded
                return
            end

            % Launched a dialog window regarding network loading
            if ~isempty(app.Engine.Network)
                app.progDlg = uiprogressdlg(app.UIFigure, 'Indeterminate', 'on', 'Title', 'CNN Processing Image');
            else
                app.progDlg = uiprogressdlg(app.UIFigure, 'Indeterminate','on', 'Title', 'Selecting CNN', 'Message', 'Select CNN', 'Cancelable','on');
            end
            % True flag ensured recomputation happens since there is
            % a switch in whether or not the image is preprocessed by a
            % CNN
            app.CheckEngineButtons
            app.LoadSettingsIntoEngine

            app.Engine.newimage = true;
            app.Engine.SetImage(app.Server.Slice)
            app.EngineSettings2SegData
            app.AIButton.State = app.Engine.ai;
            
            app.UpdateSegmentation(true)
            app.progDlg.delete

            % Manual edit overwritten
            sliceIdx = app.Server.slice;
            timeIdx  = app.Server.dim4idx;
            app.Segsettings(sliceIdx, timeIdx).refinedmanually = false;
        end

        % Callback function: ChangeNetwork
        function ChangeNetworkClicked(app, event)
            % Takes the focus off of the button so the button doesn't
            % appear clicked
            focus(app.UIFigure)
            if ~app.Server.dataloaded
                return
            end

            % Will allow user to cycle through neural networks that will
            % change the input into the parametric method
            app.progDlg = uiprogressdlg(app.UIFigure, "Indeterminate","on", 'Title', 'Selecting CNN', 'Cancelable','on');
            app.Engine.SetAINetwork([], app.progDlg)
            app.EngineSettings2SegData
            app.UpdateSegmentation(true)
            app.AIButton.State = app.Engine.ai;
            app.progDlg.delete

            % Manual edit overwritten
            sliceIdx = app.Server.slice;
            timeIdx  = app.Server.dim4idx;
            app.Segsettings(sliceIdx, timeIdx).refinedmanually = false;
        end

        % Callback function: CropButton
        function CropButtonClicked(app, event)
            % Takes the focus off of the button so the button doesn't
            % appear clicked
            focus(app.UIFigure)
            if ~app.Server.dataloaded
                return
            end

            % Get crop coords before resetting the crop (in xy format)
            cropregion = [app.Server.cropcols' app.Server.croprows'];

            % Resets to full FOV 
            app.Server.ResetCropCoords
            app.Server.Read()
            Img = app.Server.Slice;
            
            % Progress dialog prevents user queuing callbacks
            pdlg    = uiprogressdlg(app.UIFigure, 'Title', 'Setting Crop');
            drawnow

            % Transpose image and points
            if app.TransposeButton.State
                cropregion = [cropregion(:,2) cropregion(:,1)];
                Img        = pagetranspose(Img);
            end
            
            % Passes uncropped image to crop tool 
            cropregion = Seg_CropTool(Img, cropregion, app.Visengine.cmap{1});

            % Close progress bar
            pdlg.delete

            % Leave function call if no crop desired
            if isempty(cropregion)
                return
            end

            % Transpose points back
            if app.TransposeButton.State
                cropregion = [cropregion(:,2) cropregion(:,1)];
            end

            % Set the crop in server class and vis engine
            app.Server.SetCropCoords(cropregion);
            app.Visengine.SetImageCrop(app.Server.croprows, app.Server.cropcols)
            app.Visengine.UpdateResultsPlot

            % Sets the new image bounds in the export module
            yxsz = [app.Server.mrowscrop app.Server.ncolscrop];
            app.Exportmodule.SetImageBounds(yxsz)

            % Recomputation occurs on newly cropped image
            app.Engine.SetImage(app.Server.Slice)
            app.UpdateSegmentation(true)
            axis(app.UIAxes, 'image')

            % Manual edit overwritten
            sliceIdx = app.Server.slice;
            timeIdx  = app.Server.dim4idx;
            app.Segsettings(sliceIdx, timeIdx).refinedmanually = false;
        end

        % Callback function: BoundaryTaper
        function BoundaryTaperClicked(app, event)
            % Takes the focus off of the button so the button doesn't
            % appear clicked
            focus(app.UIFigure)
            if ~app.Server.dataloaded
                return
            end

            % User can draw a region on which they want to operate on
            % rather than operating on the entire image. All values outside
            % the user designated ROI are set to zero.
            if app.BoundaryTaper.State
                % Progress dialog prevents user queuing callbacks
                pdlg    = uiprogressdlg(app.UIFigure, 'Title', 'Setting Taper');
                drawnow
                app.Engine.SetComputeROI(app.TransposeButton.State)
                app.Engine.newimage    = true;
                app.Engine.SetImage(app.Server.Slice)
                app.UpdateSegmentation(true)
                pdlg.delete
            end


            % Indicates no taper being used
            if ~isempty(app.Engine.TPoints)
                app.BoundaryTaper.State           = false;
            end

            % Manual edit overwritten
            sliceIdx = app.Server.slice;
            timeIdx  = app.Server.dim4idx;
            app.Segsettings(sliceIdx, timeIdx).refinedmanually = false;
        end

        % Callback function: ChangeMethod
        function ChangeMethodClicked(app, event)
            % Takes the focus off of the button so the button doesn't
            % appear clicked
            focus(app.UIFigure)
            % When the user selects a different parametric algorithm, the
            % engine will swap out its net property with the new one
            ChangeFlag = app.Engine.SetComputeMethodDlg;            
            
            if ChangeFlag
                % Resets image only if method changed
                app.Engine.newimage = true;
                app.Engine.SetImage(app.Server.Slice)

                % If method was actually changed, new plugin is configured
                app.SetMethodConfiguration
                app.Engine.Compute
                app.ResetFunction(true)

                % Sets the data into the visualization engine
                % Getting appropriate indices
                sliceIdx = app.Server.slice;
                timeIdx  = app.Server.dim4idx;
                app.Visengine.Data = app.Exportmodule.Results{sliceIdx, timeIdx};

                % Adjusts the visualization according to results type
                app.Visengine.AdjustVisualizationType
                app.VisualizationTypeButtonCheck

                % Adjust to hold current plot width value
                app.AdjustLineMarkerField

                % Update GUI's compute step field
                app.UpdateWindowTitle
    
                % Manual edit overwritten
                sliceIdx = app.Server.slice;
                timeIdx  = app.Server.dim4idx;
                app.Segsettings(sliceIdx, timeIdx).refinedmanually = false;
            end
        end

        % Callback function: AutoParamsButton
        function AutoParamsButtonClicked(app, event)
            % Takes the focus off of the button so the button doesn't
            % appear clicked
            focus(app.UIFigure)
            % Triggers the autoparametric function in the engine
            app.AutoSetParams
            % Manual edit overwritten
            sliceIdx = app.Server.slice;
            timeIdx  = app.Server.dim4idx;
            app.Segsettings(sliceIdx, timeIdx).refinedmanually = false;
        end

        % Callback function: ActiveContours
        function ActiveContoursClicked(app, event)
            % Takes the focus off of the button so the button doesn't
            % appear clicked
            focus(app.UIFigure)
            app.CheckEngineButtons
            app.LoadSettingsIntoEngine
            app.Engine.newimage = true;
            app.Engine.SetImage(app.Server.Slice)
            app.UpdateSegmentation(true)
            app.UpdateWindowTitle
            app.ActiveContoursSettingsToggle
            % Manual edit overwritten
            sliceIdx = app.Server.slice;
            timeIdx  = app.Server.dim4idx;
            app.Segsettings(sliceIdx, timeIdx).refinedmanually = false;

            if app.ActiveContours.State
                app.ActiveContoursConfigurationPanel.Title = 'Active Contours Configuration (on)';
            else
                app.ActiveContoursConfigurationPanel.Title = 'Active Contours Configuration (off)';
            end
        end

        % Callback function: EditResults
        function EditResultsClicked(app, event)
            % Takes the focus off of the button so the button doesn't
            % appear clicked
            focus(app.UIFigure)
            if ~app.Server.dataloaded
                return
            end

            if app.EditResults.State
                % Grabbing image and results data
                Img  = app.Server.Slice;
                Data = app.Exportmodule.Results{app.Server.slice, app.Server.dim4idx};

                % Data kept in original coordinate system
                if strcmp(app.Visengine.vistype, 'contour')
                    X = Data(:,1);
                    Y = Data(:,2);
                    m = app.Server.mrows;
                    n = app.Server.ncols;
                    if app.TransposeButton.State
                        [X, Y] = deal(Y, X);
                        [m, n] = deal(n, m);
                    end
                    Data = poly2mask(X, Y, m, n);
                end

                % In case of crop
                yc   = app.Server.croprows;
                xc   = app.Server.cropcols;
                
                % In case user is viewing the transposed image
                if app.TransposeButton.State
                    % Tranpose original image
                    Img      = pagetranspose(Img);

                    % Transpose crop
                    [xc, yc] = deal(yc, xc);
                    
                    if strcmp(app.Visengine.vistype, 'pointcloud')
                        % Transpose the pointcloud
                        Data = [Data(:,2) Data(:,1)];
                    end
                end

                % In case of crop
                if strcmp(app.Visengine.vistype, 'pointcloud')
                    p    = [xc(1) yc(1)] - 1;
                    Data = Data - p;

                else
                    Data = Data(yc(1):yc(2), xc(1):xc(2));
                end

                
                % Init app data to hold the edited results output
                setappdata(app.UIFigure, 'Editedresults', [])

                % Progress dialog prevents user queuing callbacks
                pdlg   = uiprogressdlg(app.UIFigure, 'Title', 'Mask adjustment');
                drawnow

                % CLims depend on whether auxiliary image is being viewed
                if app.AuxiliaryView.State
                    % Grabs auto CLims from original data
                    if size(Img, 3) == 1
                        [mn, mx] = bounds(medfilt2(Img), 'all');
                        clims    = double([mn mx]).*[0.1 0.99];
                    else
                        clims    = [];
                    end

                else
                    % Matches the axes color limits
                    clims = app.UIAxes.CLim;

                end


                % Launches Editing Tool
                % IN: Image, Results, cmap, CLims, and plot color
                MT = EditingTool(Img, Data, app.Visengine.cmap{1}, clims, app.Visengine.color);


                % Spinlock - GUI waits for results to transfer
                uiwait(MT.UIFigure)
                while isempty(getappdata(app.UIFigure, 'Editedresults'))
                    try
                        uiresume(MT.UIFigure)
                    catch
                    end
                    drawnow
                    pause(0.01)
                end

                % Discarded edits return NaN
                Editedresults = getappdata(app.UIFigure, 'Editedresults');
                if any(isnan(Editedresults(:)))
                    app.EditResults.State = false;
                    return
                end


                % Transpose
                if app.TransposeButton.State
                    if strcmp(app.Visengine.vistype, 'pointcloud')
                        % Transpose the pointcloud
                        Editedresults = [Editedresults(:,2) Editedresults(:,1)];

                    else
                        % Transpose mask
                        Editedresults = Editedresults';

                    end
                end
                

                % Returning to original coordinates
                if strcmp(app.Visengine.vistype, 'pointcloud')
                    xc           = app.Server.cropcols(1);
                    yc           = app.Server.croprows(1);
                    Editedresults(:,1) = Editedresults(:,1) + xc - 1;
                    Editedresults(:,2) = Editedresults(:,2) + yc - 1;
                elseif any(strcmp(app.Visengine.vistype, {'mask', 'label'}))
                    dtype   = underlyingType(Editedresults);
                    sz      = [app.Server.mrows app.Server.ncols];
                    Z       = zeros(sz, dtype);
                    y       = app.Server.croprows(1):app.Server.croprows(2);
                    x       = app.Server.cropcols(1):app.Server.cropcols(2);
                    Z(y, x) = Editedresults;
                    Editedresults = Z;
                elseif strcmp(app.Visengine.vistype, 'contour')
                    Editedresults      = app.Engine.Mask2Points(Editedresults);
                    xc                 = app.Server.cropcols(1);
                    yc                 = app.Server.croprows(1);
                    Editedresults(:,1) = Editedresults(:,1) + xc - 1;
                    Editedresults(:,2) = Editedresults(:,2) + yc - 1;

                end

                
                % Comparison if data changed, i.e. not all the same
                Data   = app.Exportmodule.Results{app.Server.slice, app.Server.dim4idx};
                szold  = size(Data);
                sznew  = size(Editedresults);
                diffsz = any(szold~=sznew);
                Comp   = diffsz || any(Data(:) ~= Editedresults(:));
                if any(Comp)

                    % Saveds results in original coordinate space
                    sliceIdx        = app.Server.slice;
                    timeIdx         = app.Server.dim4idx;
                    app.Exportmodule.UpdateData(Editedresults, sliceIdx, timeIdx);
                    
                    app.Visengine.Data                                 = Editedresults;
                    app.Segsettings(sliceIdx, timeIdx).refinedmanually = true;
                    
                    app.EngineSettings2SegData
                    app.UpdateAxes
                end
                close(pdlg)
            end
            app.EditResults.State = false;
        end

        % Callback function: Colormap
        function ColormapClicked(app, event)
            % Takes the focus off of the button so the button doesn't
            % appear clicked
            focus(app.UIFigure)
            % Will allow user to select a colormap if image is grayscale
            if ~app.Visengine.isrgb
                app.Visengine.ChangeColormap
                % If engine step visualizer is open, update the colormap
                % changes there as well
                if ~isempty(app.Engine.EngineVisualizer) && isvalid(app.Engine.EngineVisualizer(1).Figure)
                    figure(app.Engine.EngineVisualizer(1).Figure)
                    app.Engine.UpdateEngineVisualizerColors
                end
                figure(app.UIFigure)
            end
        end

        % Callback function: ContourColor
        function ContourColorClicked(app, event)
            % Takes the focus off of the button so the button doesn't
            % appear clicked
            focus(app.UIFigure)
            app.Visengine.ChangePlotColor
            % Brings the UIFigure back up to the front after bringing the
            % engine visualizer to the front to prevent both from being
            % sent back
            if ~isempty(app.Engine.EngineVisualizer) && isvalid(app.Engine.EngineVisualizer(1).Figure)
                figure(app.Engine.EngineVisualizer(1).Figure)
            end
            figure(app.UIFigure)
            if ~app.Server.dataloaded
                return
            end
            % If the server and engine have data loaded up
            app.Visengine.UpdateResultsPlot
            app.Engine.UpdateEngineVisualizerColors
        end

        % Callback function: ContourButton
        function ContourButtonClicked(app, event)
            % Takes the focus off of the button so the button doesn't
            % appear clicked
            focus(app.UIFigure)
            % Toggles the contour outline
            app.Visengine.view = app.ContourButton.State;
            app.Visengine.UpdateResultsPlot
        end

        % Callback function: TransposeButton
        function TransposeButtonClicked(app, event)
            % Takes the focus off of the button so the button doesn't
            % appear clicked
            focus(app.UIFigure)
            if ~app.Server.dataloaded
                return
            end
            % Updates view to be transposed
            app.UpdateAxes
            app.Visengine.transposed = app.TransposeButton.State;
            app.Visengine.UpdateAxesLimits(app.Server.croprows, app.Server.cropcols)
            axis(app.UIAxes, 'image')
            app.Visengine.UIAxes2.InnerPosition = app.Visengine.UIAxes.InnerPosition;
        end

        % Callback function: PlotMajMin
        function PlotMajMinClicked(app, event)
            % Takes the focus off of the button so the button doesn't
            % appear clicked
            focus(app.UIFigure)
            app.Visengine.eigs = logical(app.PlotMajMin.State);
            if app.Visengine.eigs
                app.Visengine.PlotMajMinMoments
            else
                app.Visengine.ClearEigPlots
            end
        end

        % Callback function: ColorBar
        function ColorBarClicked(app, event)
            % Takes the focus off of the button so the button doesn't
            % appear clicked
            focus(app.UIFigure)
            if ~app.Server.dataloaded
                return
            end

            % Launches the colorbar or deletes it
            if app.ColorBar.State
                app.cbar = colorbar(app.UIAxes, "northoutside", 'FontSize', 12);
                if ~app.DarkMode.State
                    app.cbar.Color = [0 0 0];
                else
                    app.cbar.Color = [1 1 1];
                end
            else
                delete(app.cbar)
            end
            app.Visengine.UIAxes2.InnerPosition = app.Visengine.UIAxes.InnerPosition;
        end

        % Callback function: AuxiliaryView
        function AuxiliaryViewClicked(app, event)
            focus(app.UIFigure)
            if ~app.Server.dataloaded
                return
            end
            % Switches the view switch value as well
            if app.AuxiliaryView.State
                app.ViewTypeSwitch.Value = 'Auxiliary';
            else
                app.ViewTypeSwitch.Value = 'Raw';
            end
            app.UpdateAxes
        end

        % Value changed function: ViewTypeSwitch
        function ViewTypeSwitchValueChanged(app, event)
            value = app.ViewTypeSwitch.Value;
            value = ~strcmp(value, 'Raw');
            app.AuxiliaryView.State = value;
            app.UpdateAxes
        end

        % Callback function: ViewNetResults
        function ViewNetResultsClicked(app, event)
            % Takes the focus off of the button so the button doesn't
            % appear clicked
            focus(app.UIFigure)
            if ~isempty(app.Engine.Outputs)
                app.ViewNetResults.Enable = false;
                app.GiveParam2Engine(1)
                app.GiveParam2Engine(2)
                app.GiveParam2Engine(3)
                % Will recompute just in case the image currently loaded
                % has not been computed on since the engine will normally
                % not recompute until a value is changed
                app.Engine.Compute
                % Launches a view of all image processing results
                app.Engine.PlotPluginSteps(app.Visengine.cmap{1}, app.Visengine.color, app.Visengine.linewidth, app.DarkMode.State);
                drawnow
                pause(0.01)
                app.ViewNetResults.Enable = true;
            end
        end

        % Callback function: Overwrite
        function OverwriteClicked(app, event)
            % Does nothing since other function calls will simply check to
            % see if this button is on/off
            % refocuses the figure window to reenable key press fcns
            focus(app.UIFigure)
        end

        % Callback function: ResetButton
        function ResetButtonClicked(app, event)
            % Takes the focus off of the button so the button doesn't
            % appear clicked
            focus(app.UIFigure)
            app.ResetFunction
        end

        % Callback function: InfoTool
        function InfoToolClicked(app, event)
            % Takes the focus off of the button so the button doesn't
            % appear clicked
            focus(app.UIFigure)
            app.HelpInstruction
        end

        % Callback function: PropSettings
        function PropSettingsCheckedNodesChanged(app, event)
            % Will update the export preferences
            try
                app.Exportmodule.SetExportSettings(app.Exportsettings, app.PropSettings)
            catch
            end
        end

        % Value changed function: SaveFolderEditField
        function SaveFolderEditFieldValueChanged(app, event)
            % Changing the save location will update settings for export
            app.SetExportSettings
        end

        % Value changed function: FileNameEditField
        function FileNameEditFieldValueChanged(app, event)
            % Changing the save location will update settings for export
            app.SetExportSettings
        end

        % Value changed function: NumberofiterationsEditField
        function NumberofiterationsEditFieldValueChanged(app, event)
            value = app.NumberofiterationsEditField.Value;
            app.Engine.snakesconfig.N = value;
            app.UpdateSegmentation(true)
            app.EngineSettings2SegData
        end

        % Value changed function: SmoothnessEditField
        function SmoothnessEditFieldValueChanged(app, event)
            value = app.SmoothnessEditField.Value;
            app.Engine.snakesconfig.Smoothness = value;
            app.UpdateSegmentation(true)
            app.EngineSettings2SegData

        end

        % Value changed function: DenoiseFactorEditField
        function DenoiseFactorEditFieldValueChanged(app, event)
            value = app.DenoiseFactorEditField.Value;
            app.Engine.snakesconfig.Factor = value;
            app.UpdateSegmentation(true)
            app.EngineSettings2SegData

        end

        % Value changed function: ContractionBiasEditField
        function ContractionBiasEditFieldValueChanged(app, event)
            value = app.ContractionBiasEditField.Value;
            app.Engine.snakesconfig.ContractionBias = value;
            app.UpdateSegmentation(true)
            app.EngineSettings2SegData

        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            % User can browse folders to decide where to place file and
            % what to name it
            
            path         = app.SaveFolderEditField.Value;
            name         = app.FileNameEditField.Value;
            fid          = [path filesep name];
            [name, path] = uiputfile('*.*', 'Mask Export File Format', fid);
            focus(app.UIFigure)
            if isnumeric(name) || isnumeric(path)
                [path, name] = fileparts(fid);
            end
            app.SaveFolderEditField.Value = path;
            app.FileNameEditField.Value   = name;
            app.SetExportSettings
            focus(app.UIFigure)
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            % Will export via the export module
            if isempty(app.SaveFolderEditField.Value) || isempty(app.FileNameEditField.Value)
                % Will ask user to set a file name
                app.TabGroup.SelectedTab = app.SettingsTab;
                msg        = 'Please enter a valid file location to export to';
                alertTitle = 'Empty Filename';
                uialert(app.UIFigure, msg, alertTitle)
                return
            else
                app.SetExportSettings
                app.Exportmodule.ExportData(app.UIFigure, app.Segsettings)
            end
            focus(app.UIFigure)
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key      = event.Key;
            if ~isempty(event.Modifier)
                if isscalar(event.Modifier)
                    modifier = event.Modifier{:};
                else
                    modifier = cell2mat(event.Modifier);
                end
            else
                modifier = [];
            end

            if ~isempty(app.Engine.Outputs)
                % Navigation of the stack
                if isequal(key, 'downarrow')
                    app.DownArrowClicked
                elseif isequal(key, 'uparrow')
                    app.UpArrowClicked
                elseif isequal(key, 'leftarrow')
                    app.LeftArrowClicked
                elseif isequal(key, 'rightarrow')
                    app.RightArrowClicked
                % Transpose shorcut
                elseif strcmp(key, 't')
                    app.TransposeButton.State = ~app.TransposeButton.State;
                    app.TransposeButtonClicked
                
                elseif strcmp(key, 'c')
                    % Trim / Crop shortcut
                    if isempty(event.Modifier)
                        app.CropButtonClicked
                    % Contrast shortcut
                    elseif contains(modifier, 'shift') && contains(modifier, 'control')
                        app.ManualContrast.State = 'on';
                        app.ManualContrastClicked
                    end
                elseif strcmp(key, 'v')
                    app.AuxiliaryView.State = ~app.AuxiliaryView.State;
                    app.AuxiliaryViewClicked
                % Export / save shortcut
                elseif (strcmp(key, 's') || strcmp(key, 'e')) && strcmp(modifier, 'control')
                    app.ExportButtonPushed
                elseif strcmp(key, 'escape')
                end
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.UIFigure)
        end

        % On callback: Batch
        function BatchOn(app, event)
            % Will batch process data when clicked on if data is loaded in
            focus(app.UIFigure)
            if app.Server.dataloaded
                app.BatchProcess
            else
                app.Batch.State = 'off';
            end
        end

        % Callback function: DarkMode
        function DarkModeClicked(app, event)
            focus(app.UIFigure)
            app.ChangeGUIMode
            app.Engine.UpdateEngineVisualizerColors
        end

        % Value changed function: ComputeStopStepEditField
        function ComputeStopStepEditFieldValueChanged(app, event)
            % Engine has a method to check value validity
            app.Engine.SetComputeStop(app.ComputeStopStepEditField.Value)

            % Will change the step in the field as necessary
            app.ComputeStopStepEditField.Value = app.Engine.laststep;
            
            % Update Visualization with new step
            % Getting appropriate indices
            sliceIdx = app.Server.slice;
            timeIdx  = app.Server.dim4idx;
            app.Visengine.Data = app.Exportmodule.Results{sliceIdx, timeIdx};
            app.Visengine.AdjustVisualizationType
            app.UpdateSegmentation(true)

            % Adjust to hold current plot width value
            app.AdjustLineMarkerField
        end

        % Value changed function: MaskOpacitySlider
        function MaskOpacitySliderValueChanged(app, event)
            % Opacity slider allows for control of mask overlay
            % transparency
            app.Visengine.opacity = app.MaskOpacitySlider.Value;
            app.Visengine.UpdateResultsPlot
        end

        % Value changed function: SavemasksCheckBox
        function SavemasksCheckBoxValueChanged(app, event)
            % Will toggle the mask saving feature of the export module
            value = app.SavemasksCheckBox.Value;
            app.Exportmodule.outputmasks = value;
            % Toggles the masks export option
            app.MaskformatListBoxLabel.Enable = value;
            app.MaskformatListBox.Enable = value;
        end

        % Value changed function: LazyLoadingCheckbox
        function LazyLoadingCheckboxValueChanged(app, event)
            % Toggling between lazy or eager loading
            value = app.LazyLoadingCheckbox.Value;

            % Automatically rereads data in as requested
            app.Server.SetLazyLoading(value)
            app.Server.Read
        end

        % Value changed function: PointMarkerDropDown
        function PointMarkerDropDownValueChanged(app, event)
            value = app.PointMarkerDropDown.Value;
            app.Visengine.marker = value;
            app.Visengine.UpdateResultsPlot
        end

        % Value changed function: MarkerWidthEditField
        function MarkerWidthEditFieldValueChanged(app, event)
            value = app.MarkerWidthEditField.Value;
            app.Visengine.markerwidth = value;
            app.Visengine.UpdateResultsPlot
        end

        % Value changed function: LineWidthEditField
        function LineWidthEditFieldValueChanged(app, event)
            value = app.LineWidthEditField.Value;
            app.Visengine.linewidth = value;
            app.Visengine.UpdateResultsPlot
        end

        % Value changed function: VisualFeedback
        function VisualFeedbackValueChanged(app, event)
            value = app.VisualFeedback.Value;
            if ~value
                uialert(app.UIFigure,'If planning on batch processing, click Ctrl+C in terminal to stop processing.', 'Visualization Off')
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Segmenter';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.Toolbar = uitoolbar(app.UIFigure);
            app.Load = uipushtool(app.Toolbar);
            app.Load.ClickedCallback = createCallbackFcn(app, @LoadClicked, true);
            app.LoadMasks = uipushtool(app.Toolbar);
            app.LoadMasks.ClickedCallback = createCallbackFcn(app, @LoadMasksClicked, true);
            app.InstantNav = uipushtool(app.Toolbar);
            app.InstantNav.ClickedCallback = createCallbackFcn(app, @InstantNavClicked, true);
            app.InstantNav.Separator = 'on';
            app.LeftArrow = uipushtool(app.Toolbar);
            app.LeftArrow.ClickedCallback = createCallbackFcn(app, @LeftArrowClicked, true);
            app.RightArrow = uipushtool(app.Toolbar);
            app.RightArrow.ClickedCallback = createCallbackFcn(app, @RightArrowClicked, true);
            app.UpArrow = uipushtool(app.Toolbar);
            app.UpArrow.ClickedCallback = createCallbackFcn(app, @UpArrowClicked, true);
            app.DownArrow = uipushtool(app.Toolbar);
            app.DownArrow.ClickedCallback = createCallbackFcn(app, @DownArrowClicked, true);
            app.Batch = uitoggletool(app.Toolbar);
            app.Batch.Separator = 'on';
            app.Batch.OnCallback = createCallbackFcn(app, @BatchOn, true);
            app.AutoContrast = uitoggletool(app.Toolbar);
            app.AutoContrast.ClickedCallback = createCallbackFcn(app, @AutoContrastClicked, true);
            app.AutoContrast.Separator = 'on';
            app.ManualContrast = uitoggletool(app.Toolbar);
            app.ManualContrast.ClickedCallback = createCallbackFcn(app, @ManualContrastClicked, true);
            app.CropButton = uipushtool(app.Toolbar);
            app.CropButton.ClickedCallback = createCallbackFcn(app, @CropButtonClicked, true);
            app.CropButton.Separator = 'on';
            app.TargetColor = uipushtool(app.Toolbar);
            app.TargetColor.ClickedCallback = createCallbackFcn(app, @TargetColorClicked, true);
            app.Square = uitoggletool(app.Toolbar);
            app.Square.ClickedCallback = createCallbackFcn(app, @SquareClicked, true);
            app.LogScaleButton = uitoggletool(app.Toolbar);
            app.LogScaleButton.ClickedCallback = createCallbackFcn(app, @LogScaleButtonClicked, true);
            app.Inversion = uitoggletool(app.Toolbar);
            app.Inversion.ClickedCallback = createCallbackFcn(app, @InversionClicked, true);
            app.ChangeDenoise = uipushtool(app.Toolbar);
            app.ChangeDenoise.ClickedCallback = createCallbackFcn(app, @ChangeDenoiseClicked, true);
            app.DenoiseButton = uitoggletool(app.Toolbar);
            app.DenoiseButton.ClickedCallback = createCallbackFcn(app, @DenoiseButtonClicked, true);
            app.ChangeNetwork = uipushtool(app.Toolbar);
            app.ChangeNetwork.ClickedCallback = createCallbackFcn(app, @ChangeNetworkClicked, true);
            app.AIButton = uitoggletool(app.Toolbar);
            app.AIButton.ClickedCallback = createCallbackFcn(app, @AIButtonClicked, true);
            app.BoundaryTaper = uitoggletool(app.Toolbar);
            app.BoundaryTaper.ClickedCallback = createCallbackFcn(app, @BoundaryTaperClicked, true);
            app.ChangeMethod = uipushtool(app.Toolbar);
            app.ChangeMethod.ClickedCallback = createCallbackFcn(app, @ChangeMethodClicked, true);
            app.ChangeMethod.Separator = 'on';
            app.AutoParamsButton = uipushtool(app.Toolbar);
            app.AutoParamsButton.ClickedCallback = createCallbackFcn(app, @AutoParamsButtonClicked, true);
            app.ActiveContours = uitoggletool(app.Toolbar);
            app.ActiveContours.ClickedCallback = createCallbackFcn(app, @ActiveContoursClicked, true);
            app.EditResults = uitoggletool(app.Toolbar);
            app.EditResults.ClickedCallback = createCallbackFcn(app, @EditResultsClicked, true);
            app.Colormap = uipushtool(app.Toolbar);
            app.Colormap.ClickedCallback = createCallbackFcn(app, @ColormapClicked, true);
            app.Colormap.Separator = 'on';
            app.ContourColor = uipushtool(app.Toolbar);
            app.ContourColor.ClickedCallback = createCallbackFcn(app, @ContourColorClicked, true);
            app.ContourButton = uitoggletool(app.Toolbar);
            app.ContourButton.ClickedCallback = createCallbackFcn(app, @ContourButtonClicked, true);
            app.ContourButton.State = 'on';
            app.TransposeButton = uitoggletool(app.Toolbar);
            app.TransposeButton.ClickedCallback = createCallbackFcn(app, @TransposeButtonClicked, true);
            app.PlotMajMin = uitoggletool(app.Toolbar);
            app.PlotMajMin.ClickedCallback = createCallbackFcn(app, @PlotMajMinClicked, true);
            app.ColorBar = uitoggletool(app.Toolbar);
            app.ColorBar.ClickedCallback = createCallbackFcn(app, @ColorBarClicked, true);
            app.AuxiliaryView = uitoggletool(app.Toolbar);
            app.AuxiliaryView.ClickedCallback = createCallbackFcn(app, @AuxiliaryViewClicked, true);
            app.ViewNetResults = uipushtool(app.Toolbar);
            app.ViewNetResults.ClickedCallback = createCallbackFcn(app, @ViewNetResultsClicked, true);
            app.Overwrite = uitoggletool(app.Toolbar);
            app.Overwrite.ClickedCallback = createCallbackFcn(app, @OverwriteClicked, true);
            app.Overwrite.State = 'on';
            app.DarkMode = uitoggletool(app.Toolbar);
            app.DarkMode.ClickedCallback = createCallbackFcn(app, @DarkModeClicked, true);
            app.DarkMode.State = 'on';
            app.ResetButton = uipushtool(app.Toolbar);
            app.ResetButton.ClickedCallback = createCallbackFcn(app, @ResetButtonClicked, true);
            app.ResetButton.Separator = 'on';
            app.InfoTool = uipushtool(app.Toolbar);
            app.InfoTool.ClickedCallback = createCallbackFcn(app, @InfoToolClicked, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.AxesTab = uitab(app.TabGroup);
            app.AxesTab.Title = 'Axes';
            app.Panel = uipanel(app.AxesTab);
            app.TopSlider = uislider(app.Panel);
            app.TopSlider.MajorTicks = [];
            app.TopSlider.ValueChangedFcn = createCallbackFcn(app, @TopSliderValueChanged, true);
            app.TopSlider.MinorTicks = [];
            app.MiddleSlider = uislider(app.Panel);
            app.MiddleSlider.MajorTicks = [];
            app.MiddleSlider.ValueChangedFcn = createCallbackFcn(app, @MiddleSliderValueChanged, true);
            app.MiddleSlider.MinorTicks = [];
            app.BottomSlider = uislider(app.Panel);
            app.BottomSlider.MajorTicks = [];
            app.BottomSlider.ValueChangedFcn = createCallbackFcn(app, @BottomSliderValueChanged, true);
            app.BottomSlider.MinorTicks = [];
            app.BottomSliderValueLabel = uilabel(app.Panel);
            app.BottomSliderValueLabel.Text = '';
            app.MiddleSliderValueLabel = uilabel(app.Panel);
            app.MiddleSliderValueLabel.Text = '';
            app.TopSliderValueLabel = uilabel(app.Panel);
            app.TopSliderValueLabel.Text = '';
            app.BottomEditFieldMax = uieditfield(app.Panel, 'text');
            app.BottomEditFieldMax.ValueChangedFcn = createCallbackFcn(app, @BottomEditFieldMaxValueChanged, true);
            app.BottomEditFieldMax.Value = '40';
            app.MiddleEditFieldMax = uieditfield(app.Panel, 'text');
            app.MiddleEditFieldMax.ValueChangedFcn = createCallbackFcn(app, @MiddleEditFieldMaxValueChanged, true);
            app.MiddleEditFieldMax.Value = '50';
            app.TopEditFieldMax = uieditfield(app.Panel, 'text');
            app.TopEditFieldMax.ValueChangedFcn = createCallbackFcn(app, @TopEditFieldMaxValueChanged, true);
            app.TopEditFieldMax.Value = '10';
            app.MaxLabel = uilabel(app.Panel);
            app.MaxLabel.Tag = 'Label';
            app.MaxLabel.Text = 'Max';
            app.BottomEditFieldMin = uieditfield(app.Panel, 'text');
            app.BottomEditFieldMin.ValueChangedFcn = createCallbackFcn(app, @BottomEditFieldMinValueChanged, true);
            app.BottomEditFieldMin.Value = '10';
            app.MiddleEditFieldMin = uieditfield(app.Panel, 'text');
            app.MiddleEditFieldMin.ValueChangedFcn = createCallbackFcn(app, @MiddleEditFieldMinValueChanged, true);
            app.MiddleEditFieldMin.Value = '1';
            app.TopEditFieldMin = uieditfield(app.Panel, 'text');
            app.TopEditFieldMin.ValueChangedFcn = createCallbackFcn(app, @TopEditFieldMinValueChanged, true);
            app.TopEditFieldMin.Value = '1';
            app.MinLabel = uilabel(app.Panel);
            app.MinLabel.Tag = 'Label';
            app.MinLabel.Text = 'Min';
            app.TopSliderUnits = uilabel(app.Panel);
            app.MiddleSliderUnits = uilabel(app.Panel);
            app.BottomSliderUnits = uilabel(app.Panel);
            app.SliderLabel_1 = uilabel(app.Panel);
            app.SliderLabel_1.Tag = 'Label';
            app.SliderLabel_1.Text = '$\epsilon$';
            app.SliderLabel_2 = uilabel(app.Panel);
            app.SliderLabel_2.Text = '$\rho$';
            app.SliderLabel_3 = uilabel(app.Panel);
            app.SliderLabel_3.Text = '$\alpha$';
            app.ViewTypeSwitchLabel = uilabel(app.Panel);
            app.ViewTypeSwitchLabel.Text = 'View Type';
            app.ViewTypeSwitch = uiswitch(app.Panel, 'slider');
            app.ViewTypeSwitch.Items = {'Raw', 'Auxiliary'};
            app.ViewTypeSwitch.ValueChangedFcn = createCallbackFcn(app, @ViewTypeSwitchValueChanged, true);
            app.ViewTypeSwitch.Value = 'Raw';
            app.AxesPanel = uipanel(app.Panel);
            app.UIAxes = uiaxes(app.AxesPanel);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.Panel_2 = uipanel(app.SettingsTab);
            app.GridLayout4 = uigridlayout(app.Panel_2);
            app.ActiveContoursConfigurationPanel = uipanel(app.GridLayout4);
            app.ActiveContoursConfigurationPanel.Title = 'Active Contours Configuration (off)';
            app.GridLayout2 = uigridlayout(app.ActiveContoursConfigurationPanel);
            app.NumberofiterationsEditFieldLabel = uilabel(app.GridLayout2);
            app.NumberofiterationsEditFieldLabel.Text = 'Number of iterations';
            app.NumberofiterationsEditField = uieditfield(app.GridLayout2, 'numeric');
            app.NumberofiterationsEditField.Limits = [1 1000];
            app.NumberofiterationsEditField.RoundFractionalValues = 'on';
            app.NumberofiterationsEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofiterationsEditFieldValueChanged, true);
            app.NumberofiterationsEditField.Value = 100;
            app.SmoothnessEditFieldLabel = uilabel(app.GridLayout2);
            app.SmoothnessEditFieldLabel.Text = 'Smoothness';
            app.SmoothnessEditField = uieditfield(app.GridLayout2, 'numeric');
            app.SmoothnessEditField.Limits = [0 1];
            app.SmoothnessEditField.ValueChangedFcn = createCallbackFcn(app, @SmoothnessEditFieldValueChanged, true);
            app.SmoothnessEditField.Value = 0.5;
            app.DenoiseFactorEditFieldLabel = uilabel(app.GridLayout2);
            app.DenoiseFactorEditFieldLabel.Text = 'Denoise Factor';
            app.DenoiseFactorEditField = uieditfield(app.GridLayout2, 'numeric');
            app.DenoiseFactorEditField.Limits = [0 10];
            app.DenoiseFactorEditField.ValueChangedFcn = createCallbackFcn(app, @DenoiseFactorEditFieldValueChanged, true);
            app.DenoiseFactorEditField.Value = 3;
            app.ContractionBiasEditFieldLabel = uilabel(app.GridLayout2);
            app.ContractionBiasEditFieldLabel.Text = 'Contraction Bias';
            app.ContractionBiasEditField = uieditfield(app.GridLayout2, 'numeric');
            app.ContractionBiasEditField.Limits = [-1 1];
            app.ContractionBiasEditField.ValueChangedFcn = createCallbackFcn(app, @ContractionBiasEditFieldValueChanged, true);
            app.Visualization = uipanel(app.GridLayout4);
            app.Visualization.Title = 'Visualization and Compute Flow';
            app.GridLayout2_2 = uigridlayout(app.Visualization);
            app.MaskOpacitySliderLabel = uilabel(app.GridLayout2_2);
            app.MaskOpacitySliderLabel.Text = 'Mask Opacity';
            app.MaskOpacitySlider = uislider(app.GridLayout2_2);
            app.MaskOpacitySlider.Limits = [0 1];
            app.MaskOpacitySlider.MajorTicks = [0 0.5 1];
            app.MaskOpacitySlider.MajorTickLabels = {''};
            app.MaskOpacitySlider.ValueChangedFcn = createCallbackFcn(app, @MaskOpacitySliderValueChanged, true);
            app.MaskOpacitySlider.MinorTicks = [];
            app.MaskOpacitySlider.Value = 0.4;
            app.ComputeStopStepEditFieldLabel = uilabel(app.GridLayout2_2);
            app.ComputeStopStepEditFieldLabel.Text = 'Compute Stop Step';
            app.ComputeStopStepEditField = uieditfield(app.GridLayout2_2, 'numeric');
            app.ComputeStopStepEditField.Limits = [1 3];
            app.ComputeStopStepEditField.RoundFractionalValues = 'on';
            app.ComputeStopStepEditField.ValueChangedFcn = createCallbackFcn(app, @ComputeStopStepEditFieldValueChanged, true);
            app.ComputeStopStepEditField.Value = 3;
            app.LazyLoadingCheckbox = uicheckbox(app.GridLayout2_2);
            app.LazyLoadingCheckbox.ValueChangedFcn = createCallbackFcn(app, @LazyLoadingCheckboxValueChanged, true);
            app.LazyLoadingCheckbox.Enable = 'off';
            app.LazyLoadingCheckbox.Visible = 'off';
            app.LazyLoadingCheckbox.Text = '';
            app.LazyLoadingCheckbox.Value = true;
            app.LazyLoadingLabel = uilabel(app.GridLayout2_2);
            app.LazyLoadingLabel.Enable = 'off';
            app.LazyLoadingLabel.Visible = 'off';
            app.LazyLoadingLabel.Text = 'Lazy Loading';
            app.PointMarkerDropDownLabel = uilabel(app.GridLayout2_2);
            app.PointMarkerDropDownLabel.Text = 'Point Marker';
            app.PointMarkerDropDown = uidropdown(app.GridLayout2_2);
            app.PointMarkerDropDown.Items = {'.', 'o', '+', '*', 'x', '_', '|', 'square', 'diamond', '^', '>', '<', 'pentagram', 'hexagram'};
            app.PointMarkerDropDown.ValueChangedFcn = createCallbackFcn(app, @PointMarkerDropDownValueChanged, true);
            app.PointMarkerDropDown.Value = '.';
            app.MarkerWidthEditFieldLabel = uilabel(app.GridLayout2_2);
            app.MarkerWidthEditFieldLabel.Text = 'Marker Width';
            app.MarkerWidthEditField = uieditfield(app.GridLayout2_2, 'numeric');
            app.MarkerWidthEditField.Limits = [2.22044604925031e-16 100];
            app.MarkerWidthEditField.ValueChangedFcn = createCallbackFcn(app, @MarkerWidthEditFieldValueChanged, true);
            app.MarkerWidthEditField.Value = 16;
            app.LineWidthEditFieldLabel = uilabel(app.GridLayout2_2);
            app.LineWidthEditFieldLabel.Text = 'Line Width';
            app.LineWidthEditField = uieditfield(app.GridLayout2_2, 'numeric');
            app.LineWidthEditField.Limits = [2.22044604925031e-16 100];
            app.LineWidthEditField.ValueChangedFcn = createCallbackFcn(app, @LineWidthEditFieldValueChanged, true);
            app.LineWidthEditField.Value = 3;
            app.VisualFeedback = uicheckbox(app.GridLayout2_2);
            app.VisualFeedback.ValueChangedFcn = createCallbackFcn(app, @VisualFeedbackValueChanged, true);
            app.VisualFeedback.Text = '';
            app.VisualFeedback.Value = true;
            app.VisualsforBatchingLabel = uilabel(app.GridLayout2_2);
            app.VisualsforBatchingLabel.Text = 'Visuals for Batching';
            app.ExportTab = uitab(app.TabGroup);
            app.ExportTab.Title = 'Export';
            app.Panel_3 = uipanel(app.ExportTab);
            app.GridLayout5 = uigridlayout(app.Panel_3);
            app.ExportOptionsPanel = uipanel(app.GridLayout5);
            app.ExportOptionsPanel.Title = 'Export Options';
            app.GridLayout3 = uigridlayout(app.ExportOptionsPanel);
            app.BrowseButton = uibutton(app.GridLayout3, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.PropSettings = uitree(app.GridLayout3, 'checkbox');
            app.MaskPropertiesNode = uitreenode(app.PropSettings);
            app.MaskPropertiesNode.Text = 'Mask Properties';
            app.AreaNode = uitreenode(app.MaskPropertiesNode);
            app.AreaNode.Text = 'Area';
            app.BoundingBoxNode = uitreenode(app.MaskPropertiesNode);
            app.BoundingBoxNode.Text = 'Bounding Box';
            app.AspectRatioNode = uitreenode(app.MaskPropertiesNode);
            app.AspectRatioNode.Text = 'Aspect Ratio';
            app.CentroidNode = uitreenode(app.MaskPropertiesNode);
            app.CentroidNode.Text = 'Centroid';
            app.MajorAxisLengthNode = uitreenode(app.MaskPropertiesNode);
            app.MajorAxisLengthNode.Text = 'Major Axis Length';
            app.MinorAxisLengthNode = uitreenode(app.MaskPropertiesNode);
            app.MinorAxisLengthNode.Text = 'Minor Axis Length';
            app.OrientationNode = uitreenode(app.MaskPropertiesNode);
            app.OrientationNode.Text = 'Orientation';
            app.PerimeterNode = uitreenode(app.MaskPropertiesNode);
            app.PerimeterNode.Text = 'Perimeter';
            % Assign Checked Nodes
            app.PropSettings.CheckedNodesChangedFcn = createCallbackFcn(app, @PropSettingsCheckedNodesChanged, true);
            app.ExportButton = uibutton(app.GridLayout3, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.SaveFolderEditFieldLabel = uilabel(app.GridLayout3);
            app.SaveFolderEditFieldLabel.Text = 'Save Folder';
            app.SaveFolderEditField = uieditfield(app.GridLayout3, 'text');
            app.SaveFolderEditField.ValueChangedFcn = createCallbackFcn(app, @SaveFolderEditFieldValueChanged, true);
            app.FileNameEditFieldLabel = uilabel(app.GridLayout3);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.FileNameEditField = uieditfield(app.GridLayout3, 'text');
            app.FileNameEditField.ValueChangedFcn = createCallbackFcn(app, @FileNameEditFieldValueChanged, true);
            app.MaskformatListBoxLabel = uilabel(app.GridLayout3);
            app.MaskformatListBoxLabel.Enable = 'off';
            app.MaskformatListBoxLabel.Text = 'Mask format';
            app.MaskformatListBox = uilistbox(app.GridLayout3);
            app.MaskformatListBox.Items = {'MATLAB Array Data (.mat)', 'Image Stack (.tif)', 'Image Planes (.png)'};
            app.MaskformatListBox.Enable = 'off';
            app.MaskformatListBox.Value = 'MATLAB Array Data (.mat)';
            app.ResultsformatListBoxLabel = uilabel(app.GridLayout3);
            app.ResultsformatListBoxLabel.Text = 'Results format';
            app.ResultsformatListBox = uilistbox(app.GridLayout3);
            app.ResultsformatListBox.Items = {'Reimportable struct (.mat)', 'Reimportable struct (.json)', 'Reimportable struct (.xml)', 'Settings & points (.csv)', 'Spreadsheet (.xlsx)'};
            app.ResultsformatListBox.Tooltip = {'Results and the settings used to achieve results will always be exported. The .mat and .json formats are the only formats that can contain masks in the same file as the results and settings. It also enables mixing between masks, contours, and pointclouds. The rest of the formats are ideal for pointclouds and contours but .csv will save SegmentationResults and  SegmentationSettings files by default due to format limitations.'};
            app.ResultsformatListBox.Value = 'Reimportable struct (.mat)';
            app.SavemasksCheckBox = uicheckbox(app.GridLayout3);
            app.SavemasksCheckBox.ValueChangedFcn = createCallbackFcn(app, @SavemasksCheckBoxValueChanged, true);
            app.SavemasksCheckBox.Tooltip = {'Masks do not always need to be saved since some data will consist of boundary curves or point clouds. Converting any set of points to a mask will result in loss of subpixel resolution and take up more space than the results output file.'};
            app.SavemasksCheckBox.Text = 'Save masks';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ROISegmentationGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end